classdef wave_pro_shm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        waveProSHMgui                   matlab.ui.Figure
        LoadMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MeshTab                         matlab.ui.container.Tab
        ConvertMeshTextArea             matlab.ui.control.TextArea
        TickanddropdownmenuLabel        matlab.ui.control.Label
        LoadMeshTextArea                matlab.ui.control.TextArea
        ConvertMeshButton               matlab.ui.control.Button
        UITableDamageInterfaceSelection  matlab.ui.control.Table
        SelectDamageInterfacesVolumesandCurvesLabel  matlab.ui.control.Label
        MeshunitsButtonGroup            matlab.ui.container.ButtonGroup
        meshUnitsButton_m               matlab.ui.control.RadioButton
        meshUnitsButton_mm              matlab.ui.control.RadioButton
        LoadMeshButton                  matlab.ui.control.Button
        MaterialsTab                    matlab.ui.container.Tab
        MassDensityOrthotropicEditField  matlab.ui.control.NumericEditField
        MassDensityOrthotripicEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientOrthotropicZEditField  matlab.ui.control.NumericEditField
        DampingCoefficientOrthotropicZEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientOrthotropicYEditField  matlab.ui.control.NumericEditField
        DampingCoefficientOrthotropicYEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientOrthotropicXEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientOrthotropicXEditField  matlab.ui.control.NumericEditField
        ElasticityMatrixLabel           matlab.ui.control.Label
        EqualLabel                      matlab.ui.control.Label
        StrainVectorLabel               matlab.ui.control.Label
        StressVectorLabel               matlab.ui.control.Label
        RelativePermittivityEpsrS33EditField  matlab.ui.control.NumericEditField
        RelativePermittivityEpsrS33EditFieldLabel  matlab.ui.control.Label
        RelativePermittivityEpsrS11EditField  matlab.ui.control.NumericEditField
        RelativePermittivityEpsrS11EditFieldLabel  matlab.ui.control.Label
        ChargeConstantd15EditField      matlab.ui.control.NumericEditField
        ChargeConstantd15EditFieldLabel  matlab.ui.control.Label
        ChargeConstantd33EditField      matlab.ui.control.NumericEditField
        ChargeConstantd33EditFieldLabel  matlab.ui.control.Label
        ChargeConstantd31EditField      matlab.ui.control.NumericEditField
        ChargeConstantd31EditFieldLabel  matlab.ui.control.Label
        YoungsModulusPiezoYE55EditFieldLabel  matlab.ui.control.Label
        YoungsModulusPiezoYE55EditField  matlab.ui.control.NumericEditField
        YoungsModulusPiezoYE33EditFieldLabel  matlab.ui.control.Label
        YoungsModulusPiezoYE33EditField  matlab.ui.control.NumericEditField
        DampingCoefficientPiezoZEditField  matlab.ui.control.NumericEditField
        DampingCoefficientPiezoYEditField  matlab.ui.control.NumericEditField
        DampingCoefficientPiezoXEditField  matlab.ui.control.NumericEditField
        MassDensityPiezoEditField       matlab.ui.control.NumericEditField
        PoissonRatioPiezoEditField      matlab.ui.control.NumericEditField
        YoungsModulusPiezoYE11EditField  matlab.ui.control.NumericEditField
        DampingCoefficientPiezoZEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientPiezoYEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientPiezoXEditFieldLabel  matlab.ui.control.Label
        MassDensityPiezoEditFieldLabel  matlab.ui.control.Label
        PoissonRatioPiezoEditFieldLabel  matlab.ui.control.Label
        YoungsModulusPiezoYE11EditFieldLabel  matlab.ui.control.Label
        MaterialsAssignmentLabel        matlab.ui.control.Label
        UITableMaterialAssignment       matlab.ui.control.Table
        YoungsModulusEditField          matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        PoissonRatioEditField           matlab.ui.control.NumericEditField
        PoissonRatioEditFieldLabel      matlab.ui.control.Label
        MassDensityEditField            matlab.ui.control.NumericEditField
        MassDensityEditFieldLabel       matlab.ui.control.Label
        DampingCoefficientZEditField    matlab.ui.control.NumericEditField
        DampingCoefficientZEditFieldLabel  matlab.ui.control.Label
        MaterialNameEditField           matlab.ui.control.EditField
        MaterialNameEditFieldLabel      matlab.ui.control.Label
        RemoveMaterialButton            matlab.ui.control.Button
        AddMaterialButton               matlab.ui.control.Button
        MaterialsTree                   matlab.ui.container.Tree
        SolidMaterialCategory           matlab.ui.container.TreeNode
        IsotropicMaterialCategory       matlab.ui.container.TreeNode
        SolidMaterial1Node              matlab.ui.container.TreeNode
        OrthotropicMaterialCategory     matlab.ui.container.TreeNode
        SolidMaterial2Node              matlab.ui.container.TreeNode
        PiezoelectricMaterialCategory   matlab.ui.container.TreeNode
        PiezoelectricMaterial1Node      matlab.ui.container.TreeNode
        DampingCoefficientYEditField    matlab.ui.control.NumericEditField
        DampingCoefficientYEditFieldLabel  matlab.ui.control.Label
        DampingCoefficientXEditField    matlab.ui.control.NumericEditField
        DampingCoefficientXEditFieldLabel  matlab.ui.control.Label
        ElasticityD66EditField          matlab.ui.control.NumericEditField
        ElasticityD66EditFieldLabel     matlab.ui.control.Label
        ElasticityD65EditField          matlab.ui.control.NumericEditField
        ElasticityD65EditFieldLabel     matlab.ui.control.Label
        ElasticityD64EditField          matlab.ui.control.NumericEditField
        ElasticityD64EditFieldLabel     matlab.ui.control.Label
        ElasticityD63EditField          matlab.ui.control.NumericEditField
        ElasticityD63EditFieldLabel     matlab.ui.control.Label
        ElasticityD62EditField          matlab.ui.control.NumericEditField
        ElasticityD62EditFieldLabel     matlab.ui.control.Label
        ElasticityD61EditField          matlab.ui.control.NumericEditField
        ElasticityD61EditFieldLabel     matlab.ui.control.Label
        ElasticityD56EditField          matlab.ui.control.NumericEditField
        ElasticityD56EditFieldLabel     matlab.ui.control.Label
        ElasticityD55EditField          matlab.ui.control.NumericEditField
        ElasticityD55EditFieldLabel     matlab.ui.control.Label
        ElasticityD54EditField          matlab.ui.control.NumericEditField
        ElasticityD54EditFieldLabel     matlab.ui.control.Label
        ElasticityD53EditField          matlab.ui.control.NumericEditField
        ElasticityD53EditFieldLabel     matlab.ui.control.Label
        ElasticityD52EditField          matlab.ui.control.NumericEditField
        ElasticityD52EditFieldLabel     matlab.ui.control.Label
        ElasticityD51EditField          matlab.ui.control.NumericEditField
        ElasticityD51EditFieldLabel     matlab.ui.control.Label
        ElasticityD46EditField          matlab.ui.control.NumericEditField
        ElasticityD46EditFieldLabel     matlab.ui.control.Label
        ElasticityD45EditField          matlab.ui.control.NumericEditField
        ElasticityD45EditFieldLabel     matlab.ui.control.Label
        ElasticityD44EditField          matlab.ui.control.NumericEditField
        ElasticityD44EditFieldLabel     matlab.ui.control.Label
        ElasticityD43EditField          matlab.ui.control.NumericEditField
        ElasticityD43EditFieldLabel     matlab.ui.control.Label
        ElasticityD42EditField          matlab.ui.control.NumericEditField
        ElasticityD42EditFieldLabel     matlab.ui.control.Label
        ElasticityD41EditField          matlab.ui.control.NumericEditField
        ElasticityD41EditFieldLabel     matlab.ui.control.Label
        ElasticityD36EditField          matlab.ui.control.NumericEditField
        ElasticityD36EditFieldLabel     matlab.ui.control.Label
        ElasticityD35EditField          matlab.ui.control.NumericEditField
        ElasticityD35EditFieldLabel     matlab.ui.control.Label
        ElasticityD34EditField          matlab.ui.control.NumericEditField
        ElasticityD34EditFieldLabel     matlab.ui.control.Label
        ElasticityD33EditField          matlab.ui.control.NumericEditField
        ElasticityD33EditFieldLabel     matlab.ui.control.Label
        ElasticityD32EditField          matlab.ui.control.NumericEditField
        ElasticityD32EditFieldLabel     matlab.ui.control.Label
        ElasticityD31EditField          matlab.ui.control.NumericEditField
        ElasticityD31EditFieldLabel     matlab.ui.control.Label
        ElasticityD26EditField          matlab.ui.control.NumericEditField
        ElasticityD26EditFieldLabel     matlab.ui.control.Label
        ElasticityD25EditField          matlab.ui.control.NumericEditField
        ElasticityD25EditFieldLabel     matlab.ui.control.Label
        ElasticityD24EditField          matlab.ui.control.NumericEditField
        ElasticityD24EditFieldLabel     matlab.ui.control.Label
        ElasticityD23EditField          matlab.ui.control.NumericEditField
        ElasticityD23EditFieldLabel     matlab.ui.control.Label
        ElasticityD22EditField          matlab.ui.control.NumericEditField
        ElasticityD22EditFieldLabel     matlab.ui.control.Label
        ElasticityD21EditField          matlab.ui.control.NumericEditField
        ElasticityD21EditFieldLabel     matlab.ui.control.Label
        ElasticityD16EditField          matlab.ui.control.NumericEditField
        ElasticityD16EditFieldLabel     matlab.ui.control.Label
        ElasticityD15EditField          matlab.ui.control.NumericEditField
        ElasticityD15EditFieldLabel     matlab.ui.control.Label
        ElasticityD14EditField          matlab.ui.control.NumericEditField
        ElasticityD14EditFieldLabel     matlab.ui.control.Label
        ElasticityD13EditField          matlab.ui.control.NumericEditField
        ElasticityD13EditFieldLabel     matlab.ui.control.Label
        ElasticityD12EditField          matlab.ui.control.NumericEditField
        ElasticityD12EditFieldLabel     matlab.ui.control.Label
        ElasticityD11EditField          matlab.ui.control.NumericEditField
        ElasticityD11EditFieldLabel     matlab.ui.control.Label
        TransducersTab                  matlab.ui.container.Tab
        SensorsAssignmentLabel          matlab.ui.control.Label
        UITableSensorAssignment         matlab.ui.control.Table
        ActuatorsAssignmentLabel        matlab.ui.control.Label
        UITableActuatorAssignment       matlab.ui.control.Table
        SignalsTab                      matlab.ui.container.Tab
        ExcitationAssignmentLabel       matlab.ui.control.Label
        DeltaTEditField                 matlab.ui.control.NumericEditField
        DeltaTEditFieldLabel            matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        nCyclesEditField                matlab.ui.control.NumericEditField
        nCyclesEditFieldLabel           matlab.ui.control.Label
        UITableExcitationAssignment     matlab.ui.control.Table
        SimulationparametersLabel       matlab.ui.control.Label
        SignalNameEditField             matlab.ui.control.EditField
        SignalNameEditFieldLabel        matlab.ui.control.Label
        RemoveSignalButton              matlab.ui.control.Button
        DuplicateSignalButton           matlab.ui.control.Button
        SignalsTree                     matlab.ui.container.Tree
        HannCategory                    matlab.ui.container.TreeNode
        HannSignal1Node                 matlab.ui.container.TreeNode
        PeakVoltageEditField            matlab.ui.control.NumericEditField
        PeakVoltageEditFieldLabel       matlab.ui.control.Label
        nTimeFramesEditField            matlab.ui.control.NumericEditField
        nTimeFramesEditFieldLabel       matlab.ui.control.Label
        TotalPropagationTimeEditField   matlab.ui.control.NumericEditField
        TotalPropagationTimeEditFieldLabel  matlab.ui.control.Label
        nSamplesEditField               matlab.ui.control.NumericEditField
        nSamplesEditFieldLabel          matlab.ui.control.Label
        ModulationFrequencyEditField    matlab.ui.control.NumericEditField
        ModulationFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        HannFrequencyFig                matlab.ui.control.UIAxes
        HannTimeFig                     matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        VtuFormatButtonGroup            matlab.ui.container.ButtonGroup
        ButtonAscii                     matlab.ui.control.RadioButton
        ButtonBinary                    matlab.ui.control.RadioButton
        ParaviewVtuCheckBox             matlab.ui.control.CheckBox
        MatlabMatCheckBox               matlab.ui.control.CheckBox
        OutputFormatLabel               matlab.ui.control.Label
        SelectedGPUNameTextArea         matlab.ui.control.TextArea
        GPUindexDropDown                matlab.ui.control.DropDown
        GPUindexDropDownLabel           matlab.ui.control.Label
        DisplacementFramesCheckBox      matlab.ui.control.CheckBox
        VelocityFramesCheckBox          matlab.ui.control.CheckBox
        UITableResponsePointsSelection  matlab.ui.control.Table
        SensorSelectionPreviewDropDown  matlab.ui.control.DropDown
        SensorSelectionPreviewDropDownLabel  matlab.ui.control.Label
        ResponsePointsSelectionLabel    matlab.ui.control.Label
        ParameterstosaveLabel           matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        VelocityResponsePointsCheckBox  matlab.ui.control.CheckBox
        DisplacementResponsePointsCheckBox  matlab.ui.control.CheckBox
        NotificationTextArea            matlab.ui.control.TextArea
        NotificationTextAreaLabel       matlab.ui.control.Label
        ProcessingUnit                  matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        SignalPreview                   matlab.ui.control.UIAxes
        AboutTab                        matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        VersionLabel                    matlab.ui.control.Label
        WaveProSHMLabel                 matlab.ui.control.Label
        AffiliationLabel                matlab.ui.control.Label
        EngineLabel                     matlab.ui.control.Label
        GUIAuthorLabel                  matlab.ui.control.Label
        EngineAuthorsLabel              matlab.ui.control.Label
        GUILabel                        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HelpTab                         matlab.ui.container.Tab
    end

    % too see labels in component browser before starting appdesinger:
    % s = settings;
    % s.matlab.appdesigner.designview.ShowResizingHints.TemporaryValue = 1


    properties (Access = public)
        myBlue = [0.00,0.26,0.55];
        calculationProgress = []; % progress dialog box handle
        isGPUavailable = false;
        nGPUs = 0;
        gpuDeviceIndex = []; % for index of currently selected GPU
        gpuDeviceIndexList = []; % list of indexes of all available GPUs
        selectedGPU = []; % handle to selected GPU
        absolute_path = '';
        inputFileName = "test";
        in_millimeters = true;
        meshName = '';
        SemMesh % spectral element mesh structure
        Damage  % structure related damage interfaces, etc.
        Excitation % structure related to actuators and excitation signals
        Output % structure related to sensors and output parameters
        isVerbose = true; % flag for additional messages
        isDisplacementResponsePointsSelected = true;
        isVelocityResponsePointsSelected = false;
        isDisplacementFramesSelected = true;
        isVelocityFramesSelected = false;
        materialTreeCategory = 1;
        signalTreeCategory = 1;
        nSolidMaterials = 2; % current total number of solid materials
        nPiezoelectricMaterials = 1; % current total number of piezoelectric materials
        nAddedSolidMaterials = 2; % counter for name of new material with number
        nAddedPiezoelectricMaterials = 1; % counter for name of new material with number

        PiezoelectricMaterialNodeHandle = gobjects(4, 1); % handles to graphical objects
        SolidMaterialNodeHandle = gobjects(4, 1);         % handles to graphical objects
        HannSignalNodeHandle = gobjects(4, 1);            % handles to graphical objects
        ChirpSignalNodeHandle = gobjects(4, 1);           % handles to graphical objects
        GaussSignalNodeHandle = gobjects(4, 1);            % handles to graphical objects
        
        SolidMaterialCategoryAssignment = [];
        % Database for Materials
        Materials
        isMeshLoaded = false;
        isMeshConverted = false; % mesh assignemnt is possible only if this is true
        isMaterialAssigned = false;
        isActuatorAssigned = false;
        isSensorAssigned = false;
        isExcitationAssigned = false;

        HannTimePlotHandle = [];
        HannFramesPlotHandle = [];
        HannFrequencyPlotHandle = [];
        nHannSignals = 1;
        nAddedHannSignals = 1;
        nChirpSignals = 0;
        nGaussSignals = 0;

    end

    methods (Access = private)
    
        function changeFilenameColor(app)
            app.FilenameEditField.FontColor = [1,0,0]; % red
            app.FilenameEditFieldLabel.FontColor = [1,0,0]; % red
        end

        function fillMaterialAssignmentTable(app)
            if(app.isMeshConverted)
                % clear out existing data
                app.UITableMaterialAssignment.Data = table.empty;
                % fill Material Assignment table
                id = vertcat( app.SemMesh.PhysicalVolumes.physicalTag );
                PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';   
                MaterialSelection = strings(app.nSolidMaterials+app.nPiezoelectricMaterials,1);
                c = 0;
                for iSolid = 1:app.nSolidMaterials
                    c = c + 1;
                    MaterialSelection(c) = app.Materials.solidMaterialNameList(iSolid);
                end
                for iPiezoMat = 1:app.nPiezoelectricMaterials
                    c = c + 1;
                    MaterialSelection(c) = app.Materials.piezoelectricMaterialNameList(iPiezoMat);
                end
                
                MaterialSelection = categorical(MaterialSelection,'Protected',true);
                
                if( app.isMaterialAssigned )
                  
                    indexList = zeros(length(id),1);
                    for j = 1:length(id)
                        s1 = string(app.Materials.assignment(j).name);
                        for k = 1:length(MaterialSelection)
                            s2  = string(MaterialSelection(k));
                            if(strcmp(s1,s2))
                                indexList(j) = k;
                                break;
                            end
                        
                        end
                    end
                    M = cell(length(id),1);
                    for j = 1 : length(id)
                        if(indexList(j))
                            M{j,1} = MaterialSelection(indexList(j));
                        else
                            M{j,1} = MaterialSelection(1);
                        end
                    end
                    T2 = table(id,PhysicalVolumeNames,M);
                else
                    T2 = table(id,PhysicalVolumeNames,repmat({MaterialSelection(1)},length(id),1));
                end
                app.UITableMaterialAssignment.Data = T2;
                app.isMaterialAssigned = true;
            end
        end
        
        function extractCurrentMaterialAssignment(app)
            for iVolume = 1:height(app.UITableMaterialAssignment.Data(:,1))
                Data = get(app.UITableMaterialAssignment,'Data');
                dataCell = Data{iVolume,3};
                app.Materials.assignment(iVolume).name = string(dataCell{1,1});
                s1 = app.Materials.assignment(iVolume).name;
                % traverse MaterialsTree
                for iSolid = 1:app.nSolidMaterials
                    s2 = string(app.SolidMaterialNodeHandle(iSolid).Text);
                    if(strcmp(s1,s2))
                        app.Materials.assignment(iVolume).category = ...
                            app.SolidMaterialCategoryAssignment(iSolid);
                        app.Materials.assignment(iVolume).index = iSolid;
                        break
                    end
                end
                for iPiezoMat = 1:app.nPiezoelectricMaterials
                    s2 = string(app.PiezoelectricMaterialNodeHandle(iPiezoMat).Text);
                    if(strcmp(s1,s2))  
                        app.Materials.assignment(iVolume).category = 3;
                        app.Materials.assignment(iVolume).index = iPiezoMat;
                        break
                    end
                end
            end
            
        end
        
        function fillActuatorAssignmentTable(app)
            if(app.isMeshConverted)
                % clear out existing data
                app.UITableActuatorAssignment.Data = table.empty;
                idVol = vertcat( app.SemMesh.PhysicalVolumes.physicalTag );
                PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';
                ActuatorDefaultSelection = repmat([false],length(idVol),1);
                
                A = cell(length(idVol),1);
                for k = 1 : length(idVol)
                    A{k,1} = categorical(NaN,'Protected', true);
                end
                T = table(idVol,PhysicalVolumeNames,ActuatorDefaultSelection,A,A);
                app.UITableActuatorAssignment.Data = T;
               
                sBlack = uistyle('FontColor','black');
                indexes = [1:length(idVol)]';
                addStyle(app.UITableActuatorAssignment,sBlack,'cell',[indexes,repmat(4,[length(indexes),1])]);
                addStyle(app.UITableActuatorAssignment,sBlack,'cell',[indexes,repmat(5,[length(indexes),1])]);

                app.UITableActuatorAssignment.Enable = 'on';
                app.ActuatorsAssignmentLabel.Enable = 'on';
            end 
        end
        
        function fillSensorAssignmentTable(app)
            if(app.isMeshConverted)
                % clear out existing data
                app.UITableSensorAssignment.Data = table.empty;
                idVol = vertcat( app.SemMesh.PhysicalVolumes.physicalTag );
                PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';
                SensorDefaultSelection = repmat([false],length(idVol),1);
                
                A = cell(length(idVol),1);
                for k = 1 : length(idVol)
                    A{k,1} = categorical(NaN,'Protected', true);
                end
                T = table(idVol,PhysicalVolumeNames,SensorDefaultSelection,A,A);
                app.UITableSensorAssignment.Data = T;
               
                sBlack = uistyle('FontColor','black');
                indexes = [1:length(idVol)]';
                addStyle(app.UITableSensorAssignment,sBlack,'cell',[indexes,repmat(4,[length(indexes),1])]);
                addStyle(app.UITableSensorAssignment,sBlack,'cell',[indexes,repmat(5,[length(indexes),1])]);

                app.UITableSensorAssignment.Enable = 'on';
                app.SensorsAssignmentLabel.Enable = 'on';
            end   
        end
        
        function fillExcitationAssignmentTable(app)
            if(app.isActuatorAssigned) 
                % clear out existing data
                app.UITableExcitationAssignment.Data = table.empty;
                % fill Excitation Assignment table
                id = vertcat( app.SemMesh.PhysicalVolumes.physicalTag );
                PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';   
                selection = table2array(app.UITableActuatorAssignment.Data(:,3));
                PhysicalVolumeNames = PhysicalVolumeNames(selection); 
                id = id(selection);
                ExcitationSelection = strings(app.nHannSignals +...
                                              app.nChirpSignals +...
                                              app.nGaussSignals,1);
                c = 0;
                for iHannSignal = 1:app.nHannSignals
                    c = c + 1;
                    ExcitationSelection(c) = app.Excitation.hannSignalNameList(iHannSignal);
                end
                for iChirpSignal = 1:app.nChirpSignals
                    c = c + 1;
                    ExcitationSelection(c) = app.Excitation.chirpSignalNameList(iChirpSignal);
                end
                for iGaussSignal = 1:app.nGaussSignals
                    c = c + 1;
                    ExcitationSelection(c) = app.Excitation.gaussSignalNameList(iGaussSignal);
                end
                
                ExcitationSelection = categorical(ExcitationSelection,'Protected',true);
                
                if( app.isExcitationAssigned )   
                    indexList = zeros(length(id),1);
                    for j = 1:length(app.Excitation.assignment)
                        s1 = string(app.Excitation.assignment(j).name);
                        for k = 1:length(ExcitationSelection)
                            s2  = string(ExcitationSelection(k));
                            if(strcmp(s1,s2))
                                indexList(j) = k;
                                break;
                            end
                        
                        end
                    end
                    M = cell(length(id),1);
                    for j = 1 : length(id)
                        if(indexList(j))
                            M{j,1} = ExcitationSelection(indexList(j));
                        else
                            M{j,1} = ExcitationSelection(1);
                        end
                    end
                    T2 = table(id,PhysicalVolumeNames,M);
                else
                    T2 = table(id,PhysicalVolumeNames,repmat({ExcitationSelection(1)},length(id),1));
                end
                app.UITableExcitationAssignment.Data = T2;
                app.isExcitationAssigned = true;
            end
            
        end
        
        function extractCurrentExcitationAssignment(app)
            if(app.isActuatorAssigned)
                for iActuator = 1:height(app.UITableExcitationAssignment.Data(:,1))
                    Data = get(app.UITableExcitationAssignment,'Data');
                    dataCell = Data{iActuator,3};
                    app.Excitation.assignment(iActuator).name = string(dataCell{1,1});
                    s1 = app.Excitation.assignment(iActuator).name;
                    % traverse SignalsTree
                    for iHannSignal = 1:app.nHannSignals
                        s2 = string(app.HannSignalNodeHandle(iHannSignal).Text);
                        if(strcmp(s1,s2))
                            app.Excitation.assignment(iActuator).category = 1;
                            app.Excitation.assignment(iActuator).index = iHannSignal;
                            break
                        end
                    end
                    for iChirpSignal = 1:app.nChirpSignals
                        s2 = string(app.ChirpSignalsNodeHandle(iChirpSignal).Text);
                        if(strcmp(s1,s2))  
                            app.Excitation.assignment(iActuator).category = 2;
                            app.Excitation.assignment(iActuator).index = iChirpSignal;
                            break
                        end
                    end
                end
            end
        end
        
        function plotHannSignal(app)
            selectedNodes = app.SignalsTree.SelectedNodes;
            iHannSignal = selectedNodes.NodeData;
            nft = app.Excitation.nSamples;
            f_1 = app.Excitation.Hann(iHannSignal).modulationFrequency; % modulation freq [Hz]
            f_2 = app.Excitation.Hann(iHannSignal).carrierFrequency; % carrier freq [Hz]
            t_1 = app.Excitation.Hann(iHannSignal).initiationTime;
            V0 = app.Excitation.Hann(iHannSignal).peakVoltage;
            dt = app.Excitation.dt;   % calculation time step [s]
     
            [t,st] = hanning_signal(dt,nft,f_1,f_2,t_1);
            cla(app.HannTimeFig); 
            app.HannTimePlotHandle = plot(app.HannTimeFig,t*1e3,V0*st);
            hold(app.HannTimeFig,'on')
            frameIndexes = app.Output.sampleInterval:...
                           app.Output.sampleInterval:...
                           app.Output.sampleInterval*app.Output.nFrames;
            app.HannFramesPlotHandle = plot(app.HannTimeFig,...
                t(frameIndexes)*1e3,V0*st(frameIndexes),'b.','MarkerSize', 1); % 'MarkerFaceColor', 'b

            axis(app.HannTimeFig,[0 t(end)*1e3 -V0 V0]);
            n = 2^nextpow2(nft);
            y = fft(V0*st,n);
            P2 = abs(y/n);
            P1 = P2(1:n/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            Fs = 1/dt;
            f = Fs*(0:(n/2))/n;
            app.HannFrequencyPlotHandle = plot(app.HannFrequencyFig,f'/1e3,P1,'LineWidth',1);
            axis(app.HannFrequencyFig,[0 2*f_2/1e3 0 max(P1)]);  
        end
        
        function fillResponsePointsSelectionTable(app)
            if(app.isMeshConverted)
                % clear out existing data
                app.UITableResponsePointsSelection.Data = table.empty;
                % fill Response Points Selection table
                id = vertcat( app.SemMesh.PhysicalPoints.physicalTag );
                PhysicalPointNames = {app.SemMesh.PhysicalPoints.name}'; 

                DefaultPointSelection = repmat([true],length(id),1);
                
                T = table(id,PhysicalPointNames,DefaultPointSelection);
                app.UITableResponsePointsSelection.Data = T;
               
                app.UITableResponsePointsSelection.Enable = "on";
                app.ResponsePointsSelectionLabel.Enable = "on";  

                app.Output.responsePointTagList = id;
  
            end
        end
        
        function fillDamageInterfaceSelectionTable(app)
            app.SelectDamageInterfacesVolumesandCurvesLabel.Visible = "on";
            app.TickanddropdownmenuLabel.Visible = "on";
            app.UITableDamageInterfaceSelection.Visible = "on";
            app.UITableDamageInterfaceSelection.Enable = "on";
            idSurf = vertcat( app.SemMesh.PhysicalSurfaces.physicalTag );
            PhysicalSurfaceNames = {app.SemMesh.PhysicalSurfaces.name}';
            DamageInterfaceDefaultSelection = repmat([false],length(idSurf),1);
            
            A = cell(length(idSurf),1);
            for k = 1 : length(idSurf)
                A{k,1} = categorical(NaN,'Protected', true);
            end
            T = table(idSurf,PhysicalSurfaceNames,DamageInterfaceDefaultSelection,A,A);
            app.UITableDamageInterfaceSelection.Data = T;
           
            sBlack = uistyle('FontColor','black');
            indexes = [1:length(idSurf)]';
            addStyle(app.UITableDamageInterfaceSelection,sBlack,'cell',...
                [indexes,repmat(4,[length(indexes),1])]);
            addStyle(app.UITableDamageInterfaceSelection,sBlack,'cell',...
                [indexes,repmat(5,[length(indexes),1])]); 
        end
        
        function updateDamageInterfaceSelectionTableColors(app)
            selection = table2array(app.UITableDamageInterfaceSelection.Data(:,3));
            sHighlight = uistyle('FontColor',app.myBlue);
            sBlack = uistyle('FontColor','black');
            indexesHighlight = find(selection);
            indexesBlack = find(~selection);
            addStyle(app.UITableDamageInterfaceSelection,sHighlight,'cell',...
                [indexesHighlight,repmat(4,[length(indexesHighlight),1])]);
            addStyle(app.UITableDamageInterfaceSelection,sHighlight,'cell',...
                [indexesHighlight,repmat(5,[length(indexesHighlight),1])]);
            addStyle(app.UITableDamageInterfaceSelection,sBlack,'cell',...
                [indexesBlack,repmat(4,[length(indexesBlack),1])]);
            addStyle(app.UITableDamageInterfaceSelection,sBlack,'cell',...
                [indexesBlack,repmat(5,[length(indexesBlack),1])]);
        end
        
        function updateActuatorAssignmentTableColors(app)
            selection = table2array(app.UITableActuatorAssignment.Data(:,3));
            sHighlight = uistyle('FontColor',app.myBlue);
            sBlack = uistyle('FontColor','black');
            indexesHighlight = find(selection);
            indexesBlack = find(~selection);
            addStyle(app.UITableActuatorAssignment,sHighlight,'cell',...
                [indexesHighlight,repmat(4,[length(indexesHighlight),1])]);
            addStyle(app.UITableActuatorAssignment,sHighlight,'cell',...
                [indexesHighlight,repmat(5,[length(indexesHighlight),1])]);
            addStyle(app.UITableActuatorAssignment,sBlack,'cell',...
                [indexesBlack,repmat(4,[length(indexesBlack),1])]);
            addStyle(app.UITableActuatorAssignment,sBlack,'cell',...
                [indexesBlack,repmat(5,[length(indexesBlack),1])]);
        end
        
        function updateSensorAssignmentTableColors(app)
            selection = table2array(app.UITableSensorAssignment.Data(:,3));
            sHighlight = uistyle('FontColor',app.myBlue);
            sBlack = uistyle('FontColor','black');
            indexesHighlight = find(selection);
            indexesBlack = find(~selection);
            addStyle(app.UITableSensorAssignment,sHighlight,'cell',...
                [indexesHighlight,repmat(4,[length(indexesHighlight),1])]);
            addStyle(app.UITableSensorAssignment,sHighlight,'cell',...
                [indexesHighlight,repmat(5,[length(indexesHighlight),1])]);
            addStyle(app.UITableSensorAssignment,sBlack,'cell',...
                [indexesBlack,repmat(4,[length(indexesBlack),1])]);
            addStyle(app.UITableSensorAssignment,sBlack,'cell',...
                [indexesBlack,repmat(5,[length(indexesBlack),1])]);
        end
        
        function deleteTreeNodes(app,items)
            % Function that delete the existing items from a cell variable
            if ~isempty(items)
                for i=1:length(items)
                    % delete(items{i});
                    delete(items(i));
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.nGPUs,app.gpuDeviceIndexList] = gpuDeviceCount("available");
            if(app.nGPUs == 0)
                app.ProcessingUnit.Value = "CPU";
                app.isGPUavailable = false;
                app.GPUindexDropDown.Visible = "off";
                app.GPUindexDropDownLabel.Visible = "off";
            else
                app.isGPUavailable = true;
                app.ProcessingUnit.Value = "GPU";
                app.GPUindexDropDown.Items = string(app.gpuDeviceIndexList);
                app.GPUindexDropDown.Value = app.GPUindexDropDown.Items(1);
                app.gpuDeviceIndex = app.gpuDeviceIndexList(1);
                app.selectedGPU = gpuDevice(app.gpuDeviceIndex);
                app.GPUindexDropDown.Visible = "on";
                app.GPUindexDropDownLabel.Visible = "on";
                app.SelectedGPUNameTextArea.Visible = "on";
                app.SelectedGPUNameTextArea.Value = [num2str(1),' ',app.selectedGPU.Name];
            end
            app.absolute_path = fileparts(mfilename('fullpath'));
            input_path = ['.',filesep,'inputs',filesep];
            if ~exist(input_path, 'dir')
                mkdir(input_path);
            end 
            
            addpath(genpath(fullfile(app.absolute_path,'mesher')));
            addpath(genpath(fullfile(app.absolute_path,'wave_pro_shm','solid3d_engine')));
            

            app.meshUnitsButton_mm.Value = 1;
            
            app.Damage.interfaceBoundaryTagList = [];
            app.Damage.volumeTagList = [];
            app.Damage.interfaceTagList = [];


            app.Output.sensorTagList = [];   
            app.Output.positiveElectrodeTagList = [];   
            app.Output.negativeElectrodeTagList = [];  
            app.Output.responsePointTagList = [];
            app.Output.sensorPreviewNo = 1;
            app.Output.isDisplacementResponsePointsSelected = true;
            app.Output.isVelocityResponsePointsSelected = false;
            app.Output.isDisplacementFramesSelected = true;
            app.Output.isVelocityFramesSelected = false;
            app.Output.isMatOutput = false;
            app.Output.isVtuOutput = true;
            app.Output.isBinary = true;


            app.PiezoelectricMaterialNodeHandle(1) = app.PiezoelectricMaterial1Node;
            app.SolidMaterialNodeHandle(1) = app.SolidMaterial1Node;
            app.SolidMaterialNodeHandle(2) = app.SolidMaterial2Node;
            % materials database
            nMaterials = 4;
            app.Materials.solidMaterialNameList = ["Aluminum","CFRP","Three","Four"];
            app.Materials.piezoelectricMaterialNameList = ["my PZT","my PZT 2","my PZT 3","my PZT 4"];
            app.Materials.youngModulus = zeros(1,nMaterials);
            app.Materials.poissonRatio = zeros(1,nMaterials);
            app.Materials.density = zeros(1,nMaterials);
            app.Materials.elasticityMatrix = zeros(6,6,nMaterials);
            app.Materials.damping = zeros(3,nMaterials);
    
            app.Materials.youngModulusPiezoYE11 = zeros(1,nMaterials);
            app.Materials.youngModulusPiezoYE33 = zeros(1,nMaterials);
            app.Materials.youngModulusPiezoYE55 = zeros(1,nMaterials);
            app.Materials.chargeConstantd31 = zeros(1,nMaterials);
            app.Materials.chargeConstantd33 = zeros(1,nMaterials);
            app.Materials.chargeConstantd15 = zeros(1,nMaterials);
            app.Materials.relativePermittivityEpsrS11 = zeros(1,nMaterials);
            app.Materials.relativePermittivityEpsrS33  = zeros(1,nMaterials);

            app.Materials.poissonRatioPiezo = zeros(1,nMaterials);
            app.Materials.densityPiezo = zeros(1,nMaterials);
            app.Materials.elasticityMatrixPiezo = zeros(6,6,nMaterials);
            app.Materials.dampingPiezo = zeros(3,nMaterials);
            app.Materials.pztPermittivity = zeros(3,3,nMaterials);
            app.Materials.pztCoupling = zeros(3,6,nMaterials);
            
            app.SolidMaterialCategoryAssignment = [1,2];
            % default aluminium material
            app.Materials.solidMaterialNameList(1) = "Aluminium";
            app.Materials.youngModulus(1,1) = 71e9; % [Pa]
            app.Materials.poissonRatio(1,1) = 0.33; % [-]
            app.Materials.elasticityMatrix(:,:,1) = ...
                elasticity_matrix_isotropy(app.Materials.youngModulus(1),app.Materials.poissonRatio(1));
            app.Materials.density(1,1) = 2700; % [kg/m^3]
            % default CFRP material
            app.Materials.solidMaterialNameList(2) = "CFRP";
            app.Materials.density(1,2) = 1522.4; % [kg/m^3]
            c11 = 52.55e9;
            c12 = 6.51e9;
            c13 = 5.94e9;
            c22 = 51.83e9;
            c23 = 5.88e9;
            c33 = 10.28e9;
            c44 = 2.93e9;
            c55 = 2.93e9;
            c66 = 3.81e9;
            c16 = 0;
            c26 = 0;
            c36 = 0;
            c45 = 0;
            app.Materials.elasticityMatrix(:,:,2) = [c11,c12,c13,c16,  0,  0;
                                                     c12,c22,c23,c26,  0,  0;
                                                     c13,c23,c33,c36,  0,  0;
                                                     c16,c26,c36,c66,  0,  0;
                                                       0,  0,  0,  0,c55,c45;
                                                       0,  0,  0,  0,c45,c44]; % [Pa]

            % c11 = app.Materials.elasticityMatrix(1,1,2);
            % c12 = app.Materials.elasticityMatrix(1,2,2);
            % c13 = app.Materials.elasticityMatrix(1,3,2);
            % c22 = app.Materials.elasticityMatrix(2,2,2);
            % c23 = app.Materials.elasticityMatrix(2,3,2);
            % c33 = app.Materials.elasticityMatrix(3,3,2);
            % c44 = app.Materials.elasticityMatrix(6,6,2);
            % c55 = app.Materials.elasticityMatrix(5,5,2);
            % c66 = app.Materials.elasticityMatrix(4,4,2);
            % c16 = app.Materials.elasticityMatrix(1,4,2);
            % c26 = app.Materials.elasticityMatrix(2,4,2);
            % c36 = app.Materials.elasticityMatrix(3,4,2);
            % c45 = app.Materials.elasticityMatrix(6,5,2);

            % default piezoelectric material
            app.Materials.piezoelectricMaterialNameList(1) = "my PZT";
            app.Materials.youngModulusPiezoYE11(1,1) = 59e9;   % [Pa]
            app.Materials.youngModulusPiezoYE33(1,1) = 46.9e9; % [Pa]
            app.Materials.youngModulusPiezoYE55(1,1) = 20.5e9; % [Pa]
            app.Materials.chargeConstantd31(1,1) = -208e-12;   % [C/N]
            app.Materials.chargeConstantd33(1,1) = 443e-12;    % [C/N]
            app.Materials.chargeConstantd15(1,1) = 669e-12;    % [C/N]
            app.Materials.relativePermittivityEpsrS11(1,1) = 906; % [-]
            app.Materials.relativePermittivityEpsrS33(1,1)  = 823; % [-]

            app.Materials.poissonRatioPiezo(1,1) = 0.32; % [-]
            app.Materials.densityPiezo = 7600;           % [kg/m^3]

            app.Materials.elasticityMatrixPiezo(:,:,1) = elasticity_matrix_piezo(...
                app.Materials.youngModulusPiezoYE11(1,1),...
                app.Materials.youngModulusPiezoYE33(1,1),...
                app.Materials.youngModulusPiezoYE55(1,1),...
                app.Materials.poissonRatioPiezo(1,1));
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,1),...
                app.Materials.chargeConstantd33(1,1),...
                app.Materials.chargeConstantd15(1,1),...
                app.Materials.relativePermittivityEpsrS11(1,1),...
                app.Materials.relativePermittivityEpsrS33(1,1),...
                app.Materials.elasticityMatrixPiezo(:,:,1));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,1) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,1) = chargeConstantMatrixe;
            app.isMaterialAssigned = false;
            app.isExcitationAssigned = false;
            app.isActuatorAssigned = false;
            app.isSensorAssigned = false;
            app.Excitation.hannSignalNameList = ["HannSignal1","HannSignal2","HannSignal3","HannSignal4"];
            app.Excitation.chirpSignalNameList = ["ChirpSignal1","ChirpSignal2","ChirpSignal3","ChirpSignal4"];
            app.Excitation.gaussSignalNameList = ["GaussSignal1","GaussSignal2","GaussSignal3","GaussSignal4"];
            
            app.HannSignalNodeHandle(1) = app.HannSignal1Node;    
            app.Excitation.actuatorTagList = [];
            app.Excitation.positiveElectrodeTagList = [];
            app.Excitation.negativeElectrodeTagList = [];
            app.Excitation.nSamples = 2^19;              % total number of samples
            app.Excitation.totalTime = 1e-3;             % total calculation time [s] % 
            app.Excitation.Hann(1).peakVoltage = 70;             % peak voltage [V]
            app.Excitation.Hann(1).initiationTime = 0e-4;        % excitation initiation time [s]
            app.Excitation.Hann(1).nCycles = 5;                  % number of cycles
            app.Excitation.Hann(1).carrierFrequency = 100e3;     % carrier frequency [Hz]
            % modulation frequency [Hz]
            app.Excitation.Hann(1).modulationFrequency = app.Excitation.Hann(1).carrierFrequency/...
                                                         app.Excitation.Hann(1).nCycles;
            app.Excitation.dt = app.Excitation.totalTime / (app.Excitation.nSamples - 1);
            app.Excitation.nHannSignals = 1;
            app.Excitation.nChirpSignals = 0;
            app.Excitation.nGaussSignals = 0;

            app.Output.nFrames = 512;
            app.Output.sampleInterval = floor(app.Excitation.nSamples/(app.Output.nFrames));

            expand(app.SignalsTree);
            app.HannCategory.expand;
            app.HannTimeFig.Visible = 'off';
            app.HannFrequencyFig.Visible = 'off';

            app.HannTimePlotHandle = plot(app.HannTimeFig,[],[]);
            hold(app.HannTimeFig,'on');
            app.HannFramesPlotHandle = plot(app.HannTimeFig,[],[]);
            app.HannFrequencyPlotHandle = plot(app.HannFrequencyFig,[],[]);
            app.UITableResponsePointsSelection.Enable = "off";
            app.ResponsePointsSelectionLabel.Enable = "off";  
            % help
            h = uihtml(app.HelpTab);
            level_up_dir = fullfile(app.absolute_path,'..');
            htmlpath = fullfile(level_up_dir,'docs','build','html');
            % disp(fullfile(htmlpath,'index.html'));
            h.HTMLSource =  fullfile(htmlpath,'index.html');
            h.Position = [10 1 1100 670];
            drawnow;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            input_path = ['.',filesep,'inputs',filesep];
            if ~exist(input_path, 'dir')
                mkdir(input_path);
            end 
            
            % Primary data structures
            AllData.SemMesh = app.SemMesh;
            AllData.Damage = app.Damage;
            AllData.Materials = app.Materials;
            AllData.Output = app.Output;
            AllData.Excitation = app.Excitation; 
            % Logical status
            AllData.isMeshLoaded = app.isMeshLoaded;
            AllData.isMeshConverted = app.isMeshConverted; 
            AllData.isMaterialAssigned = app.isMaterialAssigned;
            AllData.isActuatorAssigned = app.isActuatorAssigned;
            AllData.isSensorAssigned = app.isSensorAssigned;
            AllData.isExcitationAssigned = app.isExcitationAssigned;

            % Mesh Tab
            AllData.in_millimeters = app.in_millimeters; 
            AllData.DamageInterfaceSelectionData = app.UITableDamageInterfaceSelection.Data;
            % Materials Tab
            AllData.MaterialAssignmentTableData = app.UITableMaterialAssignment.Data;
            AllData.SolidMaterialNodeHandle = app.SolidMaterialNodeHandle;
            AllData.PiezoelectricMaterialNodeHandle = app.PiezoelectricMaterialNodeHandle;
           
            AllData.nSolidMaterials = app.nSolidMaterials;
            AllData.nPiezoelectricMaterials = app.nPiezoelectricMaterials;
            AllData.nAddedSolidMaterials = app.nAddedSolidMaterials;
            AllData.solidMaterialNameList =  app.Materials.solidMaterialNameList;
            AllData.piezoelectricMaterialNameList = app.Materials.piezoelectricMaterialNameList;
            AllData.materialTreeCategory = app.materialTreeCategory;
            AllData.SolidMaterialCategoryAssignment = app.SolidMaterialCategoryAssignment;

            % Transducers Tab
            AllData.ActuatorAssignmentTableData = app.UITableActuatorAssignment.Data;
            AllData.SensorAssignmentTableData = app.UITableSensorAssignment.Data;
            
            % Signal Tab
            AllData.ExcitationAssignmentTableData = app.UITableExcitationAssignment.Data;
            AllData.nHannSignals = app.nHannSignals;
            AllData.nChirpSignals = app.nChirpSignals;
            AllData.nGaussSignals = app.nGaussSignals;
            AllData.nAddedHannSignals = app.nAddedHannSignals;
                
            AllData.hannSignalNameList = app.Excitation.hannSignalNameList;
            AllData.signalTreeCategory = app.signalTreeCategory;
            AllData.HannSignalNodeHandle = app.HannSignalNodeHandle;

            % Processing Tab
            AllData.ResponsePointsSelectionTableData = app.UITableResponsePointsSelection.Data;
            
            AllData.isGPUavailable = app.isGPUavailable;
            AllData.SensorSelectionPreviewValue = app.SensorSelectionPreviewDropDown.Value;
            AllData.SensorSelectionPreviewItems = app.SensorSelectionPreviewDropDown.Items;
            
            AllData.gpuDeviceIndex = app.gpuDeviceIndex;
            
            % Filename line  
            filename = fullfile(input_path,app.inputFileName);
            
            
            % save(filename,'AllData');
            uisave('AllData',filename);
            app.FilenameEditField.FontColor = [0,0,1]; % blue
            app.FilenameEditFieldLabel.FontColor = [0,0,1]; % blue
           
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            input_path = ['.',filesep,'inputs',filesep];
            if ~exist(input_path, 'dir')
                mkdir(input_path);
            end 
            title = 'Select File';   
            defname = fullfile(input_path);
            [input_file_name_ext,ipath] = uigetfile('*.mat',title,defname); % for development 
            if isequal(input_file_name_ext,0)
               % disp('User selected Cancel');
            else
                app.SignalPreview.Visible = "off";
                app.SignalPreview.Toolbar.Visible = "off";
                [~,app.inputFileName,~] = fileparts(input_file_name_ext);
                app.FilenameEditField.Value = app.inputFileName;
                load([ipath,input_file_name_ext],'AllData');
                % Primary data structures
                app.SemMesh = AllData.SemMesh;
                app.Damage = AllData.Damage;
                app.Materials = AllData.Materials;
                app.Output = AllData.Output;
                app.Excitation = AllData.Excitation; 
                % Logical status
                app.isMeshLoaded = AllData.isMeshLoaded;
                app.isMeshConverted = AllData.isMeshConverted; 
                app.isMaterialAssigned = AllData.isMaterialAssigned;
                app.isActuatorAssigned = AllData.isActuatorAssigned;
                app.isSensorAssigned = AllData.isSensorAssigned;
                app.isExcitationAssigned = AllData.isExcitationAssigned;
                % table data
                app.UITableDamageInterfaceSelection.Data = AllData.DamageInterfaceSelectionData;
                
                app.UITableMaterialAssignment.Data = AllData.MaterialAssignmentTableData;
                app.UITableActuatorAssignment.Data = AllData.ActuatorAssignmentTableData;
                app.UITableSensorAssignment.Data = AllData.SensorAssignmentTableData;
                app.UITableExcitationAssignment.Data = AllData.ExcitationAssignmentTableData;
                app.UITableResponsePointsSelection.Data = AllData.ResponsePointsSelectionTableData;

                % Material Tab recovery
                % material tree
                app.MaterialsTree.SelectedNodes = [];
                app.nSolidMaterials = AllData.nSolidMaterials;
                app.nPiezoelectricMaterials = AllData.nPiezoelectricMaterials;
                app.nAddedSolidMaterials = AllData.nAddedSolidMaterials;
                app.Materials.solidMaterialNameList = AllData.solidMaterialNameList;
                app.Materials.piezoelectricMaterialNameList = AllData.piezoelectricMaterialNameList;
                app.materialTreeCategory = AllData.materialTreeCategory;
                deleteTreeNodes(app,app.SolidMaterialNodeHandle);
                app.SolidMaterialNodeHandle = [];
                deleteTreeNodes(app,app.PiezoelectricMaterialNodeHandle);
                app.PiezoelectricMaterialNodeHandle = [];
                
                app.SolidMaterialNodeHandle = gobjects(app.nSolidMaterials, 1);
                app.PiezoelectricMaterialNodeHandle = gobjects(app.nPiezoelectricMaterials, 1);
                app.SolidMaterialCategoryAssignment = AllData.SolidMaterialCategoryAssignment;
                
                for k = 1:app.nSolidMaterials
                     if(app.SolidMaterialCategoryAssignment(k) == 1)
                        app.SolidMaterialNodeHandle(k) = uitreenode(app.IsotropicMaterialCategory);
                    elseif (app.SolidMaterialCategoryAssignment(k) == 2)
                        app.SolidMaterialNodeHandle(k) = uitreenode(app.OrthotropicMaterialCategory);
                    end
                    
                    app.SolidMaterialNodeHandle(k).NodeData = AllData.SolidMaterialNodeHandle(k).NodeData;
                    app.SolidMaterialNodeHandle(k).Text = AllData.SolidMaterialNodeHandle(k).Text;
                end
                for k = 1:app.nPiezoelectricMaterials
                    app.PiezoelectricMaterialNodeHandle(k) = uitreenode(app.PiezoelectricMaterialCategory);
                    app.PiezoelectricMaterialNodeHandle(k).NodeData = ...
                        AllData.PiezoelectricMaterialNodeHandle(k).NodeData;
                    app.PiezoelectricMaterialNodeHandle(k).Text = ...
                        AllData.PiezoelectricMaterialNodeHandle(k).Text;
                end

                % Signals tree recovery
                app.SignalsTree.SelectedNodes = [];
                app.nHannSignals = AllData.nHannSignals;
                app.nChirpSignals = AllData.nChirpSignals;
                app.nGaussSignals = AllData.nGaussSignals;
                app.nAddedHannSignals = AllData.nAddedHannSignals;
                
                app.Excitation.hannSignalNameList = AllData.hannSignalNameList;
                app.signalTreeCategory = AllData.signalTreeCategory;
                deleteTreeNodes(app,app.HannSignalNodeHandle);
                app.HannSignalNodeHandle = [];
                
                app.HannSignalNodeHandle = gobjects(app.nHannSignals, 1);
                
                for k = 1:app.nHannSignals
                    app.HannSignalNodeHandle(k) = uitreenode(app.HannCategory);
                    app.HannSignalNodeHandle(k).NodeData = ...
                        AllData.HannSignalNodeHandle(k).NodeData;
                    app.HannSignalNodeHandle(k).Text = ...
                        AllData.HannSignalNodeHandle(k).Text;
                end
                

                % Mesh Tab recovery
                app.in_millimeters = AllData.in_millimeters; 
                
                if(app.in_millimeters)
                    app.meshUnitsButton_m.Value = 0;
                    app.meshUnitsButton_mm.Value = 1;
                else
                    app.meshUnitsButton_m.Value = 1;
                    app.meshUnitsButton_mm.Value = 0;
                end
                if(app.isMeshLoaded)
                    app.LoadMeshTextArea.Value = sprintf('Done. Mesh loaded');
                    app.SelectDamageInterfacesVolumesandCurvesLabel.Visible = "on";
                    app.TickanddropdownmenuLabel.Visible = "on";
                    app.UITableDamageInterfaceSelection.Visible = "on";
                    app.UITableDamageInterfaceSelection.Enable = "on";
                    updateDamageInterfaceSelectionTableColors(app);
                    app.ConvertMeshButton.Visible = 'on';
                    app.ConvertMeshButton.Enable = 'on';
                    app.ConvertMeshTextArea.Visible = 'on';
                    app.ConvertMeshTextArea.Enable = 'on';
                    if(app.isMeshConverted)
                        app.ConvertMeshButton.Enable = 'off';
                        app.ConvertMeshTextArea.Enable = 'off';
                        if(app.isVerbose)
                            app.ConvertMeshTextArea.Value = sprintf(...
                                'Done. Mesh converted. Materials can be assinged.');
                            drawnow;
                        end
                        app.isMeshConverted = true;
                        app.MaterialsAssignmentLabel.Enable = "on";
                        app.UITableMaterialAssignment.Enable = "on";
                        app.UITableDamageInterfaceSelection.Enable = "off";
                        app.MaterialsAssignmentLabel.Enable = "on";
                        app.UITableMaterialAssignment.Enable = "on";
                        app.UITableActuatorAssignment.Enable = "on";
                        app.ActuatorsAssignmentLabel.Enable = "on";
                        app.UITableSensorAssignment.Enable = "on";
                        app.SensorsAssignmentLabel.Enable = "on";
                        app.UITableResponsePointsSelection.Enable = "on";
                        app.ResponsePointsSelectionLabel.Enable = "on";  
                    end
                    
                    % Transducers Tab recovery
                    % actuators
                    
                    updateActuatorAssignmentTableColors(app);
                    selection = table2array(app.UITableActuatorAssignment.Data(:,3));
                    if(any(selection))
                        app.isActuatorAssigned = true;
                        fillExcitationAssignmentTable(app);
                        extractCurrentExcitationAssignment(app);
                        app.UITableExcitationAssignment.Enable = "on";
                        app.ExcitationAssignmentLabel.Enable = "on";
                    else
                        app.isActuatorAssigned = false;
                        app.UITableExcitationAssignment.Enable = "off";
                        app.ExcitationAssignmentLabel.Enable = "off";
                    end
                    % sensors
                    
                    updateSensorAssignmentTableColors(app);
                    selection = table2array(app.UITableSensorAssignment.Data(:,3));
                    if(any(selection))
                        app.isSensorAssigned = true;
                        app.SensorSelectionPreviewDropDown.Visible = "on";
                        app.SensorSelectionPreviewDropDownLabel.Visible = "on";
                        
                        PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';
                        items = string(PhysicalVolumeNames(selection)');
                        app.SensorSelectionPreviewDropDown.Items = items;
                        s1 = string(app.SensorSelectionPreviewDropDown.Value);
                        app.SignalPreview.Title.String = s1;
                    else
                        app.isSensorAssigned = false;
                        app.SensorSelectionPreviewDropDown.Visible = "off";
                        app.SensorSelectionPreviewDropDownLabel.Visible = "off";
                        
                    end
                   
                end
             
                % Signal Tab recovery
                app.TotalPropagationTimeEditField.Value = app.Excitation.totalTime*1e3;
                app.nSamplesEditField.Value = app.Excitation.nSamples;
                app.DeltaTEditField.Value = app.Excitation.dt*1e6;
                app.nTimeFramesEditField.Value = app.Output.nFrames;    
                % Processing Tab recovery
                app.Output.isDisplacementResponsePointsSelected = ...
                    AllData.Output.isDisplacementResponsePointsSelected;
                app.Output.isVelocityResponsePointsSelected = ...
                    AllData.Output.isVelocityResponsePointsSelected;
                
                app.isGPUavailable = AllData.isGPUavailable;
                app.SensorSelectionPreviewDropDown.Items = AllData.SensorSelectionPreviewItems;
                app.SensorSelectionPreviewDropDown.Value = AllData.SensorSelectionPreviewValue;
                app.Output.isDisplacementFramesSelected = AllData.Output.isDisplacementFramesSelected;
                app.Output.isVelocityFramesSelected = ...
                    AllData.Output.isVelocityFramesSelected;
                app.Output.isMatOutput = AllData.Output.isMatOutput;
                app.Output.isVtuOutput = AllData.Output.isVtuOutput;
                app.Output.isBinary = AllData.Output.isBinary;

                app.gpuDeviceIndex = AllData.gpuDeviceIndex;

                if(app.isGPUavailable)
                    [app.nGPUs,app.gpuDeviceIndexList] = gpuDeviceCount("available");
                    if(app.nGPUs == 0)
                        app.ProcessingUnit.Value = "CPU";
                        app.isGPUavailable = false;
                        app.GPUindexDropDown.Visible = "off";
                        app.GPUindexDropDownLabel.Visible = "off";
                    else
                        if(length(app.gpuDeviceIndexList)>=app.gpuDeviceIndex)
                            app.ProcessingUnit.Value = "GPU";
                            app.GPUindexDropDown.Items = string(app.gpuDeviceIndexList);
                            app.GPUindexDropDown.Value = app.GPUindexDropDown.Items(app.gpuDeviceIndex);
                            app.selectedGPU = gpuDevice(app.gpuDeviceIndex);
                            app.GPUindexDropDown.Visible = "on";
                            app.GPUindexDropDownLabel.Visible = "on";
                            app.SelectedGPUNameTextArea.Visible = "on";
                            app.SelectedGPUNameTextArea.Value = ...
                                [num2str(app.gpuDeviceIndex),' ',app.selectedGPU.Name];
                        end
                    end
                else
                    app.ProcessingUnit.Value = "CPU";
                end
                if(app.Output.isDisplacementFramesSelected)
                    app.DisplacementFramesCheckBox.Value = 1;
                else
                    app.DisplacementFramesCheckBox.Value = 0;
                end
                if(app.Output.isVelocityFramesSelected)
                    app.VelocityFramesCheckBox.Value = 1;
                else
                    app.VelocityFramesCheckBox.Value = 0;
                end
                if(app.Output.isDisplacementResponsePointsSelected)
                    app.DisplacementResponsePointsCheckBox.Value = 1;
                else
                    app.DisplacementResponsePointsCheckBox.Value = 0;
                end
                if(app.Output.isVelocityResponsePointsSelected)
                    app.VelocityResponsePointsCheckBox.Value = 1;
                else
                    app.VelocityResponsePointsCheckBox.Value = 0;
                end
                app.SignalPreview.Title.String = string(app.SensorSelectionPreviewDropDown.Value);
                
                if(app.Output.isMatOutput)
                    app.MatlabMatCheckBox.Value = 1;
                else
                   app.MatlabMatCheckBox.Value = 0;
                end
                if(app.Output.isVtuOutput)
                    app.ParaviewVtuCheckBox.Value = 1;
                    app.VtuFormatButtonGroup.Visible = "on";
                else
                    app.ParaviewVtuCheckBox.Value = 0;
                    app.VtuFormatButtonGroup.Visible = "off";
                end
                if (app.Output.isBinary)
                    app.ButtonAscii.Value = 0;
                    app.ButtonBinary.Value = 1;
                else
                    app.ButtonAscii.Value = 1;
                    app.ButtonBinary.Value = 0;
                end
                
                clear AllData
               
               
               drawnow;
            end
            app.FilenameEditField.FontColor = [0,0,1]; % blue
            app.FilenameEditFieldLabel.FontColor = [0,0,1]; % blue  
        end

        % Value changed function: CarrierFrequencyEditField
        function CarrierFrequencyEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.SignalsTree.SelectedNodes;
            iSignal = selectedNode.NodeData;
            app.Excitation.Hann(iSignal).carrierFrequency = 1e3*app.CarrierFrequencyEditField.Value;
            
            if(app.Excitation.Hann(iSignal).nCycles ~= 0)
                app.Excitation.Hann(iSignal).modulationFrequency  = ...
                    app.Excitation.Hann(iSignal).carrierFrequency / ...
                    app.Excitation.Hann(iSignal).nCycles;
                app.ModulationFrequencyEditField.Value = ...
                    app.Excitation.Hann(iSignal).modulationFrequency / 1e3;
            end
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: ModulationFrequencyEditField
        function ModulationFrequencyEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.SignalsTree.SelectedNodes;
            iSignal = selectedNode.NodeData;
            if(1e3*app.ModulationFrequencyEditField.Value >=  app.Excitation.Hann(iSignal).carrierFrequency)
                f = msgbox("Modulation frequency must be lower than carrier frequency","Error","error");
                return
            else
            app.Excitation.Hann(iSignal).modulationFrequency = 1e3*app.ModulationFrequencyEditField.Value;
            
            if(app.Excitation.Hann(iSignal).modulationFrequency ~= 0)
                app.Excitation.Hann(iSignal).nCycles = ...
                    app.Excitation.Hann(iSignal).carrierFrequency / ...
                    app.Excitation.Hann(iSignal).modulationFrequency;
                app.nCyclesEditField.Value = app.Excitation.Hann(iSignal).nCycles;
            end
            plotHannSignal(app);
            drawnow;
            end
        end

        % Value changed function: TotalPropagationTimeEditField
        function TotalPropagationTimeEditFieldValueChanged(app, event)
            changeFilenameColor(app); 
            app.Excitation.totalTime = app.TotalPropagationTimeEditField.Value/1e3;
            if(app.Excitation.nSamples ~= 1)
                app.Excitation.dt = app.Excitation.totalTime / (app.Excitation.nSamples - 1);
                app.DeltaTEditField.Value =  1e6*app.Excitation.dt;
            end
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: nSamplesEditField
        function nSamplesEditFieldValueChanged(app, event)
            changeFilenameColor(app); 
            app.Excitation.nSamples = app.nSamplesEditField.Value;
            if(app.Excitation.nSamples ~= 1)
                app.Excitation.dt = app.Excitation.totalTime / double(app.Excitation.nSamples - 1);
                app.DeltaTEditField.Value =  1e6*app.Excitation.dt;
               
            end
            app.Output.sampleInterval = floor(app.Excitation.nSamples/(app.Output.nFrames));
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: ElasticityD11EditField
        function ElasticityD11EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(1,1,iSolid) = app.ElasticityD11EditField.Value*1e9;
        end

        % Value changed function: ElasticityD12EditField
        function ElasticityD12EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(1,2,iSolid) = app.ElasticityD12EditField.Value*1e9;
            app.Materials.elasticityMatrix(2,1,iSolid) = app.ElasticityD12EditField.Value*1e9;
            app.ElasticityD21EditField.Value = app.ElasticityD12EditField.Value;

            drawnow;
            
        end

        % Value changed function: ElasticityD13EditField
        function ElasticityD13EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(1,3,iSolid) = app.ElasticityD13EditField.Value*1e9;
            app.Materials.elasticityMatrix(3,1,iSolid) = app.ElasticityD13EditField.Value*1e9;
            app.ElasticityD31EditField.Value = app.ElasticityD13EditField.Value;
            drawnow;
            
        end

        % Value changed function: ElasticityD16EditField
        function ElasticityD16EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(1,4,iSolid) = app.ElasticityD16EditField.Value*1e9;
            app.Materials.elasticityMatrix(4,1,iSolid) = app.ElasticityD16EditField.Value*1e9;
            app.ElasticityD61EditField.Value = app.ElasticityD16EditField.Value;
            
            drawnow;
            
        end

        % Value changed function: ElasticityD22EditField
        function ElasticityD22EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(2,2,iSolid) = app.ElasticityD22EditField.Value*1e9;
        end

        % Value changed function: ElasticityD23EditField
        function ElasticityD23EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(2,3,iSolid) = app.ElasticityD23EditField.Value*1e9;
            app.Materials.elasticityMatrix(3,2,iSolid) = app.ElasticityD23EditField.Value*1e9;
            app.ElasticityD32EditField.Value = app.ElasticityD23EditField.Value;
            drawnow;
            
        end

        % Value changed function: ElasticityD26EditField
        function ElasticityD26EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(2,4,iSolid) = app.ElasticityD26EditField.Value*1e9;
            app.Materials.elasticityMatrix(4,2,iSolid) = app.ElasticityD26EditField.Value*1e9;
            app.ElasticityD62EditField.Value = app.ElasticityD26EditField.Value;
            drawnow;
            
        end

        % Value changed function: ElasticityD33EditField
        function ElasticityD33EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(3,3,iSolid) = app.ElasticityD33EditField.Value*1e9;
        end

        % Value changed function: ElasticityD36EditField
        function ElasticityD36EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(3,4,iSolid) = app.ElasticityD36EditField.Value*1e9;
            app.Materials.elasticityMatrix(4,3,iSolid) = app.ElasticityD36EditField.Value*1e9;
            app.ElasticityD63EditField.Value = app.ElasticityD36EditField.Value;
            drawnow;
            
        end

        % Value changed function: ElasticityD44EditField
        function ElasticityD44EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(6,6,iSolid) = app.ElasticityD44EditField.Value*1e9;
            
        end

        % Value changed function: ElasticityD45EditField
        function ElasticityD45EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(6,5,iSolid) = app.ElasticityD45EditField.Value*1e9;
            app.ElasticityD54EditField.Value = app.ElasticityD45EditField.Value;
            drawnow;
            
        end

        % Value changed function: ElasticityD55EditField
        function ElasticityD55EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(5,5,iSolid) = app.ElasticityD55EditField.Value*1e9;
            
        end

        % Value changed function: ElasticityD56EditField
        function ElasticityD56EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(5,4,iSolid) = app.ElasticityD56EditField.Value*1e9;
            
        end

        % Value changed function: ElasticityD66EditField
        function ElasticityD66EditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.elasticityMatrix(4,4,iSolid) = app.ElasticityD66EditField.Value*1e9;
            
        end

        % Value changed function: PeakVoltageEditField
        function PeakVoltageEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.SignalsTree.SelectedNodes;
            iSignal = selectedNode.NodeData;
            app.Excitation.Hann(iSignal).peakVoltage = app.PeakVoltageEditField.Value;
            plotHannSignal(app);
        end

        % Value changed function: nTimeFramesEditField
        function nTimeFramesEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            if(app.nTimeFramesEditField.Value > app.Excitation.nSamples)
                msgbox("Number of time frames must be lower than the number of samples",...
                    "Error","error");
                return
            else
                app.Output.nFrames = app.nTimeFramesEditField.Value;
                app.Output.sampleInterval = floor(app.Excitation.nSamples/(app.Output.nFrames));
                plotHannSignal(app);
            end
        end

        % Value changed function: DisplacementResponsePointsCheckBox
        function DisplacementResponsePointsCheckBoxValueChanged(app, event)
            changeFilenameColor(app);
            if(app.DisplacementResponsePointsCheckBox.Value == 1)
                app.Output.isDisplacementResponsePointsSelected = true;
            elseif (app.DisplacementResponsePointsCheckBox.Value == 0)
                app.Output.isDisplacementResponsePointsSelected = false;
            end
            
        end

        % Value changed function: VelocityResponsePointsCheckBox
        function VelocityResponsePointsCheckBoxValueChanged(app, event)
            changeFilenameColor(app);
            if(app.VelocityResponsePointsCheckBox.Value == 1)
                app.Output.isVelocityResponsePointsSelected = true;
            elseif (app.VelocityResponsePointsCheckBox.Value == 0)
                app.Output.isVelocityResponsePointsSelected = false;
            end
        end

        % Value changed function: ProcessingUnit
        function ProcessingUnitValueChanged(app, event)
            changeFilenameColor(app);
            if(app.ProcessingUnit.Value == "GPU")
                [app.nGPUs,app.gpuDeviceIndexList] = gpuDeviceCount("available");
                if(app.nGPUs == 0)
                    app.ProcessingUnit.Value = "CPU";
                    app.GPUindexDropDown.Visible = "off";
                    app.GPUindexDropDownLabel.Visible = "off";
                    msgbox("No available gpu card found","Error","error");
                else
                    app.isGPUavailable = true;
                    app.GPUindexDropDown.Items = string(app.gpuDeviceIndexList);
                    app.GPUindexDropDown.Value = app.GPUindexDropDown.Items(1);
                    app.gpuDeviceIndex = app.gpuDeviceIndexList(1);
                    app.selectedGPU = gpuDevice(app.gpuDeviceIndex);
                    app.GPUindexDropDown.Visible = "on";
                    app.GPUindexDropDownLabel.Visible = "on";
                    app.SelectedGPUNameTextArea.Visible = "on";
                    app.SelectedGPUNameTextArea.Value = [num2str(1),' ',app.selectedGPU.Name];

                end
            elseif (app.ProcessingUnit.Value == "CPU")
                app.isGPUavailable = false;
                app.GPUindexDropDown.Visible = "off";
                app.GPUindexDropDownLabel.Visible = "off";
                app.GPUindexDropDownLabel.Visible = "off";
                app.SelectedGPUNameTextArea.Visible = "off";
            end
            drawnow;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            app.inputFileName = app.FilenameEditField.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = "off";
            app.SignalPreview.Visible = "on";
            app.SignalPreview.Toolbar.Visible = "off";
            cla(app.SignalPreview); % clear SignalPreview axes 
            axis(app.SignalPreview,'auto');
            drawnow;
            try    
                app.calculationProgress = uiprogressdlg(app.waveProSHMgui,'Title','Please wait',...
                    'Message','Computing...',...
                    'Cancelable','on');
                [isCalculationCanceled,isCalculationFailed] = solid3d_engine(...
                    app.inputFileName, ...
                    app.SemMesh, ...
                    app.Materials,...
                    app.Excitation, ...
                    app.Output,...
                    app.isGPUavailable, ...
                    app.isVerbose, ...
                    app.SignalPreview,...
                    app.calculationProgress);

                if(isCalculationCanceled)
                    app.NotificationTextArea.Value = 'Calculations canceled';
                else
                    app.NotificationTextArea.Value = 'Calculations finished';
                end
                if(isCalculationFailed)
                    app.NotificationTextArea.Value = 'Calculations failed (integration error)';
                end
                close(app.calculationProgress);
            catch
                app.NotificationTextArea.Value = 'Calculations failed';
                close(app.calculationProgress);
            end
            reset(app.selectedGPU);
            
            app.SignalPreview.Toolbar.Visible = "on";
            app.RunButton.Enable = "on";
            drawnow;
            
        end

        % Button pushed function: LoadMeshButton
        function LoadMesh(app, event)
            
            app.isMeshConverted = false;
            app.MaterialsAssignmentLabel.Enable = "off";
            app.UITableMaterialAssignment.Enable = "off";
            app.UITableExcitationAssignment.Enable = "off";
            app.LoadMeshTextArea.Value = '';
            app.ConvertMeshTextArea.Value = '';
            app.SelectDamageInterfacesVolumesandCurvesLabel.Visible = 'off';
            app.TickanddropdownmenuLabel.Visible = 'off';
            app.UITableDamageInterfaceSelection.Visible = 'off';
            app.ConvertMeshButton.Visible = 'off';
            app.ConvertMeshTextArea.Visible = 'off';
            app.SignalPreview.Visible = "off";
            app.SignalPreview.Toolbar.Visible = "off";
            drawnow;

            app.Damage.interfaceBoundaryTagList = [];
            app.Damage.volumeTagList = [];
            app.Damage.interfaceTagList = [];
            mesh_output_path = (['.',filesep,'mesh',filesep]);
            if ~exist(mesh_output_path, 'dir')
                mkdir(mesh_output_path);
            end 
            mesh_input_path = ['.',filesep,'gmsh_out',filesep,'msh',filesep];
            if ~exist(mesh_input_path, 'dir')
                mkdir(mesh_input_path);
            end 
            title = 'Select File';
            % defname = fullfile([mesh_input_path,'simple_plate_delam_order_3.msh']); % for test mode
            defname = fullfile(mesh_input_path);% for development
            [mesh_file_name_ext,mesh_path] = uigetfile('*.msh',title,defname);  
            if isequal(mesh_file_name_ext,0)
               % disp('User selected Cancel');
            else
                % read msh file and crate structures: msh, PhysicalNames, save it to .mat file
                app.LoadMeshTextArea.Value = sprintf('Parsing mesh...');
                loadMeshProgress = uiprogressdlg(app.waveProSHMgui,'Title','Please wait',...
                    'Message','Parsing mesh',...
                    'Indeterminate','on');
                drawnow;
                try
                    [msh,PhysicalNames] = read_msh_mesh([mesh_path,mesh_file_name_ext],app.isVerbose);
                    [~,app.meshName,~] = fileparts(mesh_file_name_ext);
                    % retrieve element order
                    if(isfield(msh,'HEXAS'))
                        elementOrder = 1;
                    end
                    if(isfield(msh,'HEXAS27'))
                        elementOrder = 2;     
                    end
                    if(isfield(msh,'HEXAS64'))
                        elementOrder = 3;     
                    end
                    if(isfield(msh,'HEXAS125'))
                        elementOrder = 4;     
                    end
                    if(isfield(msh,'HEXAS216'))
                        elementOrder = 5;     
                    end
                    if(isfield(msh,'HEXAS349'))
                        elementOrder = 6;     
                    end
                catch
                    close(loadMeshProgress)
                    msgbox("Reading mesh problem","Error","error");
                    return
                end
                if(app.isVerbose)
                    disp(['element order ',num2str(elementOrder)]);
                end
                
                if(app.meshUnitsButton_mm.Value == 1)
                    app.in_millimeters = true;
                elseif(app.meshUnitsButton_m.Value == 1)
                    app.in_millimeters = false;
                end
                try
                    app.SemMesh = gmsh2sem_mesh(msh,PhysicalNames,elementOrder,app.in_millimeters);
                catch
                    close(loadMeshProgress);
                    msgbox("Mesh compatibility issue","Error","error");
                    return
                end
                clear msh;
                app.LoadMeshTextArea.Value = sprintf('Done. Mesh loaded');
                close(loadMeshProgress);
                drawnow;
                if(~isfield(app.SemMesh,'PhysicalSurfaces'))
                        msgbox("Mesh compatibility issue. PhysicalSurfaces are not defined.",...
                            "Error","error");
                        return
                end
                %% Damage interface selection
                fillDamageInterfaceSelectionTable(app);

                app.ConvertMeshButton.Visible = 'on';
                app.ConvertMeshButton.Enable = 'on';
                app.ConvertMeshTextArea.Visible = 'on';
                app.ConvertMeshTextArea.Enable = 'on';
                app.isMeshLoaded = true;
                changeFilenameColor(app); 
            end
            
        end

        % Close request function: waveProSHMgui
        function waveProSHMguiCloseRequest(app, event)
            warning('off','all');
            rmpath((fullfile(app.absolute_path,'mesher')));
            rmpath((fullfile(app.absolute_path,'wave_pro_shm','solid3d_engine')));
            delete(app)       
        end

        % Selection changed function: MeshunitsButtonGroup
        function meshUnits(app, event)
            changeFilenameColor(app);
            selectedButton = app.MeshunitsButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text,"[m]"))
                app.in_millimeters = false;
            elseif(strcmp(selectedButton.Text,"[mm]"))
                app.in_millimeters = true;
            end 
        end

        % Cell edit callback: UITableDamageInterfaceSelection
        function damageInterfaceSelectionEdited(app, event)
            changeFilenameColor(app);
            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            selection = table2array(app.UITableDamageInterfaceSelection.Data(:,3));
            PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';
            optionsVolumes = categorical(PhysicalVolumeNames,'Protected', true);
            PhysicalLineNames = {app.SemMesh.PhysicalLines.name}';
            optionsLines = categorical(PhysicalLineNames,'Protected', true);
            
            if (col == 3)
                if(selection(row))
                    app.UITableDamageInterfaceSelection.Data{row,4} = ...
                    {optionsVolumes(1)};
                    app.UITableDamageInterfaceSelection.Data{row,5} = ...
                    {optionsLines(1)};
                else
                    app.UITableDamageInterfaceSelection.Data{row,4} = ...
                    {categorical(NaN,'Protected', true)};
                    app.UITableDamageInterfaceSelection.Data{row,5} = ...
                    {categorical(NaN,'Protected', true)};
                end
            end
            
            interfaceTags = table2array(app.UITableDamageInterfaceSelection.Data(:,1));
            % Physical Surface tag numbers for damage interfaces
            app.Damage.interfaceTagList = interfaceTags(selection);    
            physicalVolumeTagList = vertcat( app.SemMesh.PhysicalVolumes.physicalTag );   
            % Physical Volume tag numbers for damage volumes
            app.Damage.volumeTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalVolumeName = string(app.UITableDamageInterfaceSelection.Data{iSelection,4});
                    for k = 1:length(PhysicalVolumeNames)
                        if(physicalVolumeName == PhysicalVolumeNames{k})
                            c = c + 1;
                            app.Damage.volumeTagList(c) = physicalVolumeTagList(k);
                        end
                    end
                end
            end
            
            physicalLineTagList = vertcat( app.SemMesh.PhysicalLines.physicalTag );
            % Physical Lines tag numbers for damage interface boundaries
            app.Damage.interfaceBoundaryTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalLineName = string(app.UITableDamageInterfaceSelection.Data{iSelection,5});
                    for k = 1:length(PhysicalLineNames)
                        if(physicalLineName == PhysicalLineNames{k})
                            c = c + 1;
                            app.Damage.interfaceBoundaryTagList(c) = physicalLineTagList(k);
                        end
                    end
                end
            end
            updateDamageInterfaceSelectionTableColors(app);
        end

        % Button pushed function: ConvertMeshButton
        function convertMesh(app, event)
            if(nnz(~app.Damage.volumeTagList) > 0 || nnz(~app.Damage.interfaceBoundaryTagList) > 0)
                f = msgbox("Damage volume and boundary are not set properly","Error","error");
                return
            end
            [app.Damage] = retrieve_mesh_damage_elements(app.SemMesh,app.Damage);
            % split nodes at delaminations
            if(app.isVerbose)
                % disp('splitting nodes');
                app.ConvertMeshTextArea.Value = sprintf('splitting nodes');
                drawnow;
            end
            [app.SemMesh] = split_interface_nodes(app.SemMesh,app.Damage);
            [nElements,nElement3DNodes] = size(app.SemMesh.element3DNodes);
            [nNodes,~] = size(app.SemMesh.nodeCoordinates);
            nDofs=3*nNodes; % number of degrees of freedom
            app.SemMesh.nDofs = nDofs;
            app.SemMesh.nNodes = nNodes;
            % Piotr Fiborek implementation of local-global node mapping for mesh coloring
            [~, ff] = mode(app.SemMesh.element3DNodes,'all');
            ff = max(ff);
            denominator = ff:32;
            nBaskets = find(sum(mod(nElement3DNodes,denominator),1)==0,1,'first');
            nBaskets = denominator(1,nBaskets);
            if(app.isVerbose)
                % disp([num2str(nBaskets),' baskets: node mapping for parallel mesh...']);
                app.ConvertMeshTextArea.Value = ...
                    sprintf('%s baskets: node mapping for parallel mesh...', num2str(nBaskets));
                drawnow;
            end
            [app.SemMesh.I_G,app.SemMesh.I_L] = local_global_nodes_map(...
                nElement3DNodes,app.SemMesh.element3DNodes,nBaskets);
            app.SemMesh.nBaskets = nBaskets;
            % connectivity matrix by degrees of freedom
            element3DDofs=zeros(nElements,3*nElement3DNodes);
            for iElement = 1:nElements
             element3DDofs(iElement,1:3:end) = 3*app.SemMesh.element3DNodes(iElement,:)-2;
             element3DDofs(iElement,2:3:end) = 3*app.SemMesh.element3DNodes(iElement,:)-1;
             element3DDofs(iElement,3:3:end) = 3*app.SemMesh.element3DNodes(iElement,:);
            end
            app.SemMesh(1).element3DDofs = element3DDofs;
            
            
            app.ConvertMeshButton.Enable = 'off';
            app.ConvertMeshTextArea.Enable = 'off';
            if(app.isVerbose)
                app.ConvertMeshTextArea.Value = sprintf(...
                    'Done. Mesh converted. Materials can be assinged.');
                drawnow;
            end
            % save mesh
            mesh_output_path = (['.',filesep,'mesh',filesep]);
            if ~exist(mesh_output_path, 'dir')
                mkdir(mesh_output_path);
            end 
            
            SemMesh = app.SemMesh;
            meshfile = [mesh_output_path,'sem_mesh_',app.meshName,'.mat'];
            save(meshfile,'SemMesh');

            % allow materials assignment in Materials Tab
            app.isMeshConverted = true;
            app.MaterialsAssignmentLabel.Enable = "on";
            app.UITableMaterialAssignment.Enable = "on";
           
            % fill Material Assignment table
            fillMaterialAssignmentTable(app);
            extractCurrentMaterialAssignment(app);
            fillActuatorAssignmentTable(app);
            fillSensorAssignmentTable(app);
            app.UITableDamageInterfaceSelection.Enable = "off";
            fillResponsePointsSelectionTable(app);
            changeFilenameColor(app); 
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            %TODO: add dialog box for material assignment: possible only after preparing mesh
            % hiding tabs
            % https://www.mathworks.com/matlabcentral/answers/1798230-hiding-tabs-breaks-app-built-with-app-designer-in-matlab-2021b-that-works-in-2018b
            
        end

        % Button down function: MaterialsTab
        function expandMaterialsTree(app, event)
             % Expand the tree
            expand(app.MaterialsTree);
            app.SolidMaterialCategory.expand;
            app.IsotropicMaterialCategory.expand;
            app.OrthotropicMaterialCategory.expand;
            app.PiezoelectricMaterialCategory.expand;
        end

        % Selection changed function: MaterialsTree
        function materialSelectionChanged(app, event)
            selectedNodes = app.MaterialsTree.SelectedNodes;
            if(strcmp(selectedNodes.Text,'Solid Material'))
                app.materialTreeCategory = 1;
            end
            if(strcmp(selectedNodes.Text,'Piezoelectric Material'))
                app.materialTreeCategory = 3;
            end
            if(strcmp(selectedNodes.Text,'Isotropic'))
                app.materialTreeCategory = 1;
            end
            if(strcmp(selectedNodes.Text,'Orthotropic'))
                app.materialTreeCategory = 2;
            end

            if(~strcmp(selectedNodes.Text,'Solid Material') && ~strcmp(selectedNodes.Text,'Piezoelectric Material'))
                if(strcmp(selectedNodes.Parent.Text,'Isotropic'))
                    app.materialTreeCategory = 1;
                end
                if(strcmp(selectedNodes.Parent.Text,'Orthotropic'))
                    app.materialTreeCategory = 2;
                end
                if(strcmp(selectedNodes.Parent.Text,'Piezoelectric Material'))
                    app.materialTreeCategory = 3;
                end
            end
            
            if(~isempty(selectedNodes.NodeData))
                app.MaterialNameEditField.Value = selectedNodes.Text;
                drawnow;
            else
                app.MaterialNameEditField.Value = '1';
                drawnow;
            end
            if(strcmp(selectedNodes.Text,'Solid Material') || ...
               strcmp(selectedNodes.Text,'Piezoelectric Material') ||...
               strcmp(selectedNodes.Text,'Isotropic') ||...
               strcmp(selectedNodes.Text,'Orthotropic'))
                app.MaterialNameEditField.Visible = 'off';
                app.MaterialNameEditFieldLabel.Visible = 'off';
                app.YoungsModulusEditField.Visible = "off";
                app.YoungsModulusEditFieldLabel.Visible = "off";
                app.PoissonRatioEditField.Visible = "off";
                app.PoissonRatioEditFieldLabel.Visible = "off";
                app.MassDensityEditField.Visible = "off";
                app.MassDensityEditFieldLabel.Visible = "off";
                app.DampingCoefficientXEditField.Visible = "off";
                app.DampingCoefficientXEditFieldLabel.Visible = "off";
                app.DampingCoefficientYEditField.Visible = "off";
                app.DampingCoefficientYEditFieldLabel.Visible = "off";
                app.DampingCoefficientZEditField.Visible = "off";
                app.DampingCoefficientZEditFieldLabel.Visible = "off";
                
                app.RelativePermittivityEpsrS33EditField.Visible = "off";
                app.RelativePermittivityEpsrS33EditFieldLabel.Visible = "off";
                app.RelativePermittivityEpsrS11EditField.Visible = "off";
                app.RelativePermittivityEpsrS11EditFieldLabel.Visible = "off";
                app.ChargeConstantd15EditField.Visible = "off";
                app.ChargeConstantd15EditFieldLabel.Visible = "off";
                app.ChargeConstantd33EditField.Visible = "off";
                app.ChargeConstantd33EditFieldLabel.Visible = "off";
                app.ChargeConstantd31EditField.Visible = "off";
                app.ChargeConstantd31EditFieldLabel.Visible = "off";
                app.YoungsModulusPiezoYE11EditField.Visible = "off";
                app.YoungsModulusPiezoYE11EditFieldLabel.Visible = "off";
                app.YoungsModulusPiezoYE33EditField.Visible = "off";
                app.YoungsModulusPiezoYE33EditFieldLabel.Visible = "off";
                app.YoungsModulusPiezoYE55EditField.Visible = "off";
                app.YoungsModulusPiezoYE55EditFieldLabel.Visible = "off";
                app.DampingCoefficientPiezoXEditField.Visible = "off";
                app.DampingCoefficientPiezoXEditFieldLabel.Visible = "off";
                app.DampingCoefficientPiezoYEditField.Visible = "off";
                app.DampingCoefficientPiezoYEditFieldLabel.Visible = "off";
                app.DampingCoefficientPiezoZEditField.Visible = "off";
                app.DampingCoefficientPiezoZEditFieldLabel.Visible = "off";
                app.MassDensityPiezoEditField.Visible = "off";
                app.MassDensityPiezoEditFieldLabel.Visible = "off";
                app.PoissonRatioPiezoEditField.Visible = "off";
                app.PoissonRatioPiezoEditFieldLabel.Visible = "off";

                app.ElasticityMatrixLabel.Visible = "off";
                app.EqualLabel.Visible = "off";
                app.StrainVectorLabel.Visible = "off";
                app.StressVectorLabel.Visible = "off";
                app.ElasticityD11EditField.Visible = "off";
                app.ElasticityD12EditField.Visible = "off";
                app.ElasticityD13EditField.Visible = "off";
                app.ElasticityD14EditField.Visible = "off";
                app.ElasticityD15EditField.Visible = "off";
                app.ElasticityD16EditField.Visible = "off";
                app.ElasticityD21EditField.Visible = "off";
                app.ElasticityD22EditField.Visible = "off";
                app.ElasticityD23EditField.Visible = "off";
                app.ElasticityD24EditField.Visible = "off";
                app.ElasticityD25EditField.Visible = "off";
                app.ElasticityD26EditField.Visible = "off";
                app.ElasticityD31EditField.Visible = "off";
                app.ElasticityD32EditField.Visible = "off";
                app.ElasticityD33EditField.Visible = "off";
                app.ElasticityD34EditField.Visible = "off";
                app.ElasticityD35EditField.Visible = "off";
                app.ElasticityD36EditField.Visible = "off";
                app.ElasticityD41EditField.Visible = "off";
                app.ElasticityD42EditField.Visible = "off";
                app.ElasticityD43EditField.Visible = "off";
                app.ElasticityD44EditField.Visible = "off";
                app.ElasticityD45EditField.Visible = "off";
                app.ElasticityD46EditField.Visible = "off";
                app.ElasticityD51EditField.Visible = "off";
                app.ElasticityD52EditField.Visible = "off";
                app.ElasticityD53EditField.Visible = "off";
                app.ElasticityD54EditField.Visible = "off";
                app.ElasticityD55EditField.Visible = "off";
                app.ElasticityD56EditField.Visible = "off";
                app.ElasticityD61EditField.Visible = "off";
                app.ElasticityD62EditField.Visible = "off";
                app.ElasticityD63EditField.Visible = "off";
                app.ElasticityD64EditField.Visible = "off";
                app.ElasticityD65EditField.Visible = "off";
                app.ElasticityD66EditField.Visible = "off";
                app.MassDensityOrthotropicEditField.Visible = "off";
                app.MassDensityOrthotripicEditFieldLabel.Visible = "off";
                app.DampingCoefficientOrthotropicXEditField.Visible = "off";
                app.DampingCoefficientOrthotropicXEditFieldLabel.Visible = "off";
                app.DampingCoefficientOrthotropicYEditField.Visible = "off";
                app.DampingCoefficientOrthotropicYEditFieldLabel.Visible = "off";
                app.DampingCoefficientOrthotropicZEditField.Visible = "off";
                app.DampingCoefficientOrthotropicZEditFieldLabel.Visible = "off";

                drawnow;
            else
                app.MaterialNameEditField.Visible = 'on';
                app.MaterialNameEditFieldLabel.Visible = 'on';
                
                if(app.materialTreeCategory == 1) % visibility for isotropic properties
                    iSolid = selectedNodes.NodeData;
                    
                    app.YoungsModulusEditField.Value = app.Materials.youngModulus(iSolid)/1e9;
                    app.PoissonRatioEditField.Value = app.Materials.poissonRatio(iSolid);
                    app.MassDensityEditField.Value = app.Materials.density(iSolid);
                    app.DampingCoefficientXEditField.Value = app.Materials.damping(1,iSolid);
                    app.DampingCoefficientYEditField.Value = app.Materials.damping(2,iSolid);
                    app.DampingCoefficientZEditField.Value = app.Materials.damping(3,iSolid);

                    app.YoungsModulusEditField.Visible = "on";        
                    app.YoungsModulusEditFieldLabel.Visible = "on";
                    app.PoissonRatioEditField.Visible = "on";
                    app.PoissonRatioEditFieldLabel.Visible = "on";
                    app.MassDensityEditField.Visible = "on";
                    app.MassDensityEditFieldLabel.Visible = "on";
                    app.DampingCoefficientXEditField.Visible = "on";
                    app.DampingCoefficientXEditFieldLabel.Visible = "on";
                    app.DampingCoefficientYEditField.Visible = "on";
                    app.DampingCoefficientYEditFieldLabel.Visible = "on";
                    app.DampingCoefficientZEditField.Visible = "on";
                    app.DampingCoefficientZEditFieldLabel.Visible = "on";

                    app.RelativePermittivityEpsrS33EditField.Visible = "off";
                    app.RelativePermittivityEpsrS33EditFieldLabel.Visible = "off";
                    app.RelativePermittivityEpsrS11EditField.Visible = "off";
                    app.RelativePermittivityEpsrS11EditFieldLabel.Visible = "off";
                    app.ChargeConstantd15EditField.Visible = "off";
                    app.ChargeConstantd15EditFieldLabel.Visible = "off";
                    app.ChargeConstantd33EditField.Visible = "off";
                    app.ChargeConstantd33EditFieldLabel.Visible = "off";
                    app.ChargeConstantd31EditField.Visible = "off";
                    app.ChargeConstantd31EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE11EditField.Visible = "off";
                    app.YoungsModulusPiezoYE11EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE33EditField.Visible = "off";
                    app.YoungsModulusPiezoYE33EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE55EditField.Visible = "off";
                    app.YoungsModulusPiezoYE55EditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoXEditField.Visible = "off";
                    app.DampingCoefficientPiezoXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoYEditField.Visible = "off";
                    app.DampingCoefficientPiezoYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoZEditField.Visible = "off";
                    app.DampingCoefficientPiezoZEditFieldLabel.Visible = "off";
                    app.MassDensityPiezoEditField.Visible = "off";
                    app.MassDensityPiezoEditFieldLabel.Visible = "off";
                    app.PoissonRatioPiezoEditField.Visible = "off";
                    app.PoissonRatioPiezoEditFieldLabel.Visible = "off";

                    app.ElasticityMatrixLabel.Visible = "off";
                    app.EqualLabel.Visible = "off";
                    app.StrainVectorLabel.Visible = "off";
                    app.StressVectorLabel.Visible = "off";
                    app.ElasticityD11EditField.Visible = "off";
                    app.ElasticityD12EditField.Visible = "off";
                    app.ElasticityD13EditField.Visible = "off";
                    app.ElasticityD14EditField.Visible = "off";
                    app.ElasticityD15EditField.Visible = "off";
                    app.ElasticityD16EditField.Visible = "off";
                    app.ElasticityD21EditField.Visible = "off";
                    app.ElasticityD22EditField.Visible = "off";
                    app.ElasticityD23EditField.Visible = "off";
                    app.ElasticityD24EditField.Visible = "off";
                    app.ElasticityD25EditField.Visible = "off";
                    app.ElasticityD26EditField.Visible = "off";
                    app.ElasticityD31EditField.Visible = "off";
                    app.ElasticityD32EditField.Visible = "off";
                    app.ElasticityD33EditField.Visible = "off";
                    app.ElasticityD34EditField.Visible = "off";
                    app.ElasticityD35EditField.Visible = "off";
                    app.ElasticityD36EditField.Visible = "off";
                    app.ElasticityD41EditField.Visible = "off";
                    app.ElasticityD42EditField.Visible = "off";
                    app.ElasticityD43EditField.Visible = "off";
                    app.ElasticityD44EditField.Visible = "off";
                    app.ElasticityD45EditField.Visible = "off";
                    app.ElasticityD46EditField.Visible = "off";
                    app.ElasticityD51EditField.Visible = "off";
                    app.ElasticityD52EditField.Visible = "off";
                    app.ElasticityD53EditField.Visible = "off";
                    app.ElasticityD54EditField.Visible = "off";
                    app.ElasticityD55EditField.Visible = "off";
                    app.ElasticityD56EditField.Visible = "off";
                    app.ElasticityD61EditField.Visible = "off";
                    app.ElasticityD62EditField.Visible = "off";
                    app.ElasticityD63EditField.Visible = "off";
                    app.ElasticityD64EditField.Visible = "off";
                    app.ElasticityD65EditField.Visible = "off";
                    app.ElasticityD66EditField.Visible = "off";
                    app.MassDensityOrthotropicEditField.Visible = "off";
                    app.MassDensityOrthotripicEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicXEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicYEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicZEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicZEditFieldLabel.Visible = "off";

                elseif(app.materialTreeCategory == 2) % visibility for orthotropic properties

                    iSolid = selectedNodes.NodeData;
                    app.MassDensityOrthotropicEditField.Value = app.Materials.density(iSolid);
                    app.DampingCoefficientOrthotropicXEditField.Value = app.Materials.damping(1,iSolid);
                    app.DampingCoefficientOrthotropicYEditField.Value = app.Materials.damping(2,iSolid);
                    app.DampingCoefficientOrthotropicZEditField.Value = app.Materials.damping(3,iSolid);
                    
                    app.ElasticityD11EditField.Value = app.Materials.elasticityMatrix(1,1,iSolid)/1e9;
                    app.ElasticityD12EditField.Value = app.Materials.elasticityMatrix(1,2,iSolid)/1e9;
                    app.ElasticityD21EditField.Value = app.Materials.elasticityMatrix(2,1,iSolid)/1e9;
                    app.ElasticityD13EditField.Value = app.Materials.elasticityMatrix(1,3,iSolid)/1e9;  
                    app.ElasticityD31EditField.Value = app.Materials.elasticityMatrix(3,1,iSolid)/1e9; 
                    app.ElasticityD16EditField.Value = app.Materials.elasticityMatrix(1,4,iSolid)/1e9; 
                    app.ElasticityD61EditField.Value = app.Materials.elasticityMatrix(4,1,iSolid)/1e9;
                    app.ElasticityD22EditField.Value = app.Materials.elasticityMatrix(2,2,iSolid)/1e9;
                    app.ElasticityD23EditField.Value = app.Materials.elasticityMatrix(2,3,iSolid)/1e9;
                    app.ElasticityD32EditField.Value = app.Materials.elasticityMatrix(3,2,iSolid)/1e9;
                    app.ElasticityD26EditField.Value = app.Materials.elasticityMatrix(2,4,iSolid)/1e9;
                    app.ElasticityD62EditField.Value = app.Materials.elasticityMatrix(4,2,iSolid)/1e9;
                    app.ElasticityD33EditField.Value = app.Materials.elasticityMatrix(3,3,iSolid)/1e9;
                    app.ElasticityD36EditField.Value = app.Materials.elasticityMatrix(3,4,iSolid)/1e9;
                    app.ElasticityD63EditField.Value = app.Materials.elasticityMatrix(4,3,iSolid)/1e9;
                    app.ElasticityD44EditField.Value = app.Materials.elasticityMatrix(6,6,iSolid)/1e9;
                    app.ElasticityD45EditField.Value = app.Materials.elasticityMatrix(6,5,iSolid)/1e9;
                    app.ElasticityD55EditField.Value = app.Materials.elasticityMatrix(5,5,iSolid)/1e9;                    
                    app.ElasticityD66EditField.Value = app.Materials.elasticityMatrix(4,4,iSolid)/1e9;

                    app.YoungsModulusEditField.Visible = "off";
                    app.YoungsModulusEditFieldLabel.Visible = "off";
                    app.PoissonRatioEditField.Visible = "off";
                    app.PoissonRatioEditFieldLabel.Visible = "off";
                    app.MassDensityEditField.Visible = "off";
                    app.MassDensityEditFieldLabel.Visible = "off";
                    app.DampingCoefficientXEditField.Visible = "off";
                    app.DampingCoefficientXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientYEditField.Visible = "off";
                    app.DampingCoefficientYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientZEditField.Visible = "off";
                    app.DampingCoefficientZEditFieldLabel.Visible = "off";

                    app.RelativePermittivityEpsrS33EditField.Visible = "off";
                    app.RelativePermittivityEpsrS33EditFieldLabel.Visible = "off";
                    app.RelativePermittivityEpsrS11EditField.Visible = "off";
                    app.RelativePermittivityEpsrS11EditFieldLabel.Visible = "off";
                    app.ChargeConstantd15EditField.Visible = "off";
                    app.ChargeConstantd15EditFieldLabel.Visible = "off";
                    app.ChargeConstantd33EditField.Visible = "off";
                    app.ChargeConstantd33EditFieldLabel.Visible = "off";
                    app.ChargeConstantd31EditField.Visible = "off";
                    app.ChargeConstantd31EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE11EditField.Visible = "off";
                    app.YoungsModulusPiezoYE11EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE33EditField.Visible = "off";
                    app.YoungsModulusPiezoYE33EditFieldLabel.Visible = "off";
                    app.YoungsModulusPiezoYE55EditField.Visible = "off";
                    app.YoungsModulusPiezoYE55EditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoXEditField.Visible = "off";
                    app.DampingCoefficientPiezoXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoYEditField.Visible = "off";
                    app.DampingCoefficientPiezoYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientPiezoZEditField.Visible = "off";
                    app.DampingCoefficientPiezoZEditFieldLabel.Visible = "off";
                    app.MassDensityPiezoEditField.Visible = "off";
                    app.MassDensityPiezoEditFieldLabel.Visible = "off";
                    app.PoissonRatioPiezoEditField.Visible = "off";
                    app.PoissonRatioPiezoEditFieldLabel.Visible = "off";

                    app.ElasticityMatrixLabel.Visible = "on";
                    app.EqualLabel.Visible = "on";
                    app.StrainVectorLabel.Visible = "on";
                    app.StressVectorLabel.Visible = "on";
                    app.ElasticityD11EditField.Visible = "on";
                    app.ElasticityD12EditField.Visible = "on";
                    app.ElasticityD13EditField.Visible = "on";
                    app.ElasticityD14EditField.Visible = "on";
                    app.ElasticityD15EditField.Visible = "on";
                    app.ElasticityD16EditField.Visible = "on";
                    app.ElasticityD21EditField.Visible = "on";
                    app.ElasticityD22EditField.Visible = "on";
                    app.ElasticityD23EditField.Visible = "on";
                    app.ElasticityD24EditField.Visible = "on";
                    app.ElasticityD25EditField.Visible = "on";
                    app.ElasticityD26EditField.Visible = "on";
                    app.ElasticityD31EditField.Visible = "on";
                    app.ElasticityD32EditField.Visible = "on";
                    app.ElasticityD33EditField.Visible = "on";
                    app.ElasticityD34EditField.Visible = "on";
                    app.ElasticityD35EditField.Visible = "on";
                    app.ElasticityD36EditField.Visible = "on";
                    app.ElasticityD41EditField.Visible = "on";
                    app.ElasticityD42EditField.Visible = "on";
                    app.ElasticityD43EditField.Visible = "on";
                    app.ElasticityD44EditField.Visible = "on";
                    app.ElasticityD45EditField.Visible = "on";
                    app.ElasticityD46EditField.Visible = "on";
                    app.ElasticityD51EditField.Visible = "on";
                    app.ElasticityD52EditField.Visible = "on";
                    app.ElasticityD53EditField.Visible = "on";
                    app.ElasticityD54EditField.Visible = "on";
                    app.ElasticityD55EditField.Visible = "on";
                    app.ElasticityD56EditField.Visible = "on";
                    app.ElasticityD61EditField.Visible = "on";
                    app.ElasticityD62EditField.Visible = "on";
                    app.ElasticityD63EditField.Visible = "on";
                    app.ElasticityD64EditField.Visible = "on";
                    app.ElasticityD65EditField.Visible = "on";
                    app.ElasticityD66EditField.Visible = "on";
                    app.MassDensityOrthotropicEditField.Visible = "on";
                    app.MassDensityOrthotripicEditFieldLabel.Visible = "on";
                    app.DampingCoefficientOrthotropicXEditField.Visible = "on";
                    app.DampingCoefficientOrthotropicXEditFieldLabel.Visible = "on";
                    app.DampingCoefficientOrthotropicYEditField.Visible = "on";
                    app.DampingCoefficientOrthotropicYEditFieldLabel.Visible = "on";
                    app.DampingCoefficientOrthotropicZEditField.Visible = "on";
                    app.DampingCoefficientOrthotropicZEditFieldLabel.Visible = "on";

                elseif (app.materialTreeCategory == 3) % visibility for piezolectric properties

                    iPiezoMat = selectedNodes.NodeData;
                    app.RelativePermittivityEpsrS33EditField.Value = app.Materials.relativePermittivityEpsrS33(1,iPiezoMat);                     
                    app.RelativePermittivityEpsrS11EditField.Value = app.Materials.relativePermittivityEpsrS11(1,iPiezoMat);                    
                    app.ChargeConstantd15EditField.Value = app.Materials.chargeConstantd15(1,iPiezoMat)*1e12;                    
                    app.ChargeConstantd33EditField.Value = app.Materials.chargeConstantd33(1,iPiezoMat)*1e12;                     
                    app.ChargeConstantd31EditField.Value = app.Materials.chargeConstantd31(1,iPiezoMat)*1e12;                     
                    app.YoungsModulusPiezoYE11EditField.Value = app.Materials.youngModulusPiezoYE11(1,iPiezoMat)/1e9;                    
                    app.YoungsModulusPiezoYE33EditField.Value = app.Materials.youngModulusPiezoYE33(1,iPiezoMat)/1e9;                    
                    app.YoungsModulusPiezoYE55EditField.Value = app.Materials.youngModulusPiezoYE55(1,iPiezoMat)/1e9;
                    
                    app.DampingCoefficientPiezoXEditField.Value = app.Materials.dampingPiezo(1,iPiezoMat);
                    app.DampingCoefficientPiezoYEditField.Value = app.Materials.dampingPiezo(2,iPiezoMat);
                    app.DampingCoefficientPiezoZEditField.Value = app.Materials.dampingPiezo(3,iPiezoMat);
                    app.MassDensityPiezoEditField.Value = app.Materials.densityPiezo(1,iPiezoMat);
                    app.PoissonRatioPiezoEditField.Value = app.Materials.poissonRatioPiezo(1,iPiezoMat);
                    

                    app.RelativePermittivityEpsrS33EditField.Visible = "on";
                    app.RelativePermittivityEpsrS33EditFieldLabel.Visible = "on";
                    app.RelativePermittivityEpsrS11EditField.Visible = "on";
                    app.RelativePermittivityEpsrS11EditFieldLabel.Visible = "on";
                    app.ChargeConstantd15EditField.Visible = "on";
                    app.ChargeConstantd15EditFieldLabel.Visible = "on";
                    app.ChargeConstantd33EditField.Visible = "on";
                    app.ChargeConstantd33EditFieldLabel.Visible = "on";
                    app.ChargeConstantd31EditField.Visible = "on";
                    app.ChargeConstantd31EditFieldLabel.Visible = "on";
                    app.YoungsModulusPiezoYE11EditField.Visible = "on";
                    app.YoungsModulusPiezoYE11EditFieldLabel.Visible = "on";
                    app.YoungsModulusPiezoYE33EditField.Visible = "on";
                    app.YoungsModulusPiezoYE33EditFieldLabel.Visible = "on";
                    app.YoungsModulusPiezoYE55EditField.Visible = "on";
                    app.YoungsModulusPiezoYE55EditFieldLabel.Visible = "on";
                    app.DampingCoefficientPiezoXEditField.Visible = "on";
                    app.DampingCoefficientPiezoXEditFieldLabel.Visible = "on";
                    app.DampingCoefficientPiezoYEditField.Visible = "on";
                    app.DampingCoefficientPiezoYEditFieldLabel.Visible = "on";
                    app.DampingCoefficientPiezoZEditField.Visible = "on";
                    app.DampingCoefficientPiezoZEditFieldLabel.Visible = "on";
                    app.MassDensityPiezoEditField.Visible = "on";
                    app.MassDensityPiezoEditFieldLabel.Visible = "on";
                    app.PoissonRatioPiezoEditField.Visible = "on";
                    app.PoissonRatioPiezoEditFieldLabel.Visible = "on";

                    app.YoungsModulusEditField.Visible = "off";
                    app.YoungsModulusEditFieldLabel.Visible = "off";
                    app.PoissonRatioEditField.Visible = "off";
                    app.PoissonRatioEditFieldLabel.Visible = "off";
                    app.MassDensityEditField.Visible = "off";
                    app.MassDensityEditFieldLabel.Visible = "off";
                    app.DampingCoefficientXEditField.Visible = "off";
                    app.DampingCoefficientXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientYEditField.Visible = "off";
                    app.DampingCoefficientYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientZEditField.Visible = "off";
                    app.DampingCoefficientZEditFieldLabel.Visible = "off";

                    app.ElasticityMatrixLabel.Visible = "off";
                    app.EqualLabel.Visible = "off";
                    app.StrainVectorLabel.Visible = "off";
                    app.StressVectorLabel.Visible = "off";
                    app.ElasticityD11EditField.Visible = "off";
                    app.ElasticityD12EditField.Visible = "off";
                    app.ElasticityD13EditField.Visible = "off";
                    app.ElasticityD14EditField.Visible = "off";
                    app.ElasticityD15EditField.Visible = "off";
                    app.ElasticityD16EditField.Visible = "off";
                    app.ElasticityD21EditField.Visible = "off";
                    app.ElasticityD22EditField.Visible = "off";
                    app.ElasticityD23EditField.Visible = "off";
                    app.ElasticityD24EditField.Visible = "off";
                    app.ElasticityD25EditField.Visible = "off";
                    app.ElasticityD26EditField.Visible = "off";
                    app.ElasticityD31EditField.Visible = "off";
                    app.ElasticityD32EditField.Visible = "off";
                    app.ElasticityD33EditField.Visible = "off";
                    app.ElasticityD34EditField.Visible = "off";
                    app.ElasticityD35EditField.Visible = "off";
                    app.ElasticityD36EditField.Visible = "off";
                    app.ElasticityD41EditField.Visible = "off";
                    app.ElasticityD42EditField.Visible = "off";
                    app.ElasticityD43EditField.Visible = "off";
                    app.ElasticityD44EditField.Visible = "off";
                    app.ElasticityD45EditField.Visible = "off";
                    app.ElasticityD46EditField.Visible = "off";
                    app.ElasticityD51EditField.Visible = "off";
                    app.ElasticityD52EditField.Visible = "off";
                    app.ElasticityD53EditField.Visible = "off";
                    app.ElasticityD54EditField.Visible = "off";
                    app.ElasticityD55EditField.Visible = "off";
                    app.ElasticityD56EditField.Visible = "off";
                    app.ElasticityD61EditField.Visible = "off";
                    app.ElasticityD62EditField.Visible = "off";
                    app.ElasticityD63EditField.Visible = "off";
                    app.ElasticityD64EditField.Visible = "off";
                    app.ElasticityD65EditField.Visible = "off";
                    app.ElasticityD66EditField.Visible = "off";
                    app.MassDensityOrthotropicEditField.Visible = "off";
                    app.MassDensityOrthotripicEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicXEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicXEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicYEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicYEditFieldLabel.Visible = "off";
                    app.DampingCoefficientOrthotropicZEditField.Visible = "off";
                    app.DampingCoefficientOrthotropicZEditFieldLabel.Visible = "off";
                end
                drawnow;
            end
        end

        % Button pushed function: AddMaterialButton
        function addMaterial(app, event)
            changeFilenameColor(app);
            app.MaterialNameEditField.Visible = 'on';
            app.MaterialNameEditFieldLabel.Visible = 'on';
            if(app.materialTreeCategory == 1 )
                
                app.nSolidMaterials = app.nSolidMaterials + 1;
                app.nAddedSolidMaterials = app.nAddedSolidMaterials + 1;
                app.SolidMaterialCategoryAssignment(app.nSolidMaterials) = 1;
                app.MaterialNameEditField.Value = ['SolidMaterial',num2str(app.nAddedSolidMaterials)];
                app.Materials.solidMaterialNameList(app.nSolidMaterials) = app.MaterialNameEditField.Value;
                % add node to Solid Materials tree
                app.SolidMaterialNodeHandle(app.nSolidMaterials) = uitreenode(app.IsotropicMaterialCategory);
                app.SolidMaterialNodeHandle(app.nSolidMaterials).NodeData = app.nSolidMaterials;
                app.SolidMaterialNodeHandle(app.nSolidMaterials).Text = app.MaterialNameEditField.Value;
                app.MaterialsTree.SelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials);
                % initialize material properties values
                app.Materials.youngModulus(1,app.nSolidMaterials) = 0;
                app.Materials.poissonRatio(1,app.nSolidMaterials) = 0;
                app.Materials.density(1,app.nSolidMaterials) = 0;
                app.Materials.damping(:,app.nSolidMaterials) = zeros(3,1);
                app.Materials.elasticityMatrix(:,:,app.nSolidMaterials) = zeros(6,6);
                
                % trigger tree selection change
                S = struct();
                S.SelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials);
                S.PreviousSelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials-1);
                S.Source = app.MaterialsTree;
                S.EventName = 'SelectionChanged';
                materialSelectionChanged(app, S);

            elseif(app.materialTreeCategory == 2)
                
                app.nSolidMaterials = app.nSolidMaterials + 1;
                app.nAddedSolidMaterials = app.nAddedSolidMaterials + 1;
                app.SolidMaterialCategoryAssignment(app.nSolidMaterials) = 2;
                app.MaterialNameEditField.Value = ['SolidMaterial',num2str(app.nAddedSolidMaterials)];  
                app.Materials.solidMaterialNameList(app.nSolidMaterials) = app.MaterialNameEditField.Value;
                % add node to Solid Materials tree
                app.SolidMaterialNodeHandle(app.nSolidMaterials) = uitreenode(app.OrthotropicMaterialCategory);
                app.SolidMaterialNodeHandle(app.nSolidMaterials).NodeData = app.nSolidMaterials;
                app.SolidMaterialNodeHandle(app.nSolidMaterials).Text = app.MaterialNameEditField.Value;
                app.MaterialsTree.SelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials);
                
                % initialize material properties values
                app.Materials.density(1,app.nSolidMaterials) = 0; % [kg/m^3]
                app.Materials.elasticityMatrix(:,:,app.nSolidMaterials) = zeros(6,6);
                app.Materials.damping(:,app.nSolidMaterials) = zeros(3,1);

                % trigger tree selection change
                S = struct();
                S.SelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials);
                S.PreviousSelectedNodes = app.SolidMaterialNodeHandle(app.nSolidMaterials-1);
                S.Source = app.MaterialsTree;
                S.EventName = 'SelectionChanged';
                materialSelectionChanged(app, S);

            elseif (app.materialTreeCategory == 3)
                
                app.nPiezoelectricMaterials = app.nPiezoelectricMaterials + 1;
                app.nAddedPiezoelectricMaterials = app.nAddedPiezoelectricMaterials + 1;
                
                app.MaterialNameEditField.Value = ['PiezoelectricMaterial',num2str(app.nAddedPiezoelectricMaterials)];
                app.Materials.piezoelectricMaterialNameList(app.nPiezoelectricMaterials) = app.MaterialNameEditField.Value;
                % add node to Piezoelectric Materials tree
                app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials) = uitreenode(app.PiezoelectricMaterialCategory);
                app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials).NodeData = app.nPiezoelectricMaterials;
                app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials).Text = app.MaterialNameEditField.Value;
                app.MaterialsTree.SelectedNodes = app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials);

                % initialize material properties values
                app.Materials.youngModulusPiezoYE11(1,app.nPiezoelectricMaterials) = 0;   % [Pa]
                app.Materials.youngModulusPiezoYE33(1,app.nPiezoelectricMaterials) = 0; % [Pa]
                app.Materials.youngModulusPiezoYE55(1,app.nPiezoelectricMaterials) = 0; % [Pa]
                app.Materials.chargeConstantd31(1,app.nPiezoelectricMaterials) = 0;   % [C/N]
                app.Materials.chargeConstantd33(1,app.nPiezoelectricMaterials) = 0;    % [C/N]
                app.Materials.chargeConstantd15(1,app.nPiezoelectricMaterials) = 0;    % [C/N]
                app.Materials.relativePermittivityEpsrS11(1,app.nPiezoelectricMaterials) = 0; % [-]
                app.Materials.relativePermittivityEpsrS33(1,app.nPiezoelectricMaterials)  = 0; % [-]
                app.Materials.poissonRatioPiezo(1,app.nPiezoelectricMaterials) = 0; % [-]
                app.Materials.densityPiezo(1,app.nPiezoelectricMaterials) = 0;           % [kg/m^3]
                % electric permittivity (Eps_S) [F/m]
                app.Materials.pztPermittivity(:,:,app.nPiezoelectricMaterials) = zeros(3,3);
                % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
                app.Materials.pztCoupling(:,:,app.nPiezoelectricMaterials) = zeros(3,6);
               
                app.Materials.dampingPiezo(:,app.nPiezoelectricMaterials) = zeros(3,1);
                app.Materials.elasticityMatrixPiezo(:,:,app.nPiezoelectricMaterials) = zeros(6,6);

                % trigger tree selection change
                S = struct();
                S.SelectedNodes = app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials);
                S.PreviousSelectedNodes = app.PiezoelectricMaterialNodeHandle(app.nPiezoelectricMaterials-1);
                S.Source = app.MaterialsTree;
                S.EventName = 'SelectionChanged';
                materialSelectionChanged(app, S);

            end
            
            fillMaterialAssignmentTable(app);
            if(app.isMeshConverted)
                extractCurrentMaterialAssignment(app);
            end
            drawnow;
        end

        % Value changed function: MaterialNameEditField
        function materialNameChanged(app, event)
            changeFilenameColor(app);
            value = app.MaterialNameEditField.Value;
            for iSolid = 1:app.nSolidMaterials
                if(strcmp(value,app.Materials.solidMaterialNameList(iSolid)))
                    msgbox("Solid material name already exist","Error","error");
                    return;
                end
            end
            for iPiezoMat = 1:app.nPiezoelectricMaterials
                if(strcmp(value,app.Materials.piezoelectricMaterialNameList(iPiezoMat)))
                    msgbox("Piezo material name already exist","Error","error");
                    return;
                end
            end
            selectedNode = app.MaterialsTree.SelectedNodes;
            oldName = selectedNode.Text;
            selectedNode.Text = value;
            if(app.materialTreeCategory == 1)
                iSolid = selectedNode.NodeData;
                app.Materials.solidMaterialNameList(iSolid) = value;
            elseif(app.materialTreeCategory == 2)
                iSolid = selectedNode.NodeData;
                app.Materials.solidMaterialNameList(iSolid) = value;
            elseif(app.materialTreeCategory == 3)
                iPiezoMat = selectedNode.NodeData;
                app.Materials.piezoelectricMaterialNameList(iPiezoMat) = value;
            end
            if(app.isMaterialAssigned)
                for j = 1 : length(app.Materials.assignment)
                    s2 = string(app.Materials.assignment(j).name);
                    if(strcmp(oldName,s2))
                        app.Materials.assignment(j).name = value;
                        break
                    end
                end
            end
            fillMaterialAssignmentTable(app);
            drawnow;
        end

        % Value changed function: YoungsModulusEditField
        function YoungsModulusEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.youngModulus(1,iSolid) = 1e9*app.YoungsModulusEditField.Value;
            [app.Materials.elasticityMatrix(:,:,iSolid)] = elasticity_matrix_isotropy(...
                app.Materials.youngModulus(iSolid),app.Materials.poissonRatio(iSolid));
            
        end

        % Value changed function: PoissonRatioEditField
        function PoissonRatioEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.poissonRatio(1,iSolid) = app.PoissonRatioEditField.Value;
        end

        % Value changed function: MassDensityEditField
        function MassDensityEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.density(1,iSolid) = app.MassDensityEditField.Value;
            
        end

        % Value changed function: DampingCoefficientZEditField
        function DampingCoefficientZEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(3,iSolid) = app.DampingCoefficientZEditField.Value;
        end

        % Value changed function: DampingCoefficientYEditField
        function DampingCoefficientYEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(2,iSolid) = app.DampingCoefficientYEditField.Value;
        end

        % Value changed function: DampingCoefficientXEditField
        function DampingCoefficientXEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(1,iSolid) = app.DampingCoefficientXEditField.Value;
        end

        % Value changed function: DampingCoefficientOrthotropicZEditField
        function DampingCoefficientOrthotropicZEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(3,iSolid) = app.DampingCoefficientOrthotropicZEditField.Value;
        end

        % Value changed function: DampingCoefficientOrthotropicYEditField
        function DampingCoefficientOrthotropicYEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(2,iSolid) = app.DampingCoefficientOrthotropicYEditField.Value;
            
        end

        % Value changed function: DampingCoefficientOrthotropicXEditField
        function DampingCoefficientOrthotropicXEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.damping(1,iSolid) = app.DampingCoefficientOrthotropicXEditField.Value;
        end

        % Value changed function: DampingCoefficientPiezoZEditField
        function DampingCoefficientPiezoZEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.dampingPiezo(3,iPiezoMat) = app.DampingCoefficientPiezoZEditField.Value;
        end

        % Value changed function: DampingCoefficientPiezoYEditField
        function DampingCoefficientPiezoYEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.dampingPiezo(2,iPiezoMat) = app.DampingCoefficientPiezoYEditField.Value;
        end

        % Value changed function: DampingCoefficientPiezoXEditField
        function DampingCoefficientPiezoXEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.dampingPiezo(1,iPiezoMat) = app.DampingCoefficientPiezoXEditField.Value;       
        end

        % Value changed function: MassDensityOrthotropicEditField
        function MassDensityOrthotropicEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iSolid = selectedNode.NodeData;
            app.Materials.density(1,iSolid) = app.MassDensityOrthotropicEditField.Value;
        end

        % Value changed function: YoungsModulusPiezoYE11EditField
        function YoungsModulusPiezoYE11EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.youngModulusPiezoYE11(1,iPiezoMat) = app.YoungsModulusPiezoYE11EditField.Value*1e9;
            app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat) = elasticity_matrix_piezo(...
                app.Materials.youngModulusPiezoYE11(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE33(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE55(1,iPiezoMat),...
                app.Materials.poissonRatioPiezo(1,iPiezoMat));
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: YoungsModulusPiezoYE33EditField
        function YoungsModulusPiezoYE33EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.youngModulusPiezoYE33(1,iPiezoMat) = app.YoungsModulusPiezoYE33EditField.Value*1e9; 
            app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat) = elasticity_matrix_piezo(...
                app.Materials.youngModulusPiezoYE11(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE33(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE55(1,iPiezoMat),...
                app.Materials.poissonRatioPiezo(1,iPiezoMat));
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: YoungsModulusPiezoYE55EditField
        function YoungsModulusPiezoYE55EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.youngModulusPiezoYE55(1,iPiezoMat) = app.YoungsModulusPiezoYE55EditField.Value*1e9;  
            app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat) = elasticity_matrix_piezo(...
                app.Materials.youngModulusPiezoYE11(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE33(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE55(1,iPiezoMat),...
                app.Materials.poissonRatioPiezo(1,iPiezoMat));
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: PoissonRatioPiezoEditField
        function PoissonRatioPiezoEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.poissonRatioPiezo(1,iPiezoMat) = app.PoissonRatioPiezoEditField.Value;
            app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat) = elasticity_matrix_piezo(...
                app.Materials.youngModulusPiezoYE11(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE33(1,iPiezoMat),...
                app.Materials.youngModulusPiezoYE55(1,iPiezoMat),...
                app.Materials.poissonRatioPiezo(1,iPiezoMat));
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: MassDensityPiezoEditField
        function MassDensityPiezoEditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.densityPiezo(1,iPiezoMat) = app.MassDensityPiezoEditField.Value;
        end

        % Value changed function: ChargeConstantd31EditField
        function ChargeConstantd31EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.chargeConstantd31(1,iPiezoMat) = app.ChargeConstantd31EditField.Value*1e-12;
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: ChargeConstantd33EditField
        function ChargeConstantd33EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.chargeConstantd33(1,iPiezoMat) = app.ChargeConstantd33EditField.Value*1e-12;
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: ChargeConstantd15EditField
        function ChargeConstantd15EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.chargeConstantd15(1,iPiezoMat) = app.ChargeConstantd15EditField.Value*1e-12;
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: RelativePermittivityEpsrS11EditField
        function RelativePermittivityEpsrS11EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.relativePermittivityEpsrS11(1,iPiezoMat) = app.RelativePermittivityEpsrS11EditField.Value;
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Value changed function: RelativePermittivityEpsrS33EditField
        function RelativePermittivityEpsrS33EditFieldChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.MaterialsTree.SelectedNodes;
            iPiezoMat = selectedNode.NodeData;
            app.Materials.relativePermittivityEpsrS33(1,iPiezoMat) = app.RelativePermittivityEpsrS33EditField.Value;
            [~,permittivityMatrixEpsS,chargeConstantMatrixe] = piezoelectricity_matrices(...
                app.Materials.chargeConstantd31(1,iPiezoMat),...
                app.Materials.chargeConstantd33(1,iPiezoMat),...
                app.Materials.chargeConstantd15(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS11(1,iPiezoMat),...
                app.Materials.relativePermittivityEpsrS33(1,iPiezoMat),...
                app.Materials.elasticityMatrixPiezo(:,:,iPiezoMat));
            % electric permittivity (Eps_S) [F/m]
            app.Materials.pztPermittivity(:,:,iPiezoMat) = permittivityMatrixEpsS;
            % piezoelectric coupling coefficients (stress-charge form) [C/m^2]
            app.Materials.pztCoupling(:,:,iPiezoMat) = chargeConstantMatrixe;
        end

        % Cell edit callback: UITableMaterialAssignment
        function materialAssignment(app, event)
            changeFilenameColor(app);
            extractCurrentMaterialAssignment(app);
            app.isMaterialAssigned = true;
        end

        % Button pushed function: RemoveMaterialButton
        function removeMaterial(app, event)
            
            selectedNode = app.MaterialsTree.SelectedNodes;
            
            if(~isempty(selectedNode))  
                index = selectedNode.NodeData;
                if(~isempty(index))
                    if(index > 1)
                        
                        changeFilenameColor(app);
                        if(app.materialTreeCategory == 1 )
                            
                            % remove material property values from database
                            app.Materials.solidMaterialNameList(index) = [];
                            app.Materials.youngModulus(:,index) = [];
                            app.Materials.poissonRatio(:,index) = [];
                            app.Materials.density(:,index) = [];
                            app.Materials.damping(:,index) = [];
                            app.Materials.elasticityMatrix(:,:,index) = [];
                            % update tree indexes
                            for iSolid = 1 : app.nSolidMaterials
                                if(app.SolidMaterialNodeHandle(iSolid).NodeData > index)
                                    app.SolidMaterialNodeHandle(iSolid).NodeData = ...
                                        app.SolidMaterialNodeHandle(iSolid).NodeData - 1;
                                end
                            end
                            app.SolidMaterialNodeHandle(index) = [];
                            app.nSolidMaterials = app.nSolidMaterials - 1;
                            % delete node tree
                            selectedNode.delete;  
                            
                            app.MaterialsTree.SelectedNodes = app.IsotropicMaterialCategory;

                            % trigger tree selection change
                            S = struct();
                            S.SelectedNodes = app.MaterialsTree.SelectedNodes;
                            S.PreviousSelectedNodes = [];
                            S.Source = app.MaterialsTree;
                            S.EventName = 'SelectionChanged';
                            materialSelectionChanged(app, S);
            
                        elseif(app.materialTreeCategory == 2)
                            if(index > 2)
                                % remove material property values from database
                                app.Materials.solidMaterialNameList(index) = [];
                                app.Materials.density(:,index) = [];
                                app.Materials.elasticityMatrix(:,:,index) = [];
                                app.Materials.damping(:,index) = [];
            
                                % update tree indexes
                                for iSolid = 1 : app.nSolidMaterials
                                    if(app.SolidMaterialNodeHandle(iSolid).NodeData > index)
                                        app.SolidMaterialNodeHandle(iSolid).NodeData = ...
                                            app.SolidMaterialNodeHandle(iSolid).NodeData - 1;
                                    end
                                end
                                app.SolidMaterialNodeHandle(index) = [];
                                app.nSolidMaterials = app.nSolidMaterials - 1;
                                % delete node tree
                                selectedNode.delete;  
                
                                app.MaterialsTree.SelectedNodes = app.OrthotropicMaterialCategory;
    
                                % trigger tree selection change
                                S = struct();
                                S.SelectedNodes = app.MaterialsTree.SelectedNodes;
                                S.PreviousSelectedNodes = [];
                                S.Source = app.MaterialsTree;
                                S.EventName = 'SelectionChanged';
                                materialSelectionChanged(app, S);
                            else
                                msgbox("Default material cannot be removed","Warning","warn");
                                return
                            end
            
                        elseif (app.materialTreeCategory == 3)
                            
                            
                            % remove material property values from database
                            app.Materials.piezoelectricMaterialNameList(index) = [];
                            app.Materials.youngModulusPiezoYE11(:,index) = [];   
                            app.Materials.youngModulusPiezoYE33(:,index) = []; 
                            app.Materials.youngModulusPiezoYE55(:,index) = [];
                            app.Materials.chargeConstantd31(:,index) = [];   
                            app.Materials.chargeConstantd33(:,index) = [];    
                            app.Materials.chargeConstantd15(:,index) = [];    
                            app.Materials.relativePermittivityEpsrS11(:,index) = []; 
                            app.Materials.relativePermittivityEpsrS33(:,index) = []; 
                            app.Materials.poissonRatioPiezo(:,index) = []; 
                            app.Materials.densityPiezo(:,index) = [];        
                            app.Materials.pztPermittivity(:,:,index) = [];
                            app.Materials.pztCoupling(:,:,index) = [];
                            app.Materials.dampingPiezo(:,index) = [];
                            % update Piezoelectric Materials tree indexes 
                            for iPiezoMat = 1 : app.nPiezoelectricMaterials
                                if(app.PiezoelectricMaterialNodeHandle(iPiezoMat).NodeData > index)
                                    app.PiezoelectricMaterialNodeHandle(iPiezoMat).NodeData = ...
                                        app.PiezoelectricMaterialNodeHandle(iPiezoMat).NodeData - 1;
                                end
                            end
                            app.PiezoelectricMaterialNodeHandle(index) = [];
                            app.nPiezoelectricMaterials = app.nPiezoelectricMaterials - 1;
                            % delete node tree
                            selectedNode.delete;  
            
                            app.MaterialsTree.SelectedNodes = app.PiezoelectricMaterialCategory;

                            % trigger tree selection change
                            S = struct();
                            S.SelectedNodes = app.MaterialsTree.SelectedNodes;
                            S.PreviousSelectedNodes = [];
                            S.Source = app.MaterialsTree;
                            S.EventName = 'SelectionChanged';
                            materialSelectionChanged(app, S);
            
                        end
                       
                        fillMaterialAssignmentTable(app);
                        extractCurrentMaterialAssignment(app);
                        drawnow;
                    else
                        if(index == 1)
                            msgbox("Default material cannot be removed","Warning","warn");
                            return
                        end
                    end
                end
            end

        end

        % Cell edit callback: UITableActuatorAssignment
        function actuatorAssignmentChanged(app, event)
            changeFilenameColor(app);
            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            
            selection = table2array(app.UITableActuatorAssignment.Data(:,3));
            % check deselection
            if(col == 3)
                if(~selection(row))       
                    s1 = string(app.UITableActuatorAssignment.Data{row,2});
                    for j = 1 :length(app.Excitation.assignment)
                        s2 = app.Excitation.assignment(j).name;
                        if(strcmp(s1,s2))
                            app.Excitation.assignment(j) = [];
                        end
                    end
                end
            end
            
            PhysicalSurfaceNames = {app.SemMesh.PhysicalSurfaces.name}';
            optionsElectrodePlus = categorical(PhysicalSurfaceNames,'Protected', true);
            optionsElectrodeMinus = categorical(PhysicalSurfaceNames,'Protected', true);
            if (col == 3)
                if(selection(row))
                    app.UITableActuatorAssignment.Data{row,4} = ...
                    {optionsElectrodePlus(1)};
                    app.UITableActuatorAssignment.Data{row,5} = ...
                    {optionsElectrodeMinus(1)};
                else
                    app.UITableActuatorAssignment.Data{row,4} = ...
                    {categorical(NaN,'Protected', true)};
                    app.UITableActuatorAssignment.Data{row,5} = ...
                    {categorical(NaN,'Protected', true)};
                end
            end
            
            volumeTags = table2array(app.UITableActuatorAssignment.Data(:,1));
            % Physical volume tag numbers for actuators
            app.Excitation.actuatorTagList = volumeTags(selection);  
            app.Excitation.nActuators = length(app.Excitation.actuatorTagList);
            physicalSurfaceTagList = vertcat( app.SemMesh.PhysicalSurfaces.physicalTag );   
            % Physical surface tag numbers for positive electrodes
            app.Excitation.positiveElectrodeTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalSurfaceName = string(app.UITableActuatorAssignment.Data{iSelection,4});
                    for k = 1:length(PhysicalSurfaceNames)
                        if(physicalSurfaceName == PhysicalSurfaceNames{k})
                            c = c + 1;
                            app.Excitation.positiveElectrodeTagList(1,c) = physicalSurfaceTagList(k);
                        end
                    end
                end
            end
            
            % Physical surface tag numbers for positive electrodes
            app.Excitation.negativeElectrodeTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalSurfaceName = string(app.UITableActuatorAssignment.Data{iSelection,5});
                    for k = 1:length(PhysicalSurfaceNames)
                        if(physicalSurfaceName == PhysicalSurfaceNames{k})
                            c = c + 1;
                            app.Excitation.negativeElectrodeTagList(1,c) = physicalSurfaceTagList(k);
                        end
                    end
                end
            end
            updateActuatorAssignmentTableColors(app);
            
            if(any(selection))
                app.isActuatorAssigned = true;
                fillExcitationAssignmentTable(app);
                extractCurrentExcitationAssignment(app);
                app.UITableExcitationAssignment.Enable = "on";
                app.ExcitationAssignmentLabel.Enable = "on";
            else
                app.isActuatorAssigned = false;
                app.UITableExcitationAssignment.Enable = "off";
                app.ExcitationAssignmentLabel.Enable = "off";
            end
            
            
        end

        % Cell edit callback: UITableSensorAssignment
        function sensorAssignmentChanged(app, event)
            changeFilenameColor(app);
            indices = event.Indices;
            row = indices(1);
            col = indices(2);
            selection = table2array(app.UITableSensorAssignment.Data(:,3));
     
            PhysicalSurfaceNames = {app.SemMesh.PhysicalSurfaces.name}';
            optionsElectrodePlus = categorical(PhysicalSurfaceNames,'Protected', true);
            optionsElectrodeMinus = categorical(PhysicalSurfaceNames,'Protected', true);
            if (col == 3)
                if(selection(row))
                    app.UITableSensorAssignment.Data{row,4} = ...
                    {optionsElectrodePlus(1)};
                    app.UITableSensorAssignment.Data{row,5} = ...
                    {optionsElectrodeMinus(1)};
                else
                    app.UITableSensorAssignment.Data{row,4} = ...
                    {categorical(NaN,'Protected', true)};
                    app.UITableSensorAssignment.Data{row,5} = ...
                    {categorical(NaN,'Protected', true)};
                end
            end
            
            volumeTags = table2array(app.UITableSensorAssignment.Data(:,1));
            % Physical volume tag numbers for Sensors
            app.Output.sensorTagList = volumeTags(selection);    
            app.Output.nSensors = length(app.Output.sensorTagList);
            physicalSurfaceTagList = vertcat( app.SemMesh.PhysicalSurfaces.physicalTag );   
            % Physical surface tag numbers for positive electrodes
            app.Output.positiveElectrodeTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalSurfaceName = string(app.UITableSensorAssignment.Data{iSelection,4});
                    for k = 1:length(PhysicalSurfaceNames)
                        if(physicalSurfaceName == PhysicalSurfaceNames{k})
                            c = c + 1;
                            app.Output.positiveElectrodeTagList(1,c) = physicalSurfaceTagList(k);
                        end
                    end
                end
            end
            
            % Physical surface tag numbers for positive electrodes
            app.Output.negativeElectrodeTagList = zeros(1,nnz(selection));
            c = 0;
            for iSelection = 1:length(selection)
                if(selection(iSelection))
                    physicalSurfaceName = string(app.UITableSensorAssignment.Data{iSelection,5});
                    for k = 1:length(PhysicalSurfaceNames)
                        if(physicalSurfaceName == PhysicalSurfaceNames{k})
                            c = c + 1;
                            app.Output.negativeElectrodeTagList(1,c) = physicalSurfaceTagList(k);
                        end
                    end
                end
            end
            updateSensorAssignmentTableColors(app);
            
            if(any(selection))
                app.isSensorAssigned = true;
                app.SensorSelectionPreviewDropDown.Visible = "on";
                app.SensorSelectionPreviewDropDownLabel.Visible = "on";
                
                PhysicalVolumeNames = {app.SemMesh.PhysicalVolumes.name}';
                items = string(PhysicalVolumeNames(selection)');
                app.SensorSelectionPreviewDropDown.Items = items;
                s1 = string(app.SensorSelectionPreviewDropDown.Value);
                app.SignalPreview.Title.String = s1;
            else
                app.isSensorAssigned = false;
                app.SensorSelectionPreviewDropDown.Visible = "off";
                app.SensorSelectionPreviewDropDownLabel.Visible = "off";
                
            end
        end

        % Value changed function: nCyclesEditField
        function nCyclesEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.SignalsTree.SelectedNodes;
            iSignal = selectedNode.NodeData;
            app.Excitation.Hann(iSignal).nCycles = app.nCyclesEditField.Value;
            if(app.Excitation.Hann(iSignal).nCycles ~= 0)
                app.Excitation.Hann(iSignal).modulationFrequency = ...
                    app.Excitation.Hann(iSignal).carrierFrequency / ...
                    app.Excitation.Hann(iSignal).nCycles;
                app.ModulationFrequencyEditField.Value = ...
                    app.Excitation.Hann(iSignal).modulationFrequency/1e3;
            end
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: DelayEditField
        function DelayEditFieldValueChanged(app, event)
            changeFilenameColor(app);
            selectedNode = app.SignalsTree.SelectedNodes;
            iSignal = selectedNode.NodeData;
            app.Excitation.Hann(iSignal).initiationTime = app.DelayEditField.Value/1e6;
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: DeltaTEditField
        function DeltaTEditFieldChanged(app, event)
            app.Excitation.dt = app.DeltaTEditField.Value/1e6;
            app.Excitation.dt
            if(app.Excitation.dt ~= 0)
                app.Excitation.nSamples = round((app.Excitation.totalTime / app.Excitation.dt) + 1);
                app.nSamplesEditField.Value = app.Excitation.nSamples;
                app.nSamplesEditField.Value
            end
            app.Output.sampleInterval = floor(app.Excitation.nSamples/(app.Output.nFrames));
            plotHannSignal(app);
            drawnow;
        end

        % Value changed function: SignalNameEditField
        function signalNameChanged(app, event)
            changeFilenameColor(app);
            value = app.SignalNameEditField.Value;
            for iSignal = 1:app.nHannSignals
                if(strcmp(value,app.Excitation.hannSignalNameList(iSignal)))
                    msgbox("Hann signal name already exist","Error","error");
                    return;
                end
            end
            for iChirp = 1:app.nChirpSignals
                if(strcmp(value,app.Excitation.chirpSignalNameList(iChirp)))
                    msgbox("Chirp signal name already exist","Error","error");
                    return;
                end
            end
            for iGAuss = 1:app.nGaussSignals
                if(strcmp(value,app.Excitation.gaussSignalNameList(iGauss)))
                    msgbox("Gauss signal name already exist","Error","error");
                    return;
                end
            end
            selectedNode = app.SignalsTree.SelectedNodes;
            oldName = selectedNode.Text;
            selectedNode.Text = value;
            if(app.signalTreeCategory == 1)
                iSignal = selectedNode.NodeData;
                app.Excitation.hannSignalNameList(iSignal) = value;
            elseif(app.signalTreeCategory == 2)
                iSignal = selectedNode.NodeData;
                app.Excitation.chirpSignalNameList(iSignal) = value;
            elseif(app.signalTreeCategory == 3)
                iSignal = selectedNode.NodeData;
                app.Excitation.gaussSignalNameList(iSignal) = value;
            end
            if(app.isExcitationAssigned)
                for j = 1 : length(app.Excitation.assignment)
                    s2 = string(app.Excitation.assignment(j).name);
                    if(strcmp(oldName,s2))
                        app.Excitation.assignment(j).name = value;
                        break
                    end
                end
            end
            
            fillExcitationAssignmentTable(app);
            drawnow;
            
        end

        % Cell edit callback: UITableExcitationAssignment
        function excitationAssignment(app, event)
            changeFilenameColor(app);
            extractCurrentExcitationAssignment(app);
            app.isExcitationAssigned = true;
            
        end

        % Selection changed function: SignalsTree
        function SignalSelectionChanged(app, event)
            selectedNodes = app.SignalsTree.SelectedNodes;
            if(strcmp(selectedNodes.Text,'Hann'))
                app.signalTreeCategory = 1;
            end
            if(strcmp(selectedNodes.Text,'Chirp'))
                app.signalTreeCategory = 2;
            end
            if(strcmp(selectedNodes.Text,'Gauss'))
                app.signalTreeCategory = 3;
            end
            if(~strcmp(selectedNodes.Text,'Hann') && ...
                    ~strcmp(selectedNodes.Text,'Chirp') && ...
                    ~strcmp(selectedNodes.Text,'Gauss'))
                if(strcmp(selectedNodes.Parent.Text,'Hann'))
                    app.signalTreeCategory = 1;
                end
                if(strcmp(selectedNodes.Parent.Text,'Chirp'))
                    app.signalTreeCategory = 2;
                end
                if(strcmp(selectedNodes.Parent.Text,'Gauss'))
                    app.signalTreeCategory = 3;
                end
            end
            
            if(~isempty(selectedNodes.NodeData))
                app.SignalNameEditField.Value = selectedNodes.Text;
                drawnow;
            else
                app.SignalNameEditField.Value = '1';
                drawnow;
            end
            if(strcmp(selectedNodes.Text,'Hann') || ...
               strcmp(selectedNodes.Text,'Chirp') ||...
               strcmp(selectedNodes.Text,'Gauss'))
                app.SignalNameEditField.Visible = 'off';
                app.SignalNameEditFieldLabel.Visible = 'off';
                
                app.PeakVoltageEditField.Visible = "off";
                app.PeakVoltageEditFieldLabel.Visible = "off";
                app.CarrierFrequencyEditField.Visible = "off";
                app.CarrierFrequencyEditFieldLabel.Visible = "off";
                app.ModulationFrequencyEditField.Visible = "off";
                app.ModulationFrequencyEditFieldLabel.Visible = "off";
                app.nCyclesEditField.Visible = "off";
                app.nCyclesEditFieldLabel.Visible = "off";
                app.DelayEditField.Visible = "off";
                app.DelayEditFieldLabel.Visible = "off";
                app.HannTimeFig.Visible = "off";
                app.HannFrequencyFig.Visible = "off";
                set(app.HannTimePlotHandle,'Visible','off');
                set(app.HannFramesPlotHandle,'Visible','off');
                set(app.HannFrequencyPlotHandle,'Visible','off');
                drawnow;
            else
                app.SignalNameEditField.Visible = 'on';
                app.SignalNameEditFieldLabel.Visible = 'on';
                
                if(app.signalTreeCategory == 1) % visibility for Hann signals
                    iHannSignal = selectedNodes.NodeData;
                    
                    app.PeakVoltageEditField.Value = app.Excitation.Hann(iHannSignal).peakVoltage;
                    
                    app.CarrierFrequencyEditField.Value = ...
                        app.Excitation.Hann(iHannSignal).carrierFrequency/1e3;
                    
                    app.ModulationFrequencyEditField.Value = ...
                        app.Excitation.Hann(iHannSignal).modulationFrequency/1e3;
                    
                    app.nCyclesEditField.Value = ...
                        app.Excitation.Hann(iHannSignal).nCycles;
                    
                    app.DelayEditField.Value = ...
                        1e6*app.Excitation.Hann(iHannSignal).initiationTime;
                    

                    app.PeakVoltageEditField.Visible = "on";
                    app.PeakVoltageEditFieldLabel.Visible = "on";
                    app.CarrierFrequencyEditField.Visible = "on";
                    app.CarrierFrequencyEditFieldLabel.Visible = "on";
                    app.ModulationFrequencyEditField.Visible = "on";
                    app.ModulationFrequencyEditFieldLabel.Visible = "on";
                    app.nCyclesEditField.Visible = "on";
                    app.nCyclesEditFieldLabel.Visible = "on";
                    app.DelayEditField.Visible = "on";
                    app.DelayEditFieldLabel.Visible = "on";

                    app.HannTimeFig.Visible = 'on';
                    app.HannFrequencyFig.Visible = 'on';
                    plotHannSignal(app);
                    set(app.HannTimePlotHandle,'Visible','on')
                    set(app.HannFramesPlotHandle,'Visible','on');
                    set(app.HannFrequencyPlotHandle,'Visible','on')

                elseif(app.signalTreeCategory == 2) % visibility for chirp signals

                    iChirpSignal = selectedNodes.NodeData;
                    

                elseif (app.signalTreeCategory == 3) % visibility for Gauss signal

                    iGaussSignal = selectedNodes.NodeData;
                end
                drawnow;
            end
            
        end

        % Button pushed function: DuplicateSignalButton
        function duplicateSignal(app, event)
            changeFilenameColor(app);
            app.SignalNameEditField.Visible = 'on';
            app.SignalNameEditFieldLabel.Visible = 'on';
            if(app.signalTreeCategory == 1 ) % Hann
                
                app.nHannSignals = app.nHannSignals + 1;
                app.nAddedHannSignals = app.nAddedHannSignals + 1;
 
                app.SignalNameEditField.Value = ['HannSignal',num2str(app.nAddedHannSignals)];
                app.Excitation.hannSignalNameList(app.nHannSignals) = app.SignalNameEditField.Value;
                % add node to Signals tree
                app.HannSignalNodeHandle(app.nHannSignals) = uitreenode(app.HannCategory);
                app.HannSignalNodeHandle(app.nHannSignals).NodeData = app.nHannSignals;
                app.HannSignalNodeHandle(app.nHannSignals).Text = app.SignalNameEditField.Value;
                app.SignalsTree.SelectedNodes = app.HannSignalNodeHandle(app.nHannSignals);

                % initialize signal properties values (duplicate current edit fields)
                app.Excitation.Hann(app.nHannSignals).peakVoltage = app.PeakVoltageEditField.Value;
                app.Excitation.Hann(app.nHannSignals).carrierFrequency = ...
                    1e3*app.CarrierFrequencyEditField.Value;
                app.Excitation.Hann(app.nHannSignals).modulationFrequency = ...
                    1e3*app.ModulationFrequencyEditField.Value;
                app.Excitation.Hann(app.nHannSignals).nCycles = app.nCyclesEditField.Value;   
                app.Excitation.Hann(app.nHannSignals).initiationTime = app.DelayEditField.Value/1e6;     
                
                % trigger tree selection change
                S = struct();
                S.SelectedNodes = app.HannSignalNodeHandle(app.nHannSignals);
                S.PreviousSelectedNodes = app.HannSignalNodeHandle(app.nHannSignals-1);
                S.Source = app.SignalsTree;
                S.EventName = 'SelectionChanged';
                SignalSelectionChanged(app, S);

            elseif(app.signalTreeCategory == 2) % Chirp
               

            elseif (app.signalTreeCategory == 3) % Gauss

            end
            fillExcitationAssignmentTable(app);
            extractCurrentExcitationAssignment(app);
            drawnow;
        end

        % Button pushed function: RemoveSignalButton
        function removeSignal(app, event)
            selectedNode = app.SignalsTree.SelectedNodes;
            
            if(~isempty(selectedNode))  
                index = selectedNode.NodeData;
                if(~isempty(index))
                    if(index > 1)
                        
                        changeFilenameColor(app);
                        if(app.signalTreeCategory == 1 ) % Hann  
                            % remove signal property values from database
                            app.Excitation.hannSignalNameList(index) = [];
                            app.Excitation.Hann(index) = [];
                            % update tree indexes
                            for iHannSignal = 1 : app.nHannSignals
                                if(app.HannSignalNodeHandle(iHannSignal).NodeData > index)
                                    app.HannSignalNodeHandle(iHannSignal).NodeData = ...
                                        app.HannSignalNodeHandle(iHannSignal).NodeData - 1;
                                end
                            end
                            app.HannSignalNodeHandle(index) = [];
                            app.nHannSignals = app.nHannSignals - 1;
                            % delete node tree
                            selectedNode.delete;  
                            
                            app.SignalsTree.SelectedNodes = app.HannCategory;

                            % trigger tree selection change
                            S = struct();
                            S.SelectedNodes = app.SignalsTree.SelectedNodes;
                            S.PreviousSelectedNodes = [];
                            S.Source = app.SignalsTree;
                            S.EventName = 'SelectionChanged';
                            SignalSelectionChanged(app, S);
            
                        elseif(app.signalTreeCategory == 2) % Chirp
                     
            
                        elseif (app.signalTreeCategory == 3) % Gauss
                            
         
                        end
                       
                        fillExcitationAssignmentTable(app);
                        extractCurrentExcitationAssignment(app);
                        drawnow;
                    else
                        if(index == 1)
                            msgbox("Default signal cannot be removed","Warning","warn");
                            return
                        end
                    end
                end
            end
            
        end

        % Button down function: ProcessingTab
        function ProcessingTabSelected(app, event)
            if(app.isMeshConverted && app.isMaterialAssigned && app.isActuatorAssigned &&...
                    app.isSensorAssigned && app.isExcitationAssigned)
                app.RunButton.Enable = "on";
            else
                app.RunButton.Enable = "off";
            end
        end

        % Value changed function: DisplacementFramesCheckBox
        function DisplacementFramesCheckBoxValueChanged(app, event)
            value = app.DisplacementFramesCheckBox.Value;
            changeFilenameColor(app);
            if(value == 1)
                app.Output.isDisplacementFramesSelected = true;
            elseif (value == 0)
                app.Output.isDisplacementFramesSelected = false;
            end      
        end

        % Value changed function: VelocityFramesCheckBox
        function VelocityFramesCheckBoxValueChanged(app, event)
            changeFilenameColor(app);
            value = app.VelocityFramesCheckBox.Value;
            changeFilenameColor(app);
            if(value == 1)
                app.Output.isVelocityFramesSelected = true;
            elseif (value == 0)
                app.Output.isVelocityFramesSelected = false;
            end      
        end

        % Cell edit callback: UITableResponsePointsSelection
        function responsePointsSelectionChanged(app, event)
            changeFilenameColor(app);
            pointTags = table2array(app.UITableResponsePointsSelection.Data(:,1));
            selection = table2array(app.UITableResponsePointsSelection.Data(:,3));
            app.Output.responsePointTagList = pointTags(selection);
        end

        % Value changed function: SensorSelectionPreviewDropDown
        function SensorSelectionPreviewDropDownChanged(app, event)
            changeFilenameColor(app);
            s1 = string(app.SensorSelectionPreviewDropDown.Value);
            app.SignalPreview.Title.String = s1;
            for k = 1 : length(app.SensorSelectionPreviewDropDown.Items)
                s2 = string(app.SensorSelectionPreviewDropDown.Items(k));
                if(strcmp(s1, s2))
                    app.Output.sensorPreviewNo = k;
                    break
                end
            end
            
            
        end

        % Value changed function: GPUindexDropDown
        function GPUindexDropDownValueChanged(app, event)
            changeFilenameColor(app);
            s1 = app.GPUindexDropDown.Value;
            for k = 1:length(app.GPUindexDropDown.Items)
                s2 = app.GPUindexDropDown.Items(k);
                if(strcmp(s1,s2))
                    app.gpuDeviceIndex = k;
                    app.selectedGPU = gpuDevice(app.gpuDeviceIndex);
                    app.SelectedGPUNameTextArea.Visible = "on";
                    app.SelectedGPUNameTextArea.Value = [num2str(k),' ',app.selectedGPU.Name];
                    break
                end
            end  
            drawnow;
        end

        % Value changed function: MatlabMatCheckBox
        function MatlabMatCheckboxValueChanged(app, event)
            changeFilenameColor(app);
            value = app.MatlabMatCheckBox.Value;
            if(value)
                app.Output.isMatOutput = true;
            else
                app.Output.isMatOutput = false;
            end

        end

        % Value changed function: ParaviewVtuCheckBox
        function ParaviewVtuCheckBoxValueChanged(app, event)
            changeFilenameColor(app);
            value = app.ParaviewVtuCheckBox.Value;
            if(value)
                app.Output.isVtuOutput = true;
                app.VtuFormatButtonGroup.Visible = "on";
            else
                app.Output.isVtuOutput = false;
                app.VtuFormatButtonGroup.Visible = "off";
            end
        end

        % Selection changed function: VtuFormatButtonGroup
        function VtuFormatButtonGroupChanged(app, event)
            changeFilenameColor(app);
            selectedButton = app.VtuFormatButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text,"ascii"))
                app.Output.isBinary = false;
            elseif(strcmp(selectedButton.Text,"binary"))
                app.Output.isBinary = true;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.waveProSHMgui = uifigure('Visible', 'off');
            app.waveProSHMgui.Name = 'WaveProSHM';
            app.waveProSHMgui.Icon = fullfile(pathToMLAPP, 'wave_pro_shm_ikon48x48_wb.png');
            app.waveProSHMgui.CloseRequestFcn = createCallbackFcn(app, @waveProSHMguiCloseRequest, true);
            app.LoadMenu = uimenu(app.waveProSHMgui);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.waveProSHMgui);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.waveProSHMgui);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.LoadMeshButton = uibutton(app.MeshTab, 'push');
            app.LoadMeshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMesh, true);
            app.LoadMeshButton.Text = 'Load Mesh';
            app.MeshunitsButtonGroup = uibuttongroup(app.MeshTab);
            app.MeshunitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @meshUnits, true);
            app.MeshunitsButtonGroup.Title = 'Mesh units';
            app.meshUnitsButton_mm = uiradiobutton(app.MeshunitsButtonGroup);
            app.meshUnitsButton_mm.Text = '[mm]';
            app.meshUnitsButton_mm.Value = true;
            app.meshUnitsButton_m = uiradiobutton(app.MeshunitsButtonGroup);
            app.meshUnitsButton_m.Text = '[m]';
            app.SelectDamageInterfacesVolumesandCurvesLabel = uilabel(app.MeshTab);
            app.SelectDamageInterfacesVolumesandCurvesLabel.Visible = 'off';
            app.SelectDamageInterfacesVolumesandCurvesLabel.Text = 'Select Damage Interfaces, Volumes and Curves';
            app.UITableDamageInterfaceSelection = uitable(app.MeshTab);
            app.UITableDamageInterfaceSelection.ColumnName = {'ID'; 'PhysicalSurfaceName'; 'Selection'; 'PhysicalVolumeName'; 'PhysicalCurveName'};
            app.UITableDamageInterfaceSelection.RowName = {};
            app.UITableDamageInterfaceSelection.ColumnEditable = [false false true true true];
            app.UITableDamageInterfaceSelection.CellEditCallback = createCallbackFcn(app, @damageInterfaceSelectionEdited, true);
            app.UITableDamageInterfaceSelection.Multiselect = 'off';
            app.UITableDamageInterfaceSelection.Visible = 'off';
            app.ConvertMeshButton = uibutton(app.MeshTab, 'push');
            app.ConvertMeshButton.ButtonPushedFcn = createCallbackFcn(app, @convertMesh, true);
            app.ConvertMeshButton.Visible = 'off';
            app.ConvertMeshButton.Text = 'Convert Mesh';
            app.LoadMeshTextArea = uitextarea(app.MeshTab);
            app.LoadMeshTextArea.Editable = 'off';
            app.TickanddropdownmenuLabel = uilabel(app.MeshTab);
            app.TickanddropdownmenuLabel.Visible = 'off';
            app.TickanddropdownmenuLabel.Text = '(Tick and drop down menu)';
            app.ConvertMeshTextArea = uitextarea(app.MeshTab);
            app.ConvertMeshTextArea.Editable = 'off';
            app.ConvertMeshTextArea.Visible = 'off';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.MaterialsTab.ButtonDownFcn = createCallbackFcn(app, @expandMaterialsTree, true);
            app.ElasticityD11EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD11EditFieldLabel.Enable = 'off';
            app.ElasticityD11EditFieldLabel.Visible = 'off';
            app.ElasticityD11EditFieldLabel.Text = '';
            app.ElasticityD11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD11EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD11EditFieldValueChanged, true);
            app.ElasticityD11EditField.Visible = 'off';
            app.ElasticityD11EditField.Value = 52.55;
            app.ElasticityD12EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD12EditFieldLabel.Enable = 'off';
            app.ElasticityD12EditFieldLabel.Visible = 'off';
            app.ElasticityD12EditFieldLabel.Text = '';
            app.ElasticityD12EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD12EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD12EditFieldValueChanged, true);
            app.ElasticityD12EditField.Visible = 'off';
            app.ElasticityD12EditField.Value = 6.51;
            app.ElasticityD13EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD13EditFieldLabel.Enable = 'off';
            app.ElasticityD13EditFieldLabel.Visible = 'off';
            app.ElasticityD13EditFieldLabel.Text = '';
            app.ElasticityD13EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD13EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD13EditFieldValueChanged, true);
            app.ElasticityD13EditField.Visible = 'off';
            app.ElasticityD13EditField.Value = 5.94;
            app.ElasticityD14EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD14EditFieldLabel.Enable = 'off';
            app.ElasticityD14EditFieldLabel.Visible = 'off';
            app.ElasticityD14EditFieldLabel.Text = '';
            app.ElasticityD14EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD14EditField.Editable = 'off';
            app.ElasticityD14EditField.Enable = 'off';
            app.ElasticityD14EditField.Visible = 'off';
            app.ElasticityD15EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD15EditFieldLabel.Enable = 'off';
            app.ElasticityD15EditFieldLabel.Visible = 'off';
            app.ElasticityD15EditFieldLabel.Text = '';
            app.ElasticityD15EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD15EditField.Editable = 'off';
            app.ElasticityD15EditField.Enable = 'off';
            app.ElasticityD15EditField.Visible = 'off';
            app.ElasticityD16EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD16EditFieldLabel.Enable = 'off';
            app.ElasticityD16EditFieldLabel.Visible = 'off';
            app.ElasticityD16EditFieldLabel.Text = '';
            app.ElasticityD16EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD16EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD16EditFieldValueChanged, true);
            app.ElasticityD16EditField.Visible = 'off';
            app.ElasticityD21EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD21EditFieldLabel.Enable = 'off';
            app.ElasticityD21EditFieldLabel.Visible = 'off';
            app.ElasticityD21EditFieldLabel.Text = '';
            app.ElasticityD21EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD21EditField.Editable = 'off';
            app.ElasticityD21EditField.Enable = 'off';
            app.ElasticityD21EditField.Visible = 'off';
            app.ElasticityD21EditField.Value = 6.51;
            app.ElasticityD22EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD22EditFieldLabel.Enable = 'off';
            app.ElasticityD22EditFieldLabel.Visible = 'off';
            app.ElasticityD22EditFieldLabel.Text = '';
            app.ElasticityD22EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD22EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD22EditFieldValueChanged, true);
            app.ElasticityD22EditField.Visible = 'off';
            app.ElasticityD22EditField.Value = 51.83;
            app.ElasticityD23EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD23EditFieldLabel.Enable = 'off';
            app.ElasticityD23EditFieldLabel.Visible = 'off';
            app.ElasticityD23EditFieldLabel.Text = '';
            app.ElasticityD23EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD23EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD23EditFieldValueChanged, true);
            app.ElasticityD23EditField.Visible = 'off';
            app.ElasticityD23EditField.Value = 5.88;
            app.ElasticityD24EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD24EditFieldLabel.Enable = 'off';
            app.ElasticityD24EditFieldLabel.Visible = 'off';
            app.ElasticityD24EditFieldLabel.Text = '';
            app.ElasticityD24EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD24EditField.Editable = 'off';
            app.ElasticityD24EditField.Enable = 'off';
            app.ElasticityD24EditField.Visible = 'off';
            app.ElasticityD25EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD25EditFieldLabel.Enable = 'off';
            app.ElasticityD25EditFieldLabel.Visible = 'off';
            app.ElasticityD25EditFieldLabel.Text = '';
            app.ElasticityD25EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD25EditField.Editable = 'off';
            app.ElasticityD25EditField.Enable = 'off';
            app.ElasticityD25EditField.Visible = 'off';
            app.ElasticityD26EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD26EditFieldLabel.Enable = 'off';
            app.ElasticityD26EditFieldLabel.Visible = 'off';
            app.ElasticityD26EditFieldLabel.Text = '';
            app.ElasticityD26EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD26EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD26EditFieldValueChanged, true);
            app.ElasticityD26EditField.Visible = 'off';
            app.ElasticityD31EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD31EditFieldLabel.Enable = 'off';
            app.ElasticityD31EditFieldLabel.Visible = 'off';
            app.ElasticityD31EditFieldLabel.Text = '';
            app.ElasticityD31EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD31EditField.Editable = 'off';
            app.ElasticityD31EditField.Enable = 'off';
            app.ElasticityD31EditField.Visible = 'off';
            app.ElasticityD31EditField.Value = 5.94;
            app.ElasticityD32EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD32EditFieldLabel.Enable = 'off';
            app.ElasticityD32EditFieldLabel.Visible = 'off';
            app.ElasticityD32EditFieldLabel.Text = '';
            app.ElasticityD32EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD32EditField.Editable = 'off';
            app.ElasticityD32EditField.Enable = 'off';
            app.ElasticityD32EditField.Visible = 'off';
            app.ElasticityD32EditField.Value = 5.88;
            app.ElasticityD33EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD33EditFieldLabel.Enable = 'off';
            app.ElasticityD33EditFieldLabel.Visible = 'off';
            app.ElasticityD33EditFieldLabel.Text = '';
            app.ElasticityD33EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD33EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD33EditFieldValueChanged, true);
            app.ElasticityD33EditField.Visible = 'off';
            app.ElasticityD33EditField.Value = 10.28;
            app.ElasticityD34EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD34EditFieldLabel.Enable = 'off';
            app.ElasticityD34EditFieldLabel.Visible = 'off';
            app.ElasticityD34EditFieldLabel.Text = '';
            app.ElasticityD34EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD34EditField.Editable = 'off';
            app.ElasticityD34EditField.Enable = 'off';
            app.ElasticityD34EditField.Visible = 'off';
            app.ElasticityD35EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD35EditFieldLabel.Enable = 'off';
            app.ElasticityD35EditFieldLabel.Visible = 'off';
            app.ElasticityD35EditFieldLabel.Text = '';
            app.ElasticityD35EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD35EditField.Editable = 'off';
            app.ElasticityD35EditField.Enable = 'off';
            app.ElasticityD35EditField.Visible = 'off';
            app.ElasticityD36EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD36EditFieldLabel.Enable = 'off';
            app.ElasticityD36EditFieldLabel.Visible = 'off';
            app.ElasticityD36EditFieldLabel.Text = '';
            app.ElasticityD36EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD36EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD36EditFieldValueChanged, true);
            app.ElasticityD36EditField.Visible = 'off';
            app.ElasticityD41EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD41EditFieldLabel.Enable = 'off';
            app.ElasticityD41EditFieldLabel.Visible = 'off';
            app.ElasticityD41EditFieldLabel.Text = '';
            app.ElasticityD41EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD41EditField.Editable = 'off';
            app.ElasticityD41EditField.Enable = 'off';
            app.ElasticityD41EditField.Visible = 'off';
            app.ElasticityD42EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD42EditFieldLabel.Enable = 'off';
            app.ElasticityD42EditFieldLabel.Visible = 'off';
            app.ElasticityD42EditFieldLabel.Text = '';
            app.ElasticityD42EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD42EditField.Editable = 'off';
            app.ElasticityD42EditField.Enable = 'off';
            app.ElasticityD42EditField.Visible = 'off';
            app.ElasticityD43EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD43EditFieldLabel.Enable = 'off';
            app.ElasticityD43EditFieldLabel.Visible = 'off';
            app.ElasticityD43EditFieldLabel.Text = '';
            app.ElasticityD43EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD43EditField.Editable = 'off';
            app.ElasticityD43EditField.Enable = 'off';
            app.ElasticityD43EditField.Visible = 'off';
            app.ElasticityD44EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD44EditFieldLabel.Enable = 'off';
            app.ElasticityD44EditFieldLabel.Visible = 'off';
            app.ElasticityD44EditFieldLabel.Text = '';
            app.ElasticityD44EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD44EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD44EditFieldValueChanged, true);
            app.ElasticityD44EditField.Visible = 'off';
            app.ElasticityD44EditField.Value = 2.92;
            app.ElasticityD45EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD45EditFieldLabel.Enable = 'off';
            app.ElasticityD45EditFieldLabel.Visible = 'off';
            app.ElasticityD45EditFieldLabel.Text = '';
            app.ElasticityD45EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD45EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD45EditFieldValueChanged, true);
            app.ElasticityD45EditField.Visible = 'off';
            app.ElasticityD46EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD46EditFieldLabel.Enable = 'off';
            app.ElasticityD46EditFieldLabel.Visible = 'off';
            app.ElasticityD46EditFieldLabel.Text = '';
            app.ElasticityD46EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD46EditField.Editable = 'off';
            app.ElasticityD46EditField.Enable = 'off';
            app.ElasticityD46EditField.Visible = 'off';
            app.ElasticityD51EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD51EditFieldLabel.Enable = 'off';
            app.ElasticityD51EditFieldLabel.Visible = 'off';
            app.ElasticityD51EditFieldLabel.Text = '';
            app.ElasticityD51EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD51EditField.Editable = 'off';
            app.ElasticityD51EditField.Enable = 'off';
            app.ElasticityD51EditField.Visible = 'off';
            app.ElasticityD52EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD52EditFieldLabel.Enable = 'off';
            app.ElasticityD52EditFieldLabel.Visible = 'off';
            app.ElasticityD52EditFieldLabel.Text = '';
            app.ElasticityD52EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD52EditField.Editable = 'off';
            app.ElasticityD52EditField.Enable = 'off';
            app.ElasticityD52EditField.Visible = 'off';
            app.ElasticityD53EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD53EditFieldLabel.Enable = 'off';
            app.ElasticityD53EditFieldLabel.Visible = 'off';
            app.ElasticityD53EditFieldLabel.Text = '';
            app.ElasticityD53EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD53EditField.Editable = 'off';
            app.ElasticityD53EditField.Enable = 'off';
            app.ElasticityD53EditField.Visible = 'off';
            app.ElasticityD54EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD54EditFieldLabel.Enable = 'off';
            app.ElasticityD54EditFieldLabel.Visible = 'off';
            app.ElasticityD54EditFieldLabel.Text = '';
            app.ElasticityD54EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD54EditField.Editable = 'off';
            app.ElasticityD54EditField.Enable = 'off';
            app.ElasticityD54EditField.Visible = 'off';
            app.ElasticityD55EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD55EditFieldLabel.Enable = 'off';
            app.ElasticityD55EditFieldLabel.Visible = 'off';
            app.ElasticityD55EditFieldLabel.Text = '';
            app.ElasticityD55EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD55EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD55EditFieldValueChanged, true);
            app.ElasticityD55EditField.Visible = 'off';
            app.ElasticityD55EditField.Value = 2.92;
            app.ElasticityD56EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD56EditFieldLabel.Enable = 'off';
            app.ElasticityD56EditFieldLabel.Visible = 'off';
            app.ElasticityD56EditFieldLabel.Text = '';
            app.ElasticityD56EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD56EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD56EditFieldValueChanged, true);
            app.ElasticityD56EditField.Editable = 'off';
            app.ElasticityD56EditField.Enable = 'off';
            app.ElasticityD56EditField.Visible = 'off';
            app.ElasticityD61EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD61EditFieldLabel.Enable = 'off';
            app.ElasticityD61EditFieldLabel.Visible = 'off';
            app.ElasticityD61EditFieldLabel.Text = '';
            app.ElasticityD61EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD61EditField.Editable = 'off';
            app.ElasticityD61EditField.Enable = 'off';
            app.ElasticityD61EditField.Visible = 'off';
            app.ElasticityD62EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD62EditFieldLabel.Enable = 'off';
            app.ElasticityD62EditFieldLabel.Visible = 'off';
            app.ElasticityD62EditFieldLabel.Text = '';
            app.ElasticityD62EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD62EditField.Editable = 'off';
            app.ElasticityD62EditField.Enable = 'off';
            app.ElasticityD62EditField.Visible = 'off';
            app.ElasticityD63EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD63EditFieldLabel.Enable = 'off';
            app.ElasticityD63EditFieldLabel.Visible = 'off';
            app.ElasticityD63EditFieldLabel.Text = '';
            app.ElasticityD63EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD63EditField.Editable = 'off';
            app.ElasticityD63EditField.Enable = 'off';
            app.ElasticityD63EditField.Visible = 'off';
            app.ElasticityD64EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD64EditFieldLabel.Enable = 'off';
            app.ElasticityD64EditFieldLabel.Visible = 'off';
            app.ElasticityD64EditFieldLabel.Text = '';
            app.ElasticityD64EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD64EditField.Editable = 'off';
            app.ElasticityD64EditField.Enable = 'off';
            app.ElasticityD64EditField.Visible = 'off';
            app.ElasticityD65EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD65EditFieldLabel.Enable = 'off';
            app.ElasticityD65EditFieldLabel.Visible = 'off';
            app.ElasticityD65EditFieldLabel.Text = '';
            app.ElasticityD65EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD65EditField.Editable = 'off';
            app.ElasticityD65EditField.Enable = 'off';
            app.ElasticityD65EditField.Visible = 'off';
            app.ElasticityD66EditFieldLabel = uilabel(app.MaterialsTab);
            app.ElasticityD66EditFieldLabel.Enable = 'off';
            app.ElasticityD66EditFieldLabel.Visible = 'off';
            app.ElasticityD66EditFieldLabel.Text = '';
            app.ElasticityD66EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ElasticityD66EditField.ValueChangedFcn = createCallbackFcn(app, @ElasticityD66EditFieldValueChanged, true);
            app.ElasticityD66EditField.Visible = 'off';
            app.ElasticityD66EditField.Value = 3.81;
            app.DampingCoefficientXEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientXEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientXEditFieldLabel.Visible = 'off';
            app.DampingCoefficientXEditFieldLabel.Text = 'Damping coefficient x';
            app.DampingCoefficientXEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientXEditField.Limits = [0 Inf];
            app.DampingCoefficientXEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientXEditFieldChanged, true);
            app.DampingCoefficientXEditField.Visible = 'off';
            app.DampingCoefficientXEditField.Tooltip = {'Mass proportional damping in x direction'};
            app.DampingCoefficientYEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientYEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientYEditFieldLabel.Visible = 'off';
            app.DampingCoefficientYEditFieldLabel.Text = 'Damping coefficient y';
            app.DampingCoefficientYEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientYEditField.Limits = [0 Inf];
            app.DampingCoefficientYEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientYEditFieldChanged, true);
            app.DampingCoefficientYEditField.Visible = 'off';
            app.DampingCoefficientYEditField.Tooltip = {'Mass proportional damping in y direction'};
            app.MaterialsTree = uitree(app.MaterialsTab);
            app.MaterialsTree.SelectionChangedFcn = createCallbackFcn(app, @materialSelectionChanged, true);
            app.SolidMaterialCategory = uitreenode(app.MaterialsTree);
            app.SolidMaterialCategory.Text = 'Solid Material';
            app.IsotropicMaterialCategory = uitreenode(app.SolidMaterialCategory);
            app.IsotropicMaterialCategory.Text = 'Isotropic';
            app.SolidMaterial1Node = uitreenode(app.IsotropicMaterialCategory);
            app.SolidMaterial1Node.NodeData = 1;
            app.SolidMaterial1Node.Text = 'Aluminium';
            app.OrthotropicMaterialCategory = uitreenode(app.SolidMaterialCategory);
            app.OrthotropicMaterialCategory.Text = 'Orthotropic';
            app.SolidMaterial2Node = uitreenode(app.OrthotropicMaterialCategory);
            app.SolidMaterial2Node.NodeData = 2;
            app.SolidMaterial2Node.Text = 'CFRP';
            app.PiezoelectricMaterialCategory = uitreenode(app.MaterialsTree);
            app.PiezoelectricMaterialCategory.Text = 'Piezoelectric Material';
            app.PiezoelectricMaterial1Node = uitreenode(app.PiezoelectricMaterialCategory);
            app.PiezoelectricMaterial1Node.NodeData = 1;
            app.PiezoelectricMaterial1Node.Text = 'my PZT';
            app.AddMaterialButton = uibutton(app.MaterialsTab, 'push');
            app.AddMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @addMaterial, true);
            app.AddMaterialButton.Text = '+ Add Material';
            app.RemoveMaterialButton = uibutton(app.MaterialsTab, 'push');
            app.RemoveMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @removeMaterial, true);
            app.RemoveMaterialButton.Text = '- Remove Material';
            app.MaterialNameEditFieldLabel = uilabel(app.MaterialsTab);
            app.MaterialNameEditFieldLabel.Visible = 'off';
            app.MaterialNameEditFieldLabel.Text = 'Material Name';
            app.MaterialNameEditField = uieditfield(app.MaterialsTab, 'text');
            app.MaterialNameEditField.CharacterLimits = [1 50];
            app.MaterialNameEditField.ValueChangedFcn = createCallbackFcn(app, @materialNameChanged, true);
            app.MaterialNameEditField.Visible = 'off';
            app.DampingCoefficientZEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientZEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientZEditFieldLabel.Visible = 'off';
            app.DampingCoefficientZEditFieldLabel.Text = 'Damping coefficient z';
            app.DampingCoefficientZEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientZEditField.Limits = [0 Inf];
            app.DampingCoefficientZEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientZEditFieldChanged, true);
            app.DampingCoefficientZEditField.Visible = 'off';
            app.DampingCoefficientZEditField.Tooltip = {'Mass proportional damping in z direction'};
            app.MassDensityEditFieldLabel = uilabel(app.MaterialsTab);
            app.MassDensityEditFieldLabel.Interpreter = 'latex';
            app.MassDensityEditFieldLabel.Visible = 'off';
            app.MassDensityEditFieldLabel.Text = 'Density $\rho \,$[kg/m$^3$]';
            app.MassDensityEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.MassDensityEditField.Limits = [0 Inf];
            app.MassDensityEditField.ValueDisplayFormat = '%.1f';
            app.MassDensityEditField.ValueChangedFcn = createCallbackFcn(app, @MassDensityEditFieldChanged, true);
            app.MassDensityEditField.Visible = 'off';
            app.MassDensityEditField.Value = 2700;
            app.PoissonRatioEditFieldLabel = uilabel(app.MaterialsTab);
            app.PoissonRatioEditFieldLabel.Interpreter = 'latex';
            app.PoissonRatioEditFieldLabel.Visible = 'off';
            app.PoissonRatioEditFieldLabel.Text = 'Poisson ratio $\nu \,$[-]';
            app.PoissonRatioEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.PoissonRatioEditField.Limits = [0 Inf];
            app.PoissonRatioEditField.ValueDisplayFormat = '%.3f';
            app.PoissonRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonRatioEditFieldChanged, true);
            app.PoissonRatioEditField.Visible = 'off';
            app.PoissonRatioEditField.Value = 0.33;
            app.YoungsModulusEditFieldLabel = uilabel(app.MaterialsTab);
            app.YoungsModulusEditFieldLabel.Interpreter = 'latex';
            app.YoungsModulusEditFieldLabel.Visible = 'off';
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus E \,[GPa]';
            app.YoungsModulusEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YoungsModulusEditField.Limits = [0 Inf];
            app.YoungsModulusEditField.ValueDisplayFormat = '%.2f';
            app.YoungsModulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusEditFieldChanged, true);
            app.YoungsModulusEditField.Visible = 'off';
            app.YoungsModulusEditField.Value = 71;
            app.UITableMaterialAssignment = uitable(app.MaterialsTab);
            app.UITableMaterialAssignment.ColumnName = {'ID'; 'PhysicalVolumeName'; 'MaterialSelection'};
            app.UITableMaterialAssignment.RowName = {};
            app.UITableMaterialAssignment.ColumnEditable = [false false true];
            app.UITableMaterialAssignment.CellEditCallback = createCallbackFcn(app, @materialAssignment, true);
            app.UITableMaterialAssignment.Multiselect = 'off';
            app.UITableMaterialAssignment.Tooltip = {'Mesh must be converted before material assignment'};
            app.UITableMaterialAssignment.Enable = 'off';
            app.MaterialsAssignmentLabel = uilabel(app.MaterialsTab);
            app.MaterialsAssignmentLabel.Enable = 'off';
            app.MaterialsAssignmentLabel.Text = 'Materials Assignment';
            app.YoungsModulusPiezoYE11EditFieldLabel = uilabel(app.MaterialsTab);
            app.YoungsModulusPiezoYE11EditFieldLabel.Interpreter = 'latex';
            app.YoungsModulusPiezoYE11EditFieldLabel.Visible = 'off';
            app.YoungsModulusPiezoYE11EditFieldLabel.Text = 'Young''s Modulus $Y^E_{11} \, $[GPa]';
            app.PoissonRatioPiezoEditFieldLabel = uilabel(app.MaterialsTab);
            app.PoissonRatioPiezoEditFieldLabel.Interpreter = 'latex';
            app.PoissonRatioPiezoEditFieldLabel.Visible = 'off';
            app.PoissonRatioPiezoEditFieldLabel.Text = 'Poisson ratio $\nu_{12}^{PZT} \,$[-]';
            app.MassDensityPiezoEditFieldLabel = uilabel(app.MaterialsTab);
            app.MassDensityPiezoEditFieldLabel.Interpreter = 'latex';
            app.MassDensityPiezoEditFieldLabel.Visible = 'off';
            app.MassDensityPiezoEditFieldLabel.Text = 'Density $\rho^{PZT} \,$[kg/m$^3$]';
            app.DampingCoefficientPiezoXEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientPiezoXEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientPiezoXEditFieldLabel.Visible = 'off';
            app.DampingCoefficientPiezoXEditFieldLabel.Text = 'Damping coefficient x';
            app.DampingCoefficientPiezoYEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientPiezoYEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientPiezoYEditFieldLabel.Visible = 'off';
            app.DampingCoefficientPiezoYEditFieldLabel.Text = 'Damping coefficient y';
            app.DampingCoefficientPiezoZEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientPiezoZEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientPiezoZEditFieldLabel.Visible = 'off';
            app.DampingCoefficientPiezoZEditFieldLabel.Text = 'Damping coefficient z';
            app.YoungsModulusPiezoYE11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YoungsModulusPiezoYE11EditField.Limits = [0 Inf];
            app.YoungsModulusPiezoYE11EditField.ValueDisplayFormat = '%.2f';
            app.YoungsModulusPiezoYE11EditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusPiezoYE11EditFieldChanged, true);
            app.YoungsModulusPiezoYE11EditField.Visible = 'off';
            app.YoungsModulusPiezoYE11EditField.Value = 59;
            app.PoissonRatioPiezoEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.PoissonRatioPiezoEditField.Limits = [0 Inf];
            app.PoissonRatioPiezoEditField.ValueDisplayFormat = '%.2f';
            app.PoissonRatioPiezoEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonRatioPiezoEditFieldChanged, true);
            app.PoissonRatioPiezoEditField.Visible = 'off';
            app.PoissonRatioPiezoEditField.Value = 0.32;
            app.MassDensityPiezoEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.MassDensityPiezoEditField.Limits = [0 Inf];
            app.MassDensityPiezoEditField.ValueDisplayFormat = '%.1f';
            app.MassDensityPiezoEditField.ValueChangedFcn = createCallbackFcn(app, @MassDensityPiezoEditFieldChanged, true);
            app.MassDensityPiezoEditField.Visible = 'off';
            app.MassDensityPiezoEditField.Value = 7600;
            app.DampingCoefficientPiezoXEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientPiezoXEditField.Limits = [0 Inf];
            app.DampingCoefficientPiezoXEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientPiezoXEditFieldChanged, true);
            app.DampingCoefficientPiezoXEditField.Visible = 'off';
            app.DampingCoefficientPiezoXEditField.Tooltip = {'Mass proportional damping in x direction'};
            app.DampingCoefficientPiezoYEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientPiezoYEditField.Limits = [0 Inf];
            app.DampingCoefficientPiezoYEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientPiezoYEditFieldChanged, true);
            app.DampingCoefficientPiezoYEditField.Visible = 'off';
            app.DampingCoefficientPiezoYEditField.Tooltip = {'Mass proportional damping in x direction'};
            app.DampingCoefficientPiezoZEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientPiezoZEditField.Limits = [0 Inf];
            app.DampingCoefficientPiezoZEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientPiezoZEditFieldChanged, true);
            app.DampingCoefficientPiezoZEditField.Visible = 'off';
            app.DampingCoefficientPiezoZEditField.Tooltip = {'Mass proportional damping in z direction'};
            app.YoungsModulusPiezoYE33EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YoungsModulusPiezoYE33EditField.Limits = [0 Inf];
            app.YoungsModulusPiezoYE33EditField.ValueDisplayFormat = '%.2f';
            app.YoungsModulusPiezoYE33EditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusPiezoYE33EditFieldChanged, true);
            app.YoungsModulusPiezoYE33EditField.Visible = 'off';
            app.YoungsModulusPiezoYE33EditField.Value = 46.9;
            app.YoungsModulusPiezoYE33EditFieldLabel = uilabel(app.MaterialsTab);
            app.YoungsModulusPiezoYE33EditFieldLabel.Interpreter = 'latex';
            app.YoungsModulusPiezoYE33EditFieldLabel.Visible = 'off';
            app.YoungsModulusPiezoYE33EditFieldLabel.Text = 'Young''s Modulus $Y^E_{33} \, $[GPa]';
            app.YoungsModulusPiezoYE55EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YoungsModulusPiezoYE55EditField.Limits = [0 Inf];
            app.YoungsModulusPiezoYE55EditField.ValueDisplayFormat = '%.2f';
            app.YoungsModulusPiezoYE55EditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusPiezoYE55EditFieldChanged, true);
            app.YoungsModulusPiezoYE55EditField.Visible = 'off';
            app.YoungsModulusPiezoYE55EditField.Value = 20.5;
            app.YoungsModulusPiezoYE55EditFieldLabel = uilabel(app.MaterialsTab);
            app.YoungsModulusPiezoYE55EditFieldLabel.Interpreter = 'latex';
            app.YoungsModulusPiezoYE55EditFieldLabel.Visible = 'off';
            app.YoungsModulusPiezoYE55EditFieldLabel.Text = 'Young''s Modulus $Y^E_{33} \, $[GPa]';
            app.ChargeConstantd31EditFieldLabel = uilabel(app.MaterialsTab);
            app.ChargeConstantd31EditFieldLabel.Interpreter = 'latex';
            app.ChargeConstantd31EditFieldLabel.Visible = 'off';
            app.ChargeConstantd31EditFieldLabel.Text = 'Charge constant $d_{31}$ [C/N] $10^{-12}$';
            app.ChargeConstantd31EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ChargeConstantd31EditField.ValueDisplayFormat = '%.1f';
            app.ChargeConstantd31EditField.ValueChangedFcn = createCallbackFcn(app, @ChargeConstantd31EditFieldChanged, true);
            app.ChargeConstantd31EditField.Visible = 'off';
            app.ChargeConstantd31EditField.Value = -208;
            app.ChargeConstantd33EditFieldLabel = uilabel(app.MaterialsTab);
            app.ChargeConstantd33EditFieldLabel.Interpreter = 'latex';
            app.ChargeConstantd33EditFieldLabel.Visible = 'off';
            app.ChargeConstantd33EditFieldLabel.Text = 'Charge constant $d_{33}$ [C/N] $10^{-12}$';
            app.ChargeConstantd33EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ChargeConstantd33EditField.Limits = [0 Inf];
            app.ChargeConstantd33EditField.ValueDisplayFormat = '%.1f';
            app.ChargeConstantd33EditField.ValueChangedFcn = createCallbackFcn(app, @ChargeConstantd33EditFieldChanged, true);
            app.ChargeConstantd33EditField.Visible = 'off';
            app.ChargeConstantd33EditField.Value = 443;
            app.ChargeConstantd15EditFieldLabel = uilabel(app.MaterialsTab);
            app.ChargeConstantd15EditFieldLabel.Interpreter = 'latex';
            app.ChargeConstantd15EditFieldLabel.Visible = 'off';
            app.ChargeConstantd15EditFieldLabel.Text = 'Charge constant $d_{15}$ [C/N] $10^{-12}$';
            app.ChargeConstantd15EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.ChargeConstantd15EditField.Limits = [0 Inf];
            app.ChargeConstantd15EditField.ValueDisplayFormat = '%.1f';
            app.ChargeConstantd15EditField.ValueChangedFcn = createCallbackFcn(app, @ChargeConstantd15EditFieldChanged, true);
            app.ChargeConstantd15EditField.Visible = 'off';
            app.ChargeConstantd15EditField.Value = 669;
            app.RelativePermittivityEpsrS11EditFieldLabel = uilabel(app.MaterialsTab);
            app.RelativePermittivityEpsrS11EditFieldLabel.Interpreter = 'latex';
            app.RelativePermittivityEpsrS11EditFieldLabel.Visible = 'off';
            app.RelativePermittivityEpsrS11EditFieldLabel.Text = 'Relative permittivity $\epsilon^S_{11}$ [-]';
            app.RelativePermittivityEpsrS11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.RelativePermittivityEpsrS11EditField.Limits = [0 Inf];
            app.RelativePermittivityEpsrS11EditField.ValueDisplayFormat = '%.1f';
            app.RelativePermittivityEpsrS11EditField.ValueChangedFcn = createCallbackFcn(app, @RelativePermittivityEpsrS11EditFieldChanged, true);
            app.RelativePermittivityEpsrS11EditField.Visible = 'off';
            app.RelativePermittivityEpsrS11EditField.Value = 906;
            app.RelativePermittivityEpsrS33EditFieldLabel = uilabel(app.MaterialsTab);
            app.RelativePermittivityEpsrS33EditFieldLabel.Interpreter = 'latex';
            app.RelativePermittivityEpsrS33EditFieldLabel.Visible = 'off';
            app.RelativePermittivityEpsrS33EditFieldLabel.Text = 'Relative permittivity $\epsilon^S_{33}$ [-]';
            app.RelativePermittivityEpsrS33EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.RelativePermittivityEpsrS33EditField.Limits = [0 Inf];
            app.RelativePermittivityEpsrS33EditField.ValueDisplayFormat = '%.1f';
            app.RelativePermittivityEpsrS33EditField.ValueChangedFcn = createCallbackFcn(app, @RelativePermittivityEpsrS33EditFieldChanged, true);
            app.RelativePermittivityEpsrS33EditField.Visible = 'off';
            app.RelativePermittivityEpsrS33EditField.Value = 823;
            app.StressVectorLabel = uilabel(app.MaterialsTab);
            app.StressVectorLabel.Interpreter = 'latex';
            app.StressVectorLabel.Visible = 'off';
            app.StressVectorLabel.Text = {'$\sigma_{x}$'; '$\sigma_{y}$'; '$\sigma_{z}$'; '$\sigma_{yz}$'; '$\sigma_{xz}$'; '$\sigma_{xy}$'};
            app.StrainVectorLabel = uilabel(app.MaterialsTab);
            app.StrainVectorLabel.Interpreter = 'latex';
            app.StrainVectorLabel.Visible = 'off';
            app.StrainVectorLabel.Text = {'$\varepsilon_{x}$'; '$\varepsilon_{y}$'; '$\varepsilon_{z}$'; '$\gamma_{yz}$'; '$\gamma_{xz}$'; '$\gamma_{xy}$'};
            app.EqualLabel = uilabel(app.MaterialsTab);
            app.EqualLabel.Interpreter = 'latex';
            app.EqualLabel.Visible = 'off';
            app.EqualLabel.Text = '=';
            app.ElasticityMatrixLabel = uilabel(app.MaterialsTab);
            app.ElasticityMatrixLabel.Visible = 'off';
            app.ElasticityMatrixLabel.Text = 'Elasticity Matrix [GPa]';
            app.DampingCoefficientOrthotropicXEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientOrthotropicXEditField.Limits = [0 Inf];
            app.DampingCoefficientOrthotropicXEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientOrthotropicXEditFieldChanged, true);
            app.DampingCoefficientOrthotropicXEditField.Visible = 'off';
            app.DampingCoefficientOrthotropicXEditField.Tooltip = {'Mass proportional damping in x direction'};
            app.DampingCoefficientOrthotropicXEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientOrthotropicXEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientOrthotropicXEditFieldLabel.Visible = 'off';
            app.DampingCoefficientOrthotropicXEditFieldLabel.Text = 'Damping coefficient x';
            app.DampingCoefficientOrthotropicYEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientOrthotropicYEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientOrthotropicYEditFieldLabel.Visible = 'off';
            app.DampingCoefficientOrthotropicYEditFieldLabel.Text = 'Damping coefficient y';
            app.DampingCoefficientOrthotropicYEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientOrthotropicYEditField.Limits = [0 Inf];
            app.DampingCoefficientOrthotropicYEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientOrthotropicYEditFieldChanged, true);
            app.DampingCoefficientOrthotropicYEditField.Visible = 'off';
            app.DampingCoefficientOrthotropicYEditField.Tooltip = {'Mass proportional damping in y direction'};
            app.DampingCoefficientOrthotropicZEditFieldLabel = uilabel(app.MaterialsTab);
            app.DampingCoefficientOrthotropicZEditFieldLabel.Interpreter = 'latex';
            app.DampingCoefficientOrthotropicZEditFieldLabel.Visible = 'off';
            app.DampingCoefficientOrthotropicZEditFieldLabel.Text = 'Damping coefficient z';
            app.DampingCoefficientOrthotropicZEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.DampingCoefficientOrthotropicZEditField.Limits = [0 Inf];
            app.DampingCoefficientOrthotropicZEditField.ValueChangedFcn = createCallbackFcn(app, @DampingCoefficientOrthotropicZEditFieldChanged, true);
            app.DampingCoefficientOrthotropicZEditField.Visible = 'off';
            app.DampingCoefficientOrthotropicZEditField.Tooltip = {'Mass proportional damping in z direction'};
            app.MassDensityOrthotripicEditFieldLabel = uilabel(app.MaterialsTab);
            app.MassDensityOrthotripicEditFieldLabel.Interpreter = 'latex';
            app.MassDensityOrthotripicEditFieldLabel.Visible = 'off';
            app.MassDensityOrthotripicEditFieldLabel.Text = 'Density $\rho \,$[kg/m$^3$]';
            app.MassDensityOrthotropicEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.MassDensityOrthotropicEditField.Limits = [0 Inf];
            app.MassDensityOrthotropicEditField.ValueDisplayFormat = '%.1f';
            app.MassDensityOrthotropicEditField.ValueChangedFcn = createCallbackFcn(app, @MassDensityOrthotropicEditFieldChanged, true);
            app.MassDensityOrthotropicEditField.Visible = 'off';
            app.MassDensityOrthotropicEditField.Value = 1522.4;
            app.TransducersTab = uitab(app.TabGroup);
            app.TransducersTab.Title = 'Transducers';
            app.UITableActuatorAssignment = uitable(app.TransducersTab);
            app.UITableActuatorAssignment.ColumnName = {'ID'; 'PhysicalVolumeName'; 'Selection'; 'PlusElectrodeSurface'; 'MinusElectrodeSurface'};
            app.UITableActuatorAssignment.RowName = {};
            app.UITableActuatorAssignment.ColumnEditable = [false false true true true];
            app.UITableActuatorAssignment.CellEditCallback = createCallbackFcn(app, @actuatorAssignmentChanged, true);
            app.UITableActuatorAssignment.Multiselect = 'off';
            app.UITableActuatorAssignment.Enable = 'off';
            app.ActuatorsAssignmentLabel = uilabel(app.TransducersTab);
            app.ActuatorsAssignmentLabel.Enable = 'off';
            app.ActuatorsAssignmentLabel.Text = 'Actuators Assignment';
            app.UITableSensorAssignment = uitable(app.TransducersTab);
            app.UITableSensorAssignment.ColumnName = {'ID'; 'PhysicalVolumeName'; 'Selection'; 'PlusElectrodeSurface'; 'MinusElectrodeSurface'};
            app.UITableSensorAssignment.RowName = {};
            app.UITableSensorAssignment.ColumnEditable = [false false true true true];
            app.UITableSensorAssignment.CellEditCallback = createCallbackFcn(app, @sensorAssignmentChanged, true);
            app.UITableSensorAssignment.Multiselect = 'off';
            app.UITableSensorAssignment.Enable = 'off';
            app.SensorsAssignmentLabel = uilabel(app.TransducersTab);
            app.SensorsAssignmentLabel.Enable = 'off';
            app.SensorsAssignmentLabel.Text = 'Sensors Assignment';
            app.SignalsTab = uitab(app.TabGroup);
            app.SignalsTab.Title = 'Signals';
            app.HannTimeFig = uiaxes(app.SignalsTab);
            title(app.HannTimeFig, 'Signal')
            xlabel(app.HannTimeFig, 'Time [ms]')
            app.HannFrequencyFig = uiaxes(app.SignalsTab);
            xlabel(app.HannFrequencyFig, 'Frequency [kHz]')
            app.CarrierFrequencyEditFieldLabel = uilabel(app.SignalsTab);
            app.CarrierFrequencyEditFieldLabel.Visible = 'off';
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency [kHz]';
            app.CarrierFrequencyEditField = uieditfield(app.SignalsTab, 'numeric');
            app.CarrierFrequencyEditField.Limits = [0 500];
            app.CarrierFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyEditFieldValueChanged, true);
            app.CarrierFrequencyEditField.Visible = 'off';
            app.CarrierFrequencyEditField.Value = 100;
            app.ModulationFrequencyEditFieldLabel = uilabel(app.SignalsTab);
            app.ModulationFrequencyEditFieldLabel.Visible = 'off';
            app.ModulationFrequencyEditFieldLabel.Text = 'Modulation Frequency [kHz]';
            app.ModulationFrequencyEditField = uieditfield(app.SignalsTab, 'numeric');
            app.ModulationFrequencyEditField.Limits = [0 500];
            app.ModulationFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationFrequencyEditFieldValueChanged, true);
            app.ModulationFrequencyEditField.Visible = 'off';
            app.ModulationFrequencyEditField.Value = 20;
            app.nSamplesEditFieldLabel = uilabel(app.SignalsTab);
            app.nSamplesEditFieldLabel.Text = 'Number of Samples';
            app.nSamplesEditField = uieditfield(app.SignalsTab, 'numeric');
            app.nSamplesEditField.Limits = [0 Inf];
            app.nSamplesEditField.ValueDisplayFormat = '%.0f';
            app.nSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @nSamplesEditFieldValueChanged, true);
            app.nSamplesEditField.Value = 524288;
            app.TotalPropagationTimeEditFieldLabel = uilabel(app.SignalsTab);
            app.TotalPropagationTimeEditFieldLabel.Text = 'Total Propagation Time [ms]';
            app.TotalPropagationTimeEditField = uieditfield(app.SignalsTab, 'numeric');
            app.TotalPropagationTimeEditField.Limits = [0 5];
            app.TotalPropagationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @TotalPropagationTimeEditFieldValueChanged, true);
            app.TotalPropagationTimeEditField.Value = 1;
            app.nTimeFramesEditFieldLabel = uilabel(app.SignalsTab);
            app.nTimeFramesEditFieldLabel.Text = 'Number of Time Frames';
            app.nTimeFramesEditField = uieditfield(app.SignalsTab, 'numeric');
            app.nTimeFramesEditField.Limits = [1 Inf];
            app.nTimeFramesEditField.ValueDisplayFormat = '%.0f';
            app.nTimeFramesEditField.ValueChangedFcn = createCallbackFcn(app, @nTimeFramesEditFieldValueChanged, true);
            app.nTimeFramesEditField.Value = 512;
            app.PeakVoltageEditFieldLabel = uilabel(app.SignalsTab);
            app.PeakVoltageEditFieldLabel.Visible = 'off';
            app.PeakVoltageEditFieldLabel.Text = 'Peak Voltage [V]';
            app.PeakVoltageEditField = uieditfield(app.SignalsTab, 'numeric');
            app.PeakVoltageEditField.Limits = [0 240];
            app.PeakVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @PeakVoltageEditFieldValueChanged, true);
            app.PeakVoltageEditField.Visible = 'off';
            app.PeakVoltageEditField.Value = 10;
            app.SignalsTree = uitree(app.SignalsTab);
            app.SignalsTree.SelectionChangedFcn = createCallbackFcn(app, @SignalSelectionChanged, true);
            app.HannCategory = uitreenode(app.SignalsTree);
            app.HannCategory.Text = 'Hann';
            app.HannSignal1Node = uitreenode(app.HannCategory);
            app.HannSignal1Node.NodeData = 1;
            app.HannSignal1Node.Text = 'HannSignal1';
            app.DuplicateSignalButton = uibutton(app.SignalsTab, 'push');
            app.DuplicateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @duplicateSignal, true);
            app.DuplicateSignalButton.Text = '+ Duplicate Signal';
            app.RemoveSignalButton = uibutton(app.SignalsTab, 'push');
            app.RemoveSignalButton.ButtonPushedFcn = createCallbackFcn(app, @removeSignal, true);
            app.RemoveSignalButton.Text = '- Remove Signal';
            app.SignalNameEditFieldLabel = uilabel(app.SignalsTab);
            app.SignalNameEditFieldLabel.Visible = 'off';
            app.SignalNameEditFieldLabel.Text = 'Signal Name';
            app.SignalNameEditField = uieditfield(app.SignalsTab, 'text');
            app.SignalNameEditField.CharacterLimits = [1 25];
            app.SignalNameEditField.ValueChangedFcn = createCallbackFcn(app, @signalNameChanged, true);
            app.SignalNameEditField.Visible = 'off';
            app.SignalNameEditField.Value = 'HannSignal1';
            app.SimulationparametersLabel = uilabel(app.SignalsTab);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.UITableExcitationAssignment = uitable(app.SignalsTab);
            app.UITableExcitationAssignment.ColumnName = {'ID'; 'ActuatorName'; 'SignalSelection'};
            app.UITableExcitationAssignment.RowName = {};
            app.UITableExcitationAssignment.ColumnEditable = [false false true];
            app.UITableExcitationAssignment.CellEditCallback = createCallbackFcn(app, @excitationAssignment, true);
            app.UITableExcitationAssignment.Multiselect = 'off';
            app.UITableExcitationAssignment.Tooltip = {'Actuator must be assigned before excitation assignment'};
            app.UITableExcitationAssignment.Enable = 'off';
            app.nCyclesEditFieldLabel = uilabel(app.SignalsTab);
            app.nCyclesEditFieldLabel.Visible = 'off';
            app.nCyclesEditFieldLabel.Text = 'Number of Cycles';
            app.nCyclesEditField = uieditfield(app.SignalsTab, 'numeric');
            app.nCyclesEditField.LowerLimitInclusive = 'off';
            app.nCyclesEditField.Limits = [0 500];
            app.nCyclesEditField.ValueChangedFcn = createCallbackFcn(app, @nCyclesEditFieldValueChanged, true);
            app.nCyclesEditField.Visible = 'off';
            app.nCyclesEditField.Value = 5;
            app.DelayEditFieldLabel = uilabel(app.SignalsTab);
            app.DelayEditFieldLabel.Interpreter = 'latex';
            app.DelayEditFieldLabel.Visible = 'off';
            app.DelayEditFieldLabel.Text = 'Delay [\mu s]';
            app.DelayEditField = uieditfield(app.SignalsTab, 'numeric');
            app.DelayEditField.Limits = [0 500];
            app.DelayEditField.ValueDisplayFormat = '%5.3f';
            app.DelayEditField.ValueChangedFcn = createCallbackFcn(app, @DelayEditFieldValueChanged, true);
            app.DelayEditField.Visible = 'off';
            app.DeltaTEditFieldLabel = uilabel(app.SignalsTab);
            app.DeltaTEditFieldLabel.Interpreter = 'latex';
            app.DeltaTEditFieldLabel.Text = '\Delta t [\mu s]';
            app.DeltaTEditField = uieditfield(app.SignalsTab, 'numeric');
            app.DeltaTEditField.Limits = [0 Inf];
            app.DeltaTEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaTEditFieldChanged, true);
            app.DeltaTEditField.Value = 0.0019;
            app.ExcitationAssignmentLabel = uilabel(app.SignalsTab);
            app.ExcitationAssignmentLabel.Text = 'Excitation Assignment';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.ProcessingTab.ButtonDownFcn = createCallbackFcn(app, @ProcessingTabSelected, true);
            app.SignalPreview = uiaxes(app.ProcessingTab);
            title(app.SignalPreview, 'Signal preview')
            xlabel(app.SignalPreview, 'Time [ms]')
            ylabel(app.SignalPreview, 'Voltage [V]')
            app.SignalPreview.Visible = 'off';
            app.Label = uilabel(app.ProcessingTab);
            app.Label.Text = 'Processing unit';
            app.ProcessingUnit = uiswitch(app.ProcessingTab, 'slider');
            app.ProcessingUnit.Items = {'CPU', 'GPU'};
            app.ProcessingUnit.ValueChangedFcn = createCallbackFcn(app, @ProcessingUnitValueChanged, true);
            app.ProcessingUnit.Value = 'GPU';
            app.NotificationTextAreaLabel = uilabel(app.ProcessingTab);
            app.NotificationTextAreaLabel.Text = 'Notification';
            app.NotificationTextArea = uitextarea(app.ProcessingTab);
            app.NotificationTextArea.Editable = 'off';
            app.DisplacementResponsePointsCheckBox = uicheckbox(app.ProcessingTab);
            app.DisplacementResponsePointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplacementResponsePointsCheckBoxValueChanged, true);
            app.DisplacementResponsePointsCheckBox.Text = 'Displacements at response points';
            app.DisplacementResponsePointsCheckBox.Value = true;
            app.VelocityResponsePointsCheckBox = uicheckbox(app.ProcessingTab);
            app.VelocityResponsePointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocityResponsePointsCheckBoxValueChanged, true);
            app.VelocityResponsePointsCheckBox.Text = 'Velocities at response points';
            app.RunButton = uibutton(app.ProcessingTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Make sure to convert mesh and assign transducers'};
            app.RunButton.Text = 'Run';
            app.ParameterstosaveLabel = uilabel(app.ProcessingTab);
            app.ParameterstosaveLabel.Text = 'Parameters to save';
            app.ResponsePointsSelectionLabel = uilabel(app.ProcessingTab);
            app.ResponsePointsSelectionLabel.Enable = 'off';
            app.ResponsePointsSelectionLabel.Text = 'Response points selection';
            app.SensorSelectionPreviewDropDownLabel = uilabel(app.ProcessingTab);
            app.SensorSelectionPreviewDropDownLabel.Visible = 'off';
            app.SensorSelectionPreviewDropDownLabel.Text = 'Sensor selection for preview';
            app.SensorSelectionPreviewDropDown = uidropdown(app.ProcessingTab);
            app.SensorSelectionPreviewDropDown.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4'};
            app.SensorSelectionPreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorSelectionPreviewDropDownChanged, true);
            app.SensorSelectionPreviewDropDown.Visible = 'off';
            app.SensorSelectionPreviewDropDown.Value = 'Sensor 1';
            app.UITableResponsePointsSelection = uitable(app.ProcessingTab);
            app.UITableResponsePointsSelection.ColumnName = {'ID'; 'PhysicalPointName'; 'Selection'};
            app.UITableResponsePointsSelection.RowName = {};
            app.UITableResponsePointsSelection.ColumnEditable = [false false true];
            app.UITableResponsePointsSelection.CellEditCallback = createCallbackFcn(app, @responsePointsSelectionChanged, true);
            app.UITableResponsePointsSelection.Multiselect = 'off';
            app.UITableResponsePointsSelection.Enable = 'off';
            app.VelocityFramesCheckBox = uicheckbox(app.ProcessingTab);
            app.VelocityFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @VelocityFramesCheckBoxValueChanged, true);
            app.VelocityFramesCheckBox.Text = 'Velocity frames';
            app.DisplacementFramesCheckBox = uicheckbox(app.ProcessingTab);
            app.DisplacementFramesCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplacementFramesCheckBoxValueChanged, true);
            app.DisplacementFramesCheckBox.Text = 'Displacement frames';
            app.DisplacementFramesCheckBox.Value = true;
            app.GPUindexDropDownLabel = uilabel(app.ProcessingTab);
            app.GPUindexDropDownLabel.Visible = 'off';
            app.GPUindexDropDownLabel.Text = 'GPU index';
            app.GPUindexDropDown = uidropdown(app.ProcessingTab);
            app.GPUindexDropDown.Items = {'1', '2', '3'};
            app.GPUindexDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUindexDropDownValueChanged, true);
            app.GPUindexDropDown.Visible = 'off';
            app.GPUindexDropDown.Value = '1';
            app.SelectedGPUNameTextArea = uitextarea(app.ProcessingTab);
            app.SelectedGPUNameTextArea.Editable = 'off';
            app.SelectedGPUNameTextArea.Enable = 'off';
            app.SelectedGPUNameTextArea.Visible = 'off';
            app.OutputFormatLabel = uilabel(app.ProcessingTab);
            app.OutputFormatLabel.Text = 'Frames output format';
            app.MatlabMatCheckBox = uicheckbox(app.ProcessingTab);
            app.MatlabMatCheckBox.ValueChangedFcn = createCallbackFcn(app, @MatlabMatCheckboxValueChanged, true);
            app.MatlabMatCheckBox.Text = 'Matlab *.mat';
            app.ParaviewVtuCheckBox = uicheckbox(app.ProcessingTab);
            app.ParaviewVtuCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParaviewVtuCheckBoxValueChanged, true);
            app.ParaviewVtuCheckBox.Text = 'Paraview .*vtu';
            app.ParaviewVtuCheckBox.Value = true;
            app.VtuFormatButtonGroup = uibuttongroup(app.ProcessingTab);
            app.VtuFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VtuFormatButtonGroupChanged, true);
            app.VtuFormatButtonGroup.Title = 'Format';
            app.ButtonBinary = uiradiobutton(app.VtuFormatButtonGroup);
            app.ButtonBinary.Text = 'binary';
            app.ButtonBinary.Value = true;
            app.ButtonAscii = uiradiobutton(app.VtuFormatButtonGroup);
            app.ButtonAscii.Text = 'ascii';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_eng_40mm_1200dpi.png');
            app.GUILabel = uilabel(app.AboutTab);
            app.GUILabel.Interpreter = 'html';
            app.GUILabel.Text = 'GUI:';
            app.EngineAuthorsLabel = uilabel(app.AboutTab);
            app.EngineAuthorsLabel.Interpreter = 'html';
            app.EngineAuthorsLabel.Text = ' &copy; Pawel Kudela, &copy; Piotr Fiborek';
            app.GUIAuthorLabel = uilabel(app.AboutTab);
            app.GUIAuthorLabel.Interpreter = 'html';
            app.GUIAuthorLabel.Text = '&copy; Pawel Kudela';
            app.EngineLabel = uilabel(app.AboutTab);
            app.EngineLabel.Interpreter = 'html';
            app.EngineLabel.Text = 'Engine:';
            app.AffiliationLabel = uilabel(app.AboutTab);
            app.AffiliationLabel.Interpreter = 'html';
            app.AffiliationLabel.Text = 'Institute of Fluid Flow Machinery <br>Polish Academy of Sciences';
            app.WaveProSHMLabel = uilabel(app.AboutTab);
            app.WaveProSHMLabel.Interpreter = 'html';
            app.WaveProSHMLabel.Text = 'Wave Propagation for Structural Health Monitoring';
            app.VersionLabel = uilabel(app.AboutTab);
            app.VersionLabel.Interpreter = 'html';
            app.VersionLabel.Text = 'Version 1.1.0';
            app.Image2 = uiimage(app.AboutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'wave_pro_shm_logo_transparent.png');
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.FilenameEditFieldLabel = uilabel(app.waveProSHMgui);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.waveProSHMgui, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Value = 'test';
            % Show the figure after all components are created
            app.waveProSHMgui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wave_pro_shm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.waveProSHMgui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.waveProSHMgui)
        end
    end
end